classdef TCCv138F < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ElementosUIFigure              matlab.ui.Figure
        EngrenagensButton              matlab.ui.control.Button
        PanelEngrenagem                matlab.ui.container.Panel
        EngrenagemHelicoidal           matlab.ui.container.Panel
        NmerodedentesLabel             matlab.ui.control.Label
        nd1                            matlab.ui.control.NumericEditField
        Passodiametral1inLabel         matlab.ui.control.Label
        pd                             matlab.ui.control.NumericEditField
        ngulodepressoLabel_2           matlab.ui.control.Label
        ap                             matlab.ui.control.NumericEditField
        ngulodehliceLabel              matlab.ui.control.Label
        ah                             matlab.ui.control.NumericEditField
        PotnciacvLabel_2               matlab.ui.control.Label
        pot1                           matlab.ui.control.NumericEditField
        PosioLabel                     matlab.ui.control.Label
        xEditFieldLabel                matlab.ui.control.Label
        xeng                           matlab.ui.control.NumericEditField
        yEditFieldLabel                matlab.ui.control.Label
        yeng                           matlab.ui.control.NumericEditField
        zEditFieldLabel                matlab.ui.control.Label
        zeng                           matlab.ui.control.NumericEditField
        rd                             matlab.ui.control.CheckBox
        re                             matlab.ui.control.CheckBox
        hor                            matlab.ui.control.CheckBox
        ahor                           matlab.ui.control.CheckBox
        RotaorpmLabel_2                matlab.ui.control.Label
        rot_1                          matlab.ui.control.NumericEditField
        AdicionarEngrenagensButton     matlab.ui.control.Button
        TipodeengrenagemDropDownLabel  matlab.ui.control.Label
        TipodeengrenagemDropDown       matlab.ui.control.DropDown
        EngrenagemDentesRetos          matlab.ui.container.Panel
        NmerodedentesLabel_2           matlab.ui.control.Label
        nd1_2                          matlab.ui.control.NumericEditField
        Passodiametral1inLabel_2       matlab.ui.control.Label
        pd_2                           matlab.ui.control.NumericEditField
        ngulodepressoLabel             matlab.ui.control.Label
        ap_2                           matlab.ui.control.NumericEditField
        PotnciacvLabel                 matlab.ui.control.Label
        pot_2                          matlab.ui.control.NumericEditField
        PosioLabel_2                   matlab.ui.control.Label
        xeng_2Label_2                  matlab.ui.control.Label
        xeng_2                         matlab.ui.control.NumericEditField
        yeng_2Label_2                  matlab.ui.control.Label
        yeng_2                         matlab.ui.control.NumericEditField
        zeng_2Label_2                  matlab.ui.control.Label
        zeng_2                         matlab.ui.control.NumericEditField
        RotaorpmLabel                  matlab.ui.control.Label
        rot_2                          matlab.ui.control.NumericEditField
        hor_2                          matlab.ui.control.CheckBox
        ahor_2                         matlab.ui.control.CheckBox
        AtualizarEngrenagensButton     matlab.ui.control.Button
        DeletarEngrenagensButton       matlab.ui.control.Button
        NomedasengrenagensLabel        matlab.ui.control.Label
        Nomee                          matlab.ui.control.EditField
        EixoDropDownLabel              matlab.ui.control.Label
        EixoDropDown                   matlab.ui.control.DropDown
        EixoDropDown_2Label            matlab.ui.control.Label
        EixoDropDown_2                 matlab.ui.control.DropDown
        EngrenagemConicaDR             matlab.ui.container.Panel
        NmerodedentesLabel_4           matlab.ui.control.Label
        nd1_3                          matlab.ui.control.NumericEditField
        Passodiametral1inLabel_3       matlab.ui.control.Label
        pd_3                           matlab.ui.control.NumericEditField
        ngulodepressoLabel_3           matlab.ui.control.Label
        ap_3                           matlab.ui.control.NumericEditField
        PotnciacvLabel_3               matlab.ui.control.Label
        pot_3                          matlab.ui.control.NumericEditField
        PosioLabel_3                   matlab.ui.control.Label
        xeng_2Label_5                  matlab.ui.control.Label
        xeng_6                         matlab.ui.control.NumericEditField
        yeng_2Label_5                  matlab.ui.control.Label
        yeng_6                         matlab.ui.control.NumericEditField
        zeng_2Label_5                  matlab.ui.control.Label
        zeng_6                         matlab.ui.control.NumericEditField
        cone1                          matlab.ui.control.CheckBox
        cone2                          matlab.ui.control.CheckBox
        LarguradefaceinLabel           matlab.ui.control.Label
        lff                            matlab.ui.control.NumericEditField
        hor_3                          matlab.ui.control.CheckBox
        ahor_3                         matlab.ui.control.CheckBox
        RotaorpmLabel_3                matlab.ui.control.Label
        rot_3                          matlab.ui.control.NumericEditField
        EngrenagemSemFim               matlab.ui.container.Panel
        NmerodeentradasLabel           matlab.ui.control.Label
        nd1_4                          matlab.ui.control.NumericEditField
        ngulodepressoLabel_4           matlab.ui.control.Label
        ap_4                           matlab.ui.control.NumericEditField
        DimetroprimitivoinLabel        matlab.ui.control.Label
        dp_2                           matlab.ui.control.NumericEditField
        PotnciacvLabel_4               matlab.ui.control.Label
        pot_4                          matlab.ui.control.NumericEditField
        PosioLabel_4                   matlab.ui.control.Label
        xEditFieldLabel_2              matlab.ui.control.Label
        xeng_7                         matlab.ui.control.NumericEditField
        yEditFieldLabel_2              matlab.ui.control.Label
        yeng_7                         matlab.ui.control.NumericEditField
        zEditFieldLabel_2              matlab.ui.control.Label
        zeng_7                         matlab.ui.control.NumericEditField
        rd_3                           matlab.ui.control.CheckBox
        re_3                           matlab.ui.control.CheckBox
        hor_4                          matlab.ui.control.CheckBox
        ahor_4                         matlab.ui.control.CheckBox
        RotaorpmLabel_4                matlab.ui.control.Label
        rot_4                          matlab.ui.control.NumericEditField
        AtritoaltoCheckBox             matlab.ui.control.CheckBox
        AtritobaixoCheckBox            matlab.ui.control.CheckBox
        EngrenagemAcopladaPanel        matlab.ui.container.Panel
        NmerodedentesLabel_3           matlab.ui.control.Label
        nd2                            matlab.ui.control.NumericEditField
        ngulorelativoLabel             matlab.ui.control.Label
        ar                             matlab.ui.control.NumericEditField
        ngulorelativoLabel_3           matlab.ui.control.Label
        ar2                            matlab.ui.control.DropDown
        EngrenagemAcopladaPanel_2      matlab.ui.container.Panel
        NmerodedentesLabel_5           matlab.ui.control.Label
        nd2_2                          matlab.ui.control.NumericEditField
        ngulorelativoLabel_2           matlab.ui.control.Label
        ar2_2                          matlab.ui.control.DropDown
        Passodiametral1inLabel_4       matlab.ui.control.Label
        pd_5                           matlab.ui.control.NumericEditField
        EixosButton                    matlab.ui.control.Button
        PanelEixo                      matlab.ui.container.Panel
        EixoPanel                      matlab.ui.container.Panel
        SyLabel                        matlab.ui.control.Label
        sy                             matlab.ui.control.NumericEditField
        FatordeSeguranaLabel           matlab.ui.control.Label
        fs                             matlab.ui.control.NumericEditField
        AdicionarEixoButton            matlab.ui.control.Button
        NomedoeixoLabel                matlab.ui.control.Label
        Nomeex                         matlab.ui.control.EditField
        DireodoeixoLabel               matlab.ui.control.Label
        xCheckBox                      matlab.ui.control.CheckBox
        yCheckBox                      matlab.ui.control.CheckBox
        zCheckBox                      matlab.ui.control.CheckBox
        Posiodomancal1Label            matlab.ui.control.Label
        Posiodomancal2Label            matlab.ui.control.Label
        AtualizarEixoButton            matlab.ui.control.Button
        xeng_3Label                    matlab.ui.control.Label
        xm2                            matlab.ui.control.NumericEditField
        xeng_2Label                    matlab.ui.control.Label
        xm1                            matlab.ui.control.NumericEditField
        yeng_2Label                    matlab.ui.control.Label
        ym1                            matlab.ui.control.NumericEditField
        zeng_2Label                    matlab.ui.control.Label
        zm1                            matlab.ui.control.NumericEditField
        zeng_3Label                    matlab.ui.control.Label
        zm2                            matlab.ui.control.NumericEditField
        yeng_3Label                    matlab.ui.control.Label
        ym2                            matlab.ui.control.NumericEditField
        DeletarEixoButton              matlab.ui.control.Button
        ResultadoButton                matlab.ui.control.Button
        PanelResultado                 matlab.ui.container.Panel
        DimetrodoEixoEditFieldLabel    matlab.ui.control.Label
        DimetrodoEixoEditField         matlab.ui.control.EditField
        TorqueMximoEditFieldLabel      matlab.ui.control.Label
        TorqueMximoEditField           matlab.ui.control.EditField
        MomentoMximoEditFieldLabel     matlab.ui.control.Label
        MomentoMximoEditField          matlab.ui.control.EditField
        SelecionarEixoDropDownLabel    matlab.ui.control.Label
        SelecionarEixoDropDown         matlab.ui.control.DropDown
        SelecionarElementoDropDownLabel  matlab.ui.control.Label
        SelecionarElementoDropDown     matlab.ui.control.DropDown
        FxEditField_2Label             matlab.ui.control.Label
        FxEditField                    matlab.ui.control.EditField
        FyEditFieldLabel               matlab.ui.control.Label
        FyEditField                    matlab.ui.control.EditField
        FzEditFieldLabel               matlab.ui.control.Label
        FzEditField                    matlab.ui.control.EditField
        AnliseDinmicaButton            matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        AdicionarEixoPanel             matlab.ui.container.Panel
        xeng_2Label_3                  matlab.ui.control.Label
        xeng_4                         matlab.ui.control.NumericEditField
        yeng_2Label_3                  matlab.ui.control.Label
        yeng_4                         matlab.ui.control.NumericEditField
        zeng_2Label_3                  matlab.ui.control.Label
        zeng_4                         matlab.ui.control.NumericEditField
        xeng_2Label_4                  matlab.ui.control.Label
        xeng_5                         matlab.ui.control.NumericEditField
        yeng_2Label_4                  matlab.ui.control.Label
        yeng_5                         matlab.ui.control.NumericEditField
        zeng_2Label_4                  matlab.ui.control.Label
        zeng_5                         matlab.ui.control.NumericEditField
        NomedoeixoEditFieldLabel       matlab.ui.control.Label
        Nomeex2                        matlab.ui.control.EditField
        SyEditFieldLabel               matlab.ui.control.Label
        sy2                            matlab.ui.control.NumericEditField
        FatordeseguranaLabel           matlab.ui.control.Label
        fs2                            matlab.ui.control.NumericEditField
        DireodoeixoLabel_2             matlab.ui.control.Label
        xCheckBox_2                    matlab.ui.control.CheckBox
        yCheckBox_2                    matlab.ui.control.CheckBox
        zCheckBox_2                    matlab.ui.control.CheckBox
        Posiodomancal1Label_2          matlab.ui.control.Label
        Posiodomancal2Label_2          matlab.ui.control.Label
        AdicionarEixoButton_2          matlab.ui.control.Button
        NovoProjetoLabel               matlab.ui.control.Label
        SistemaInternacionalButton     matlab.ui.control.Button
        SistemaInglsButton             matlab.ui.control.Button
        AnaliseDinamica                matlab.ui.container.Panel
        ElementosDropDownLabel         matlab.ui.control.Label
        ElementosDropDown              matlab.ui.control.DropDown
        EngrenagensdeDentesRetosPanel  matlab.ui.container.Panel
        TrattrmicopinhoDropDownLabel   matlab.ui.control.Label
        TrattrmicopinhoDropDown        matlab.ui.control.DropDown
        FontedepotnciaLabel            matlab.ui.control.Label
        FontedepotnciaDropDown         matlab.ui.control.DropDown
        MquinaacionadaDropDownLabel    matlab.ui.control.Label
        MquinaacionadaDropDown         matlab.ui.control.DropDown
        CoroamentoDropDownLabel        matlab.ui.control.Label
        CoroamentoDropDown             matlab.ui.control.DropDown
        EngrenamentoDropDownLabel      matlab.ui.control.Label
        EngrenamentoDropDown           matlab.ui.control.DropDown
        TipodeengDropDownLabel         matlab.ui.control.Label
        TipodeengDropDown              matlab.ui.control.DropDown
        CaractdoaroDropDownLabel       matlab.ui.control.Label
        CaractdoaroDropDown            matlab.ui.control.DropDown
        MaterialdacoroaDropDownLabel   matlab.ui.control.Label
        MaterialdacoroaDropDown        matlab.ui.control.DropDown
        MaterialdopinhoDropDownLabel   matlab.ui.control.Label
        MaterialdopinhoDropDown        matlab.ui.control.DropDown
        TrattrmicocoroaDropDownLabel   matlab.ui.control.Label
        TrattrmicocoroaDropDown        matlab.ui.control.DropDown
        LarguradefaceinLabel_2         matlab.ui.control.Label
        LarguradefaceEditField         matlab.ui.control.NumericEditField
        TempdefuncFLabel               matlab.ui.control.Label
        TempEditField                  matlab.ui.control.NumericEditField
        CoefdeconfiabilidadeLabel      matlab.ui.control.Label
        ConfEditField                  matlab.ui.control.NumericEditField
        VidadopinhociclosLabel         matlab.ui.control.Label
        VidaEditField                  matlab.ui.control.NumericEditField
        NmerodequalidadeLabel          matlab.ui.control.Label
        NumqEditField                  matlab.ui.control.NumericEditField
        DurezadacoroaHBEditFieldLabel  matlab.ui.control.Label
        DurezacEditField               matlab.ui.control.NumericEditField
        DurezadopinhoHBLabel           matlab.ui.control.Label
        DurezapEditField               matlab.ui.control.NumericEditField
        MbEditFieldLabel               matlab.ui.control.Label
        MbEditField                    matlab.ui.control.NumericEditField
        AdicionarDadosButton           matlab.ui.control.Button
        AtualizarButton                matlab.ui.control.Button
        DeletarButton                  matlab.ui.control.Button
        EdacoroapsiLabel               matlab.ui.control.Label
        EdacoroapsiEditField           matlab.ui.control.NumericEditField
        EdopinhopsiEditFieldLabel      matlab.ui.control.Label
        EdopinhopsiEditField           matlab.ui.control.NumericEditField
        ScadacoroapsiEditFieldLabel    matlab.ui.control.Label
        ScadacoroapsiEditField         matlab.ui.control.NumericEditField
        ScadopinhopsiEditFieldLabel    matlab.ui.control.Label
        ScadopinhopsiEditField         matlab.ui.control.NumericEditField
        PosiodasengsDropDownLabel      matlab.ui.control.Label
        PosiodasengsDropDown           matlab.ui.control.DropDown
        GraudacoroaDropDownLabel       matlab.ui.control.Label
        GraudacoroaDropDown            matlab.ui.control.DropDown
        GraudopinhoDropDownLabel       matlab.ui.control.Label
        GraudopinhoDropDown            matlab.ui.control.DropDown
        StadacoroapsiEditFieldLabel    matlab.ui.control.Label
        StadacoroapsiEditField         matlab.ui.control.NumericEditField
        StadopinhopsiEditFieldLabel    matlab.ui.control.Label
        StadopinhopsiEditField         matlab.ui.control.NumericEditField
        EixosPanel                     matlab.ui.container.Panel
        TratamentosuperficialDropDownLabel  matlab.ui.control.Label
        TratamentosuperficialDropDown  matlab.ui.control.DropDown
        SutMPaLabel                    matlab.ui.control.Label
        Sutf                           matlab.ui.control.NumericEditField
        TempdefuncFLabel_2             matlab.ui.control.Label
        TempEditField_2                matlab.ui.control.NumericEditField
        CoefdeconfiabilidadeLabel_2    matlab.ui.control.Label
        ConfEditField_2                matlab.ui.control.NumericEditField
        AdicionarDadosButton_2         matlab.ui.control.Button
        AtualizarButton_2              matlab.ui.control.Button
        DeletarButton_2                matlab.ui.control.Button
        CritriodefalhaDropDownLabel    matlab.ui.control.Label
        CritriodefalhaDropDown         matlab.ui.control.DropDown
        RelatrioButton                 matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
    end


    properties (Access = private)
        pm
        pn
        p
        pot
        rotacao
        n
        ns
        tetan
        phi
        dir
        rot
        r
        ra
        d
        de
        da
        pa
        pc
        ft
        fr
        fa
        f
        fatr
        fm
        na
        posa
        tm
        dira
        dire
        rota
        Sy
        strh=string('h')
        strah=string('ah')
        strd=string('d')
        stre=string('e')
        tipoe
        butt
        butt2
        butte
        bot
        botex
        nomee
        nomeep
        nomeex
        nomeexp
        chamada
        M
        Mrmax
        Tmax
        refe
        lf
        dc
        sistema
        exf
        sigmap
        sigmag
        K0
        Kv
        Ksp
        Kmp
        Kb
        jp
        Ksg 
        Kmg
        jg
        Sfp
        Stp
        Ynp
        Kt
        Kr
        Sfg
        Stg
        Yng
        sigmacp
        sigmacg
        Cp
        I
        Shp
        Shg
        Scp
        Scg
        Znp
        Zng
        EC
        EP
        Cf
        dinamica
        dinamicae
        dfe
        critf
      
    end

    methods (Access = private)
    
        function results = botaoengrenagem(app,src,event)
            num=find(app.butt == src);
            app.bot=num;
            ind=app.refe{num}(1);
            len=app.refe{num}(2);
            ind2=app.refe{num}(3);
            app.Nomee.Value = char(app.nomeep(num));
            app.TipodeengrenagemDropDown.Value=char(app.tipoe{ind}(len));
            app.AdicionarEngrenagensButton.Visible='off';
            app.AtualizarEngrenagensButton.Visible='on';
            app.DeletarEngrenagensButton.Visible='on';
            app.EixoDropDown.Value = char(app.nomeex(ind));
            app.EixoDropDown_2.Enable = 'on';
            if(app.tipoe{ind}(len) == string('Helicoidal'))
                app.EngrenagemHelicoidal.Visible = 'on';
                app.EngrenagemDentesRetos.Visible = 'off';
                app.EngrenagemConicaDR.Visible = 'off';
                app.EngrenagemSemFim.Visible = 'off';
                app.EngrenagemAcopladaPanel.Visible = 'on';
                app.EngrenagemAcopladaPanel_2.Visible = 'off';
                app.ar.Visible = 'on';
                app.ar2.Visible = 'off';
                app.nd2.Value=app.na{ind}(len);
                app.ar.Value=app.posa{ind}(len);
                app.nd1.Value=app.n{ind}(len);
                app.pot1.Value=app.pot{ind}(len);
                app.rot_1.Value=app.rotacao{ind}(len);
                if(app.rot{ind}(len) == app.strh)
                    app.hor.Value = 1;
                    app.ahor.Value = 0;
                elseif(app.rot{ind}(len) == app.strah)
                    app.hor.Value = 0;
                    app.ahor.Value = 1;
                end
                if(app.sistema==1)
                    app.pd.Value=1/app.pn{ind}(len);
                else
                    app.pd.Value=app.pn{ind}(len);
                end
                app.ap.Value=app.tetan{ind}(len);
                app.ah.Value=app.phi{ind}(len);
                if(app.dir{ind}(len) == app.strd)
                    app.rd.Value = 1;
                    app.re.Value = 0;
                elseif(app.dir{ind}(len) == app.stre)
                    app.re.Value = 1;
                    app.rd.Value = 0;
                end
                app.xeng.Value=app.p{ind}{len}(1); 
                app.yeng.Value=app.p{ind}{len}(2);  
                app.zeng.Value=app.p{ind}{len}(3);
                app.rot_1.Enable = 'off';
                app.pot1.Enable = 'off';
                app.hor.Enable = 'off';
                app.ahor.Enable = 'off';
            elseif(app.tipoe{ind}(len) == string('Dentes Retos'))
                app.EngrenagemDentesRetos.Visible = 'on';
                app.EngrenagemHelicoidal.Visible = 'off';
                app.EngrenagemConicaDR.Visible = 'off';
                app.EngrenagemSemFim.Visible = 'off';
                app.EngrenagemAcopladaPanel.Visible = 'on';
                app.EngrenagemAcopladaPanel_2.Visible = 'off';
                app.ar.Visible = 'on';
                app.ar2.Visible = 'off';
                app.nd2.Value=app.na{ind}(len);
                app.ar.Value=app.posa{ind}(len);
                app.nd1_2.Value=app.n{ind}(len);
                app.pot_2.Value=app.pot{ind}(len);
                app.rot_2.Value=app.rotacao{ind}(len);
                if(app.rot{ind}(len) == app.strh)
                    app.hor_2.Value = 1;
                    app.ahor_2.Value = 0;
                elseif(app.rot{ind}(len) == app.strah)
                    app.hor_2.Value = 0;
                    app.ahor_2.Value = 1;
                end
                if(app.sistema==1)
                    app.pd_2.Value=1/app.pn{ind}(len);
                else
                    app.pd_2.Value=app.pn{ind}(len);
                end
                app.ap_2.Value=app.tetan{ind}(len);
                app.xeng_2.Value=app.p{ind}{len}(1); 
                app.yeng_2.Value=app.p{ind}{len}(2);  
                app.zeng_2.Value=app.p{ind}{len}(3);
                app.rot_2.Enable = 'off';
                app.pot_2.Enable = 'off';
                app.hor_2.Enable = 'off';
                app.ahor_2.Enable = 'off';
            elseif(app.tipoe{ind}(len) == string('Cônica D.R.'))
                app.EngrenagemDentesRetos.Visible = 'off';
                app.EngrenagemHelicoidal.Visible = 'off';
                app.EngrenagemConicaDR.Visible = 'on';
                app.EngrenagemSemFim.Visible = 'off';
                app.EngrenagemAcopladaPanel.Visible = 'on';
                app.EngrenagemAcopladaPanel_2.Visible = 'off';
                app.ar2.Visible = 'on';
                app.ar.Visible = 'off';
                app.nd2.Value=app.na{ind}(len);
                app.ar2.Value=num2str(app.posa{ind}(len));
                app.nd1_3.Value=app.n{ind}(len);
                app.pot_3.Value=app.pot{ind}(len);
                app.rot_3.Value=app.rotacao{ind}(len);
                if(app.rot{ind}(len) == app.strh)
                    app.hor_3.Value = 1;
                    app.ahor_3.Value = 0;
                elseif(app.rot{ind}(len) == app.strah)
                    app.hor_3.Value = 0;
                    app.ahor_3.Value = 1;
                end
                if(app.sistema==1)
                    app.pd_3.Value=1/app.pn{ind}(len);
                else
                    app.pd_3.Value=app.pn{ind}(len);
                end
                app.lff.Value = app.lf{ind}(len);
                if(app.dc{ind}(len) == 1)
                    app.cone1.Value = 1;
                    app.cone2.Value = 0;
                elseif(app.dc{ind}(len) == 1)
                    app.cone1.Value = 0;
                    app.cone2.Value = 1;
                end
                app.ap_3.Value=app.tetan{ind}(len);
                app.xeng_6.Value=app.p{ind}{len}(1); 
                app.yeng_6.Value=app.p{ind}{len}(2);  
                app.zeng_6.Value=app.p{ind}{len}(3);
                app.rot_3.Enable = 'off';
                app.pot_3.Enable = 'off';
                app.hor_3.Enable = 'off';
                app.ahor_3.Enable = 'off';
            elseif(app.tipoe{ind}(len) == string('Sem Fim'))
                app.EngrenagemDentesRetos.Visible = 'off';
                app.EngrenagemHelicoidal.Visible = 'off';
                app.EngrenagemConicaDR.Visible = 'off';
                app.EngrenagemSemFim.Visible = 'on';
                app.EngrenagemAcopladaPanel.Visible = 'off';
                app.EngrenagemAcopladaPanel_2.Visible = 'on';
                app.nd2_2.Value=app.na{ind}(len);
                app.ar2_2.Value=num2str(app.posa{ind}(len));
                app.nd1_4.Value=app.n{ind}(len);
                app.pot_4.Value=app.pot{ind}(len);
                app.rot_4.Value=app.rotacao{ind}(len);
                app.dp_2.Value=app.d{ind}(len);
                if(app.dir{ind}(len) == app.stre)
                    app.re_3.Value = 1;
                    app.rd_3.Value = 0;
                elseif(app.dir{ind}(len) == app.strd)
                    app.re_3.Value = 0;
                    app.rd_3.Value = 1;
                end
                if(app.rot{ind}(len) == app.strh)
                    app.hor_4.Value = 1;
                    app.ahor_4.Value = 0;
                elseif(app.rot{ind}(len) == app.strah)
                    app.hor_4.Value = 0;
                    app.ahor_4.Value = 1;
                end
                if(app.sistema==1)
                    app.pd_5.Value=1/app.pn{ind}(len);
                else
                    app.pd_5.Value=app.pn{ind}(len);
                end
                app.ap_4.Value=app.tetan{ind}(len);
                app.xeng_7.Value=app.p{ind}{len}(1); 
                app.yeng_7.Value=app.p{ind}{len}(2);  
                app.zeng_7.Value=app.p{ind}{len}(3);
                if(app.fatr{ind}(len) == 1)
                    app.AtritoaltoCheckBox.Value = 1;
                    app.AtritobaixoCheckBox.Value = 0;
                elseif(app.fatr{ind}(len) == 2)
                    app.AtritoaltoCheckBox.Value = 0;
                    app.AtritobaixoCheckBox.Value = 1;
                end
                app.rot_4.Enable = 'off';
                app.pot_4.Enable = 'off';
                app.hor_4.Enable = 'off';
                app.ahor_4.Enable = 'off';
            end
            atualizardropdown(app);
            app.EixoDropDown_2.Value = char(app.nomeex(ind2));
            app.EixoDropDown_2.Enable = 'on';
            
        end
        
        function results = botaoeixo(app,src,event)
            num=find(app.butte == src);
            app.botex=num;
            app.Nomeex.Value = char(app.nomeexp(num));
            app.AdicionarEixoButton.Visible='off';
            app.DeletarEixoButton.Visible='on';
            app.AtualizarEixoButton.Visible='on';
            app.sy.Value=app.Sy(num);
            app.fs.Value=app.ns(num);
            app.xCheckBox.Value = 0;
            app.yCheckBox.Value = 0;
            app.zCheckBox.Value = 0;
            app.xm2.Enable = 'off';
            app.ym2.Enable = 'off';
            app.zm2.Enable = 'off';
            if(app.dire{num}(3) == 1)
                app.xCheckBox.Value = 1;
                app.xm2.Enable = 'on';
            elseif(app.dire{num}(3) == 2)
                app.ym2.Enable = 'on';
                app.yCheckBox.Value = 1;
            elseif(app.dire{num}(3) == 3)
                app.zCheckBox.Value = 1;
                app.zm2.Enable = 'on';
            end
            
            app.xm1.Value = app.pm{num*2-1}(1);
            app.ym1.Value = app.pm{num*2-1}(2);
            app.zm1.Value = app.pm{num*2-1}(3);
            app.xm2.Value = app.pm{num*2}(1);
            app.ym2.Value = app.pm{num*2}(2);
            app.zm2.Value = app.pm{num*2}(3);
            
        end
        
        function results = atualizareixoeng(app)
            if(string(app.chamada)==string('a'))
            if(not((string(app.EixoDropDown.Value) == string('Selecionar')) && ((string(app.EixoDropDown.Value) == string('Adicionar Eixo')))))
                nomes = [];
                for i=1:length(app.butte)
                    nomes = [nomes string(app.butte(i).Text)];
                end
                
                ind = find(nomes == string(app.EixoDropDown.Value));
                app.xCheckBox_2.Enable = 'off';
                app.yCheckBox_2.Enable = 'off';
                app.zCheckBox_2.Enable = 'off';
                app.xeng_4.Enable = 'off';
                app.yeng_4.Enable = 'off';
                app.zeng_4.Enable = 'off';
                app.xeng_5.Enable = 'off';
                app.yeng_5.Enable = 'off';
                app.zeng_5.Enable = 'off';
                app.xCheckBox_2.Value = 0;
                app.yCheckBox_2.Value = 0;
                app.zCheckBox_2.Value = 0;
                if(string(app.TipodeengrenagemDropDown.Value) == string('Dentes Retos'))
                    if(app.sistema == 0)
                        raiom = app.nd1_2.Value/(app.pd_2.Value)/2;
                    elseif(app.sistema == 1)
                        raiom = app.nd1_2.Value*(app.pd_2.Value)/2;
                    end
                    raioa = raiom*(app.nd2.Value/app.nd1_2.Value);
                elseif(string(app.TipodeengrenagemDropDown.Value) == string('Helicoidal'))
                    if(app.sistema == 0)
                        raiom = app.nd1.Value/(app.pd.Value*cosd(app.ah.Value))/2;
                    elseif(app.sistema == 1)
                        raiom = app.nd1.Value*(app.pd.Value/cosd(app.ah.Value))/2;
                    end
                    raioa = raiom*(app.nd2.Value/app.nd1.Value);
                elseif(string(app.TipodeengrenagemDropDown.Value) == string('Cônica D.R.'))
                    if(app.sistema == 0)
                        raiom = ((app.nd1_3.Value/(app.pd_3.Value))-cosd(atand(app.nd2.Value/app.nd1_3.Value))*app.lff.Value)/2;
                    elseif(app.sistema == 1)
                        raiom = ((app.nd1_3.Value*(app.pd_3.Value))-cosd(atand(app.nd2.Value/app.nd1_3.Value))*app.lff.Value)/2;
                    end
                    raioa = raiom*(app.nd2.Value/app.nd1_3.Value);
                elseif(string(app.TipodeengrenagemDropDown.Value) == string('Sem Fim'))
                    raiom = app.dp_2.Value/2;
                    if(app.sistema == 0)
                        raioa = (app.nd2_2.Value/app.pd_5.Value)/2;
                    elseif(app.sistema == 1)
                        raioa = (app.nd2_2.Value*app.pd_5.Value)/2;
                    end
                end
                val=string(app.TipodeengrenagemDropDown.Value);
                if(val == string('Dentes Retos') || val == string('Helicoidal'))
                    if(app.dire{ind}(3) == 1)
                        app.xCheckBox_2.Value = 1;
                        app.xCheckBox_2.Enable = 'on';
                        app.xeng_4.Enable = 'on';
                        app.xeng_5.Enable = 'on';
                        app.xeng_4.Value = 0;
                        app.xeng_5.Value = 0;
                        app.yeng_4.Value = (raiom+raioa)*cosd(app.ar.Value)+app.pm{(ind-1)*2+1}(2);
                        app.yeng_5.Value = (raiom+raioa)*cosd(app.ar.Value)+app.pm{(ind-1)*2+1}(2);
                        app.zeng_4.Value = (raiom+raioa)*sind(app.ar.Value)+app.pm{(ind-1)*2+1}(3);
                        app.zeng_5.Value = (raiom+raioa)*sind(app.ar.Value)+app.pm{(ind-1)*2+1}(3);
                    elseif(app.dire{ind}(3) == 2)
                        app.yCheckBox_2.Value = 1;
                        app.yCheckBox_2.Enable = 'on';
                        app.yeng_4.Enable = 'on';
                        app.yeng_5.Enable = 'on';
                        app.yeng_4.Value = 0;
                        app.yeng_5.Value = 0;
                        app.zeng_4.Value = (raiom+raioa)*cosd(app.ar.Value)+app.pm{(ind-1)*2+1}(3);
                        app.zeng_5.Value = (raiom+raioa)*cosd(app.ar.Value)+app.pm{(ind-1)*2+1}(3);
                        app.xeng_4.Value = (raiom+raioa)*sind(app.ar.Value)+app.pm{(ind-1)*2+1}(1);
                        app.xeng_5.Value = (raiom+raioa)*sind(app.ar.Value)+app.pm{(ind-1)*2+1}(1);
                    elseif(app.dire{ind}(3) == 3)
                        app.zCheckBox_2.Value = 1;
                        app.zCheckBox_2.Enable = 'on';
                        app.zeng_4.Enable = 'on';
                        app.zeng_5.Enable = 'on';
                        app.zeng_4.Value = 0;
                        app.zeng_5.Value = 0;
                        app.xeng_4.Value = (raiom+raioa)*cosd(app.ar.Value)+app.pm{(ind-1)*2+1}(1);
                        app.xeng_5.Value = (raiom+raioa)*cosd(app.ar.Value)+app.pm{(ind-1)*2+1}(1);
                        app.yeng_4.Value = (raiom+raioa)*sind(app.ar.Value)+app.pm{(ind-1)*2+1}(2);
                        app.yeng_5.Value = (raiom+raioa)*sind(app.ar.Value)+app.pm{(ind-1)*2+1}(2);
                    end
                elseif(val == string('Cônica D.R.') && string(app.ar2.Value) ~= string('Selecionar'))
                    ang=str2num(char(app.ar2.Value));
                    if(app.cone1.Value == 1);
                        direc=1;
                    elseif(app.cone2.Value == 1);
                        direc=-1;
                    end
                    if(app.dire{ind}(3) == 1)
                        if(ang == 0 || ang == 180)
                            app.yCheckBox_2.Value = 1;
                            app.yCheckBox_2.Enable = 'on';
                            app.yeng_4.Enable = 'on';
                            app.yeng_5.Enable = 'on';
                            app.yeng_4.Value = 0;
                            app.yeng_5.Value = 0;
                            app.xeng_4.Value = (raioa)*direc+app.xeng_6.Value;
                            app.xeng_5.Value = (raioa)*direc+app.xeng_6.Value;
                            app.zeng_4.Value = app.zeng_6.Value;
                            app.zeng_5.Value = app.zeng_6.Value;
                        elseif(ang == 90 || ang == 270)
                            app.zCheckBox_2.Value = 1;
                            app.zCheckBox_2.Enable = 'on';
                            app.zeng_4.Enable = 'on';
                            app.zeng_5.Enable = 'on';
                            app.zeng_4.Value = 0;
                            app.zeng_5.Value = 0;
                            app.xeng_4.Value = (raioa)*direc+app.xeng_6.Value;
                            app.xeng_5.Value = (raioa)*direc+app.xeng_6.Value;
                            app.yeng_4.Value = app.yeng_6.Value;
                            app.yeng_5.Value = app.yeng_6.Value;
                        end
                    elseif(app.dire{ind}(3) == 2)
                        if(ang == 0 || ang == 180)
                            app.zCheckBox_2.Value = 1;
                            app.zCheckBox_2.Enable = 'on';
                            app.zeng_4.Enable = 'on';
                            app.zeng_5.Enable = 'on';
                            app.zeng_4.Value = 0;
                            app.zeng_5.Value = 0;
                            app.yeng_4.Value = (raioa)*direc+app.yeng_6.Value;
                            app.yeng_5.Value = (raioa)*direc+app.yeng_6.Value;
                            app.xeng_4.Value = app.xeng_6.Value;
                            app.xeng_5.Value = app.xeng_6.Value;
                        elseif(ang == 90 || ang == 270)
                            app.xCheckBox_2.Value = 1;
                            app.xCheckBox_2.Enable = 'on';
                            app.xeng_4.Enable = 'on';
                            app.xeng_5.Enable = 'on';
                            app.xeng_4.Value = 0;
                            app.xeng_5.Value = 0;
                            app.yeng_4.Value = (raioa)*direc+app.yeng_6.Value;
                            app.yeng_5.Value = (raioa)*direc+app.yeng_6.Value;
                            app.zeng_4.Value = app.zeng_6.Value;
                            app.zeng_5.Value = app.zeng_6.Value;
                        end
                    elseif(app.dire{ind}(3) == 3)
                      if(ang == 0 || ang == 180)
                            app.xCheckBox_2.Value = 1;
                            app.xCheckBox_2.Enable = 'on';
                            app.xeng_4.Enable = 'on';
                            app.xeng_5.Enable = 'on';
                            app.xeng_4.Value = 0;
                            app.xeng_5.Value = 0;
                            app.zeng_4.Value = (raioa)*direc+app.zeng_6.Value;
                            app.zeng_5.Value = (raioa)*direc+app.zeng_6.Value;
                            app.yeng_4.Value = app.yeng_6.Value;
                            app.yeng_5.Value = app.yeng_6.Value;
                        elseif(ang == 90 || ang == 270)
                            app.yCheckBox_2.Value = 1;
                            app.yCheckBox_2.Enable = 'on';
                            app.yeng_4.Enable = 'on';
                            app.yeng_5.Enable = 'on';
                            app.yeng_4.Value = 0;
                            app.yeng_5.Value = 0;
                            app.zeng_4.Value = (raioa)*direc+app.zeng_6.Value;
                            app.zeng_5.Value = (raioa)*direc+app.zeng_6.Value;
                            app.xeng_4.Value = app.xeng_6.Value;
                            app.xeng_5.Value = app.xeng_6.Value;
                      end
                    end
                elseif(val == string('Sem Fim') && string(app.ar2_2.Value) ~= string('Selecionar'))
                    ang=str2num(char(app.ar2_2.Value));
                    if(app.dire{ind}(3) == 1)
                        if(ang == 0 || ang == 180)
                            app.zCheckBox_2.Value = 1;
                            app.zCheckBox_2.Enable = 'on';
                            app.zeng_4.Enable = 'on';
                            app.zeng_5.Enable = 'on';
                            app.zeng_4.Value = 0;
                            app.zeng_5.Value = 0;
                            app.xeng_4.Value = cosd(ang)*(raioa+raiom)+app.xeng_7.Value;
                            app.xeng_5.Value = cosd(ang)*(raioa+raiom)+app.xeng_7.Value;
                            app.yeng_4.Value = app.yeng_7.Value;
                            app.yeng_5.Value = app.yeng_7.Value;
                        elseif(ang == 90 || ang == 270)
                            app.yCheckBox_2.Value = 1;
                            app.yCheckBox_2.Enable = 'on';
                            app.yeng_4.Enable = 'on';
                            app.yeng_5.Enable = 'on';
                            app.yeng_4.Value = 0;
                            app.yeng_5.Value = 0;
                            app.xeng_4.Value = app.xeng_7.Value;
                            app.xeng_5.Value = app.xeng_7.Value;
                            app.zeng_4.Value = sind(ang)*(raioa+raiom)+app.zeng_7.Value;
                            app.zeng_5.Value = sind(ang)*(raioa+raiom)+app.zeng_7.Value;
                        end
                    elseif(app.dire{ind}(3) == 2)
                        if(ang == 0 || ang == 180)
                            app.xCheckBox_2.Value = 1;
                            app.xCheckBox_2.Enable = 'on';
                            app.xeng_4.Enable = 'on';
                            app.xeng_5.Enable = 'on';
                            app.xeng_4.Value = 0;
                            app.xeng_5.Value = 0;
                            app.yeng_4.Value = app.yeng_7.Value;
                            app.yeng_5.Value = app.yeng_7.Value;
                            app.zeng_4.Value = cosd(ang)*(raioa+raiom)+app.zeng_7.Value;
                            app.zeng_5.Value = cosd(ang)*(raioa+raiom)+app.zeng_7.Value;
                        elseif(ang == 90 || ang == 270)
                            app.zCheckBox_2.Value = 1;
                            app.zCheckBox_2.Enable = 'on';
                            app.zeng_4.Enable = 'on';
                            app.zeng_5.Enable = 'on';
                            app.zeng_4.Value = 0;
                            app.zeng_5.Value = 0;
                            app.xeng_4.Value = sind(ang)*(raioa+raiom)+app.xeng_7.Value;
                            app.xeng_5.Value = sind(ang)*(raioa+raiom)+app.xeng_7.Value;
                            app.yeng_4.Value = app.yeng_7.Value;
                            app.yeng_5.Value = app.yeng_7.Value;
                        end
                    elseif(app.dire{ind}(3) == 3)
                      if(ang == 0 || ang == 180)
                            app.yCheckBox_2.Value = 1;
                            app.yCheckBox_2.Enable = 'on';
                            app.yeng_4.Enable = 'on';
                            app.yeng_5.Enable = 'on';
                            app.yeng_4.Value = 0;
                            app.yeng_5.Value = 0;
                            app.xeng_4.Value = cosd(ang)*(raioa+raiom)+app.xeng_7.Value;
                            app.xeng_5.Value = cosd(ang)*(raioa+raiom)+app.xeng_7.Value;
                            app.zeng_4.Value = app.zeng_7.Value;
                            app.zeng_5.Value = app.zeng_7.Value;
                        elseif(ang == 90 || ang == 270)
                            app.xCheckBox_2.Value = 1;
                            app.xCheckBox_2.Enable = 'on';
                            app.xeng_4.Enable = 'on';
                            app.xeng_5.Enable = 'on';
                            app.xeng_4.Value = 0;
                            app.xeng_5.Value = 0;
                            app.yeng_4.Value = sind(ang)*(raioa+raiom)+app.yeng_7.Value;
                            app.yeng_5.Value = sind(ang)*(raioa+raiom)+app.yeng_7.Value;
                            app.zeng_4.Value = app.zeng_7.Value;
                            app.zeng_5.Value = app.zeng_7.Value;
                      end
                    end
                end
            end 
            end
        end
        
        function results = atualizardropdown(app)
            app.EixoDropDown_2.Items = {'Selecionar' 'Adicionar Eixo'};
            if(string(app.TipodeengrenagemDropDown.Value) == string('Dentes Retos'))
                if(app.nd1_2.Value>0 && app.pd_2.Value>0 && app.nd2.Value>0)
                    if(app.sistema == 0)
                        raiom = app.nd1_2.Value/(app.pd_2.Value)/2;
                    elseif(app.sistema == 1)
                        raiom = app.nd1_2.Value*(app.pd_2.Value)/2;
                    end
                    raioa = raiom*(app.nd2.Value/app.nd1_2.Value);
                    x=app.xeng_2.Value;
                    y=app.yeng_2.Value;
                    z=app.zeng_2.Value;
                    atualizardropdown2(app,x,y,z,raiom,raioa)
                end   
            elseif(string(app.TipodeengrenagemDropDown.Value) == string('Helicoidal'))
                if(app.nd1.Value>0 && app.pd.Value>0 && app.ah.Value>0 && app.nd2.Value>0)
                    if(app.sistema == 0)
                        raiom = app.nd1.Value/(app.pd.Value*cosd(app.ah.Value))/2;
                    elseif(app.sistema == 1)
                        raiom = app.nd1.Value*(app.pd.Value/cosd(app.ah.Value))/2;
                    end
                    raioa = raiom*(app.nd2.Value/app.nd1.Value);
                    x=app.xeng.Value;
                    y=app.yeng.Value;
                    z=app.zeng.Value;
                    atualizardropdown2(app,x,y,z,raiom,raioa)
                end
            elseif(string(app.TipodeengrenagemDropDown.Value) == string('Cônica D.R.'))
                if(string(app.ar2.Value) ~= string('Selecionar'))
                    if(app.sistema == 0)
                        raiom = ((app.nd1_3.Value/(app.pd_3.Value))-cosd(atand(app.nd2.Value/app.nd1_3.Value))*app.lff.Value)/2;
                    elseif(app.sistema == 1)
                        raiom = ((app.nd1_3.Value*(app.pd_3.Value))-cosd(atand(app.nd2.Value/app.nd1_3.Value))*app.lff.Value)/2;
                    end
                    raioa = raiom*(app.nd2.Value/app.nd1_3.Value);
                    x=app.xeng_6.Value;
                    y=app.yeng_6.Value;
                    z=app.zeng_6.Value;
                    atualizardropdown2(app,x,y,z,raiom,raioa)
                end
            elseif(string(app.TipodeengrenagemDropDown.Value) == string('Sem Fim'))
                if(string(app.ar2_2.Value) ~= string('Selecionar'))
                    raiom = (app.dp_2.Value)/2;
                    if(app.sistema == 0)
                        raioa = (app.nd2_2.Value/app.pd_5.Value)/2;
                    elseif(app.sistema == 1)
                        raioa = (app.nd2_2.Value*app.pd_5.Value)/2;
                    end
                    x=app.xeng_7.Value;
                    y=app.yeng_7.Value;
                    z=app.zeng_7.Value;
                    atualizardropdown2(app,x,y,z,raiom,raioa)
                end
            end


        end
        
        function results = atualizardropdown2(app,x,y,z,raiom,raioa)
            textdrop={'Selecionar' 'Adicionar Eixo'};
            if(string(app.TipodeengrenagemDropDown.Value) == string('Dentes Retos') || string(app.TipodeengrenagemDropDown.Value) == string('Helicoidal'))
                for(i=1:length(app.nomeex))
                    if(app.dire{i}(3)==1)
                        yy=(raiom+raioa)*cosd(app.ar.Value)+y;
                        zz=(raiom+raioa)*sind(app.ar.Value)+z;
                        if((app.pm{(i-1)*2+1}(2) == yy) && (app.pm{(i-1)*2+1}(3) == zz))
                            textdrop{length(textdrop)+1}=char(app.nomeex(i));
                        end
                    elseif(app.dire{i}(3)==2)
                        xx=(raiom+raioa)*sind(app.ar.Value)+x;
                        zz=(raiom+raioa)*cosd(app.ar.Value)+z;
                        if((app.pm{(i-1)*2+1}(1) == xx) && (app.pm{(i-1)*2+1}(3) == zz))
                            textdrop{length(textdrop)+1}=char(app.nomeex(i));
                        end
                    elseif(app.dire{i}(3)==3)
                        xx=(raiom+raioa)*cosd(app.ar.Value)+x;
                        yy=(raiom+raioa)*sind(app.ar.Value)+y;
                        if((app.pm{(i-1)*2+1}(1) == xx) && (app.pm{(i-1)*2+1}(2) == yy))
                            textdrop{length(textdrop)+1}=char(app.nomeex(i));
                        end
                    end
                end
            elseif(string(app.TipodeengrenagemDropDown.Value) == string('Cônica D.R.'))
                for(i=1:length(app.nomeex))
                    ang=str2num(char(app.ar2.Value));
                    if(app.cone1.Value == 1);
                        direc=1;
                    elseif(app.cone2.Value == 1);
                        direc=-1;
                    end
                    if(app.dire{i}(3) == 1)
                        if(ang == 0 || ang == 180)
                            yy = y;
                            zz = (raioa)*direc+z;
                        elseif(ang == 90 || ang == 270)
                            yy = (raioa)*direc+y;
                            zz = z;
                        end
                        if((app.pm{(i-1)*2+1}(2) == yy) && (app.pm{(i-1)*2+1}(3) == zz))
                                textdrop{length(textdrop)+1}=char(app.nomeex(i));
                        end
                    elseif(app.dire{i}(3) == 2)
                        if(ang == 0 || ang == 180)
                            xx = (raioa)*direc + x;
                            zz = z;
                        elseif(ang == 90 || ang == 270)
                            xx = x;
                            zz = (raioa)*direc + z;
                        end
                        if((app.pm{(i-1)*2+1}(1) == xx) && (app.pm{(i-1)*2+1}(3) == zz))
                            textdrop{length(textdrop)+1}=char(app.nomeex(i));
                        end
                    elseif(app.dire{i}(3) == 3)
                        if(ang == 0 || ang == 180)
                            xx = x;
                            yy = (raioa)*direc+y;
                        elseif(ang == 90 || ang == 270)
                            xx = (raioa)*direc+x;
                            yy = y;
                        end
                        if((app.pm{(i-1)*2+1}(1) == xx) && (app.pm{(i-1)*2+1}(2) == yy))
                            textdrop{length(textdrop)+1}=char(app.nomeex(i));
                        end
                      
                    end
                end
            elseif(string(app.TipodeengrenagemDropDown.Value) == string('Sem Fim'))
                for(i=1:length(app.nomeex))
                    ang=str2num(char(app.ar2_2.Value));
                    if(app.dire{i}(3) == 1)
                        if(ang == 0 || ang == 180)
                            zz = z+cosd(ang)*(raiom+raioa);
                            yy = y;
                        elseif(ang == 90 || ang == 270)
                            zz = z;
                            yy = y+sind(ang)*(raiom+raioa);
                        end
                        if((app.pm{(i-1)*2+1}(2) == yy) && (app.pm{(i-1)*2+1}(3) == zz))
                                textdrop{length(textdrop)+1}=char(app.nomeex(i));
                        end
                    elseif(app.dire{i}(3) == 2)
                        if(ang == 0 || ang == 180)
                            xx = x+cosd(ang)*(raiom+raioa);
                            zz = z;
                        elseif(ang == 90 || ang == 270)
                            xx = x;
                            zz = z+sind(ang)*(raiom+raioa);
                        end
                        if((app.pm{(i-1)*2+1}(1) == xx) && (app.pm{(i-1)*2+1}(3) == zz))
                            textdrop{length(textdrop)+1}=char(app.nomeex(i));
                        end
                    elseif(app.dire{i}(3) == 3)
                        if(ang == 0 || ang == 180)
                            xx = x;
                            yy = y+cosd(ang)*(raiom+raioa);
                        elseif(ang == 90 || ang == 270)
                            xx = x+sind(ang)*(raiom+raioa);
                            yy = y;
                        end
                        if((app.pm{(i-1)*2+1}(1) == xx) && (app.pm{(i-1)*2+1}(2) == yy))
                            textdrop{length(textdrop)+1}=char(app.nomeex(i));
                        end
                      
                    end
                end
            end
            app.EixoDropDown_2.Items = textdrop;
        end
        
        function results = deletarengrenagens(app,but,b)
            if(b==1)
                a=nan;
                app.nomee(but)=a;
                app.nomeep(but)=a;
                ind=[app.refe{but}(1) app.refe{but}(3)];
                len=[app.refe{but}(2) app.refe{but}(4)];
                for(i=1:2)
                    app.na{ind(i)}(len(i))=a;
                    app.posa{ind(i)}(len(i))=a;
                    app.fatr{ind(i)}(len(i))=a;
                    app.n{ind(i)}(len(i))=a;
                    app.pn{ind(i)}(len(i))=a;
                    app.tetan{ind(i)}(len(i))=a;
                    app.phi{ind(i)}(len(i))= a;
                    app.tm{ind(i)}(len(i))=a;
                    app.tipoe{ind(i)}(len(i)) = a;
                    app.rot{ind(i)}(len(i))=a;
                    app.rota{ind(i)}(len(i))=a;
                    app.dir{ind(i)}(len(i)) = a;
                    app.dira{ind(i)}(len(i)) = a;
                    app.p{ind(i)}{len(i)}=[a];
                    app.dc{ind(i)}(len(i))=a;
                    app.lf{ind(i)}(len(i))=a;
                    app.pot{ind(i)}(len(i))=a;
                    app.rotacao{ind(i)}(len(i))=a;
                    
                    %% Calculo dos diametros
                    
                    app.d{ind(i)}(len(i)) = a;
                    app.r{ind(i)}(len(i)) = a;
                    app.da{ind(i)}(len(i)) = a;
                    app.ra{ind(i)}(len(i)) = a;
                    
                    %% Posicao das engrenagens
                    
                    app.pa{ind(i)}{len(i)}=[a];
                    
                    %% Pontos de contato
        
                    app.pc{ind(i)}{len(i)}=[a];
             
                    %% Calculo dos modulos
                    
                    app.ft{ind(i)}(len(i)) = a; % Tangencial
                    app.fr{ind(i)}(len(i)) = a; % Radial
                    app.fa{ind(i)}(len(i)) = a; % Axial
                    
                    
                    %% Direcao das Forcas
                    
                    app.f{ind(i)}{len(i)}=[a];
                end
            else
                if(b==0)
                    app.nomee(but)=[];
                    app.nomeep(but)=[];
                end
                ind=[app.refe{but}(1) app.refe{but}(3)];
                len=[app.refe{but}(2) app.refe{but}(4)];
                for(i=1:2)
                    app.na{ind(i)}(len(i))=[];
                    app.posa{ind(i)}(len(i))=[];
                    app.fatr{ind(i)}(len(i))=[];
                    app.n{ind(i)}(len(i))=[];
                    app.pn{ind(i)}(len(i))=[];
                    app.tetan{ind(i)}(len(i))=[];
                    app.phi{ind(i)}(len(i))= [];
                    app.tm{ind(i)}(len(i))=[];
                    app.tipoe{ind(i)}(len(i)) = [];
                    app.rot{ind(i)}(len(i))=[];
                    app.rota{ind(i)}(len(i))=[];
                    app.dir{ind(i)}(len(i)) = [];
                    app.dira{ind(i)}(len(i)) = [];
                    app.rotacao{ind(i)}(len(i)) = [];
                    app.pot{ind(i)}(len(i)) = [];
                    if(b==1)
                        app.p{ind(i)}{len(i)}=[];
                    elseif(b==0)
                        app.p{ind(i)}(len(i))=[];
                    end
                    app.dc{ind(i)}(len(i))=[];
                    app.lf{ind(i)}(len(i))=[];
                    
                    %% Calculo dos diametros
                    
                    app.d{ind(i)}(len(i)) = [];
                    app.r{ind(i)}(len(i)) = [];
                    app.da{ind(i)}(len(i)) = [];
                    app.ra{ind(i)}(len(i)) = [];
                    
                    %% Posicao das engrenagens
                    
                    if(b==1)
                        app.pa{ind(i)}{len(i)}=[];
                    elseif(b==0)
                        app.pa{ind(i)}(len(i))=[];
                    end
                    
                    %% Pontos de contato
        
                    if(b==1)
                        app.pc{ind(i)}{len(i)}=[];
                    elseif(b==0)
                        app.pc{ind(i)}(len(i))=[];
                    end
             
                    %% Calculo dos modulos
                    
                    app.ft{ind(i)}(len(i)) = []; % Tangencial
                    app.fr{ind(i)}(len(i)) = []; % Radial
                    app.fa{ind(i)}(len(i)) = []; % Axial
                    
                    
                    %% Direcao das Forcas
                    
                    if(b==1)
                        app.f{ind(i)}{len(i)}=[];
                    elseif(b==0)
                        app.f{ind(i)}(len(i))=[];
                    end
                end
            end
            
        end
        
        function results = adicionarengrenagens(app,req,req2)
            
            app.AdicionarEngrenagensButton.Enable = 'off';
            if(req2==-1)
                nom=length(app.nomee)+1;
            else
                nom=req2;
            end
                
            if(length(app.nomee)==0)
                app.nomee=string(['Engrenagens ' num2str(length(app.nomee)+1) ' ' app.Nomee.Value]);
                app.nomeep=string(app.Nomee.Value);
            else
                app.nomee(nom)=string(['Engrenagens ' num2str(nom) ' ' app.Nomee.Value]);
                app.nomeep(nom)=string(app.Nomee.Value);
            end
            nomes = [];
            for i=1:length(app.butte)
                nomes = [nomes string(app.butte(i).Text)];
            end
            ind = find(nomes == string(app.EixoDropDown.Value));
            
            if(length(app.na) < ind)
                app.na{ind}=[];
                app.posa{ind}=[];
                app.n{ind}=[];
                app.pn{ind}=[];
                app.tetan{ind}=[];
                app.phi{ind}=[];
                app.tm{ind}=[];
                app.rot{ind}=[];
                app.rota{ind}=[];
                app.dir{ind}=[];
                app.dira{ind}=[];
                app.tipoe{ind}=[];
                app.p{ind}=[];
                app.d{ind}=[];
                app.r{ind}=[];
                app.da{ind}=[];
                app.ra{ind}=[];
                app.pa{ind}=[];
                app.pc{ind}=[];
                app.f{ind}=[];
                app.fr{ind}=[];
                app.fa{ind}=[];
                app.ft{ind}=[];
                app.lf{ind}=[];
                app.dc{ind}=[];
                app.pot{ind}=[];
                app.rotacao{ind}=[];
                app.fatr{ind}=[];
           end
           if(not(req==-1))
                ind = req(1);
                len = req(2);
            else
                len = length(app.na{ind})+1;
            end
            if(app.TipodeengrenagemDropDown.Value == string('Helicoidal'))
                app.na{ind}(len)=app.nd2.Value;
                app.posa{ind}(len)=app.ar.Value;
                app.n{ind}(len)=app.nd1.Value;
                app.fatr{ind}(len)= 0;
                app.pn{ind}(len)=app.pd.Value;
                if(app.sistema==1)
                    app.pn{ind}(len)=1/app.pn{ind}(length(app.pn{ind}));
                end
                app.tetan{ind}(len)=app.ap.Value;
                app.phi{ind}(len)= app.ah.Value;
                app.pot{ind}(len)=app.pot1.Value;
                app.rotacao{ind}(len)=app.rot_1.Value;
                if(app.sistema == 1)
                    app.tm{ind}(len)=1000*app.pot1.Value/(app.rot_1.Value/60*2*pi);
                elseif(app.sistema == 0)
                    app.tm{ind}(len)=550*app.pot1.Value/(app.rot_1.Value/60*2*pi);
                end
                app.lf{ind}(len)=0;
                app.dc{ind}(len)=0;
                miss2=0;
                if(length(app.rot{ind})==1)
                    miss=ismissing(app.rot{ind});
                    miss2=ismember(1,miss);
                end
                if(length(app.rot{ind}) == 0 || miss2)
                    app.tipoe{ind} = string(app.TipodeengrenagemDropDown.Value);
                    if(app.ahor.Value==1)
                        app.rot{ind}=app.strah;
                        app.rota{ind}=app.strh;
                    elseif(app.hor.Value==1)
                        app.rot{ind}=app.strh;
                        app.rota{ind}=app.strah;
                    end
                    if(app.rd.Value == 1)
                        app.dir{ind} = app.strd;
                        app.dira{ind} = app.stre;
                    elseif(app.re.Value == 1)
                        app.dir{ind} = app.stre;
                        app.dira{ind} = app.strd;
                    end

                else
                    app.tipoe{ind}(len) = string(app.TipodeengrenagemDropDown.Value);
                    app.rot{ind}(len)=app.rot{ind}(1);
                    app.rota{ind}(len)=app.rota{ind}(1);
                    if(app.rd.Value == 1)
                        app.dir{ind}(len) = app.strd;
                        app.dira{ind}(len) = app.stre;
                    elseif(app.re.Value ==1)
                        app.dir{ind}(len) = app.stre;
                        app.dira{ind}(len) = app.strd;
                    end
                end
                app.p{ind}{(len)}=[app.xeng.Value app.yeng.Value app.zeng.Value];
                app.Nomee.Value = '';
                app.nd1.Value = 10;
                app.pd.Value = 5;
                app.ap.Value = 0;
                app.ah.Value = 0;
                app.pot1.Value = 0;
                app.xeng.Value = 0;
                app.yeng.Value = 0;
                app.zeng.Value = 0;
                app.rd.Value = 1;
                app.re.Value = 0;
                app.nd2.Value = 10;
                app.ar.Value = 0;
                app.rot_1.Value = 0;
                app.hor.Value = 1;
                app.ahor.Value = 0;
            elseif(app.TipodeengrenagemDropDown.Value == string('Dentes Retos'))
                app.na{ind}(len)=app.nd2.Value;
                app.posa{ind}(len)=app.ar.Value;
                app.n{ind}(len)=app.nd1_2.Value;
                app.fatr{ind}(len)= 0;
                app.pn{ind}(len)=app.pd_2.Value;
                if(app.sistema==1)
                    app.pn{ind}(len)=1/app.pn{ind}(length(app.pn{ind}));
                end
                app.tetan{ind}(len)=app.ap_2.Value;
                app.phi{ind}(len)= 0;
                app.pot{ind}(len)=app.pot_2.Value;
                app.rotacao{ind}(len)=app.rot_2.Value;
                if(app.sistema == 1)
                    app.tm{ind}(len)=1000*app.pot_2.Value/(app.rot_2.Value/60*2*pi);
                elseif(app.sistema == 0)
                    app.tm{ind}(len)=550*app.pot_2.Value/(app.rot_2.Value/60*2*pi);
                end
                app.lf{ind}(len)=0;
                app.dc{ind}(len)=0;
                miss2=0;
                if(length(app.rot{ind})==1)
                    miss=ismissing(app.rot{ind});
                    miss2=ismember(1,miss);
                end
                if(length(app.rot{ind}) == 0 || miss2)
                    app.tipoe{ind} = string(app.TipodeengrenagemDropDown.Value);
                    if(app.hor_2.Value==1)
                        app.rot{ind}=app.strh;
                        app.rota{ind}=app.strah;
                    else
                        app.rot{ind}=app.strah;
                        app.rota{ind}=app.strh;
                    end
                    app.dir{ind} = app.strd;
                    app.dira{ind} = app.stre;
                else
                    app.tipoe{ind}(len) = string(app.TipodeengrenagemDropDown.Value);
                    app.rot{ind}(len)=app.rot{ind}(1);
                    app.rota{ind}(len)=app.rota{ind}(1);
                    app.dir{ind}(len) = app.strd;
                    app.dira{ind}(len) = app.stre;
                end
                app.p{ind}{len}=[app.xeng_2.Value app.yeng_2.Value app.zeng_2.Value];
                app.Nomee.Value = '';
                app.nd1_2.Value = 10;
                app.pd_2.Value = 5;
                app.ap_2.Value = 0;
                app.pot_2.Value = 0;
                app.xeng_2.Value = 0;
                app.yeng_2.Value = 0;
                app.zeng_2.Value = 0;
                app.nd2.Value = 10;
                app.ar.Value = 0;
                app.rot_2.Value = 0;
                app.hor_2.Value = 1;
                app.ahor_2.Value = 0;
            elseif(app.TipodeengrenagemDropDown.Value == string('Cônica D.R.'))
                app.na{ind}(len)=app.nd2.Value;
                app.posa{ind}(len)=str2num(app.ar2.Value);
                app.n{ind}(len)=app.nd1_3.Value;
                app.fatr{ind}(len)= 0;
                app.pn{ind}(len)=app.pd_3.Value;
                if(app.sistema==1)
                    app.pn{ind}(len)=1/app.pn{ind}(length(app.pn{ind}));
                end
                app.tetan{ind}(len)=app.ap_3.Value;
                app.phi{ind}(len)= 0;
                app.pot{ind}(len)=app.pot_3.Value;
                app.rotacao{ind}(len)=app.rot_3.Value;
                if(app.sistema == 1)
                    app.tm{ind}(len)=1000*app.pot_3.Value/(app.rot_3.Value/60*2*pi);
                elseif(app.sistema == 0)
                    app.tm{ind}(len)=550*app.pot_3.Value/(app.rot_3.Value/60*2*pi);
                end
                app.lf{ind}(len)=app.lff.Value;
                if(app.cone1.Value == 1)
                    app.dc{ind}(len)=1;
                elseif(app.cone2.Value == 1)
                    app.dc{ind}(len)=-1;
                end
                ang=app.posa{ind}(len);
                miss2=0;
                if(length(app.rot{ind})==1)
                    miss=ismissing(app.rot{ind});
                    miss2=ismember(1,miss);
                end
                if(length(app.rot{ind}) == 0 || miss2)
                    app.tipoe{ind} = string(app.TipodeengrenagemDropDown.Value);
                    if(app.rd_3.Value == 1)
                        app.dir{ind} = app.strd;
                    elseif(app.re.Value == 1)
                        app.dir{ind} = app.stre;
                    end
                    if(app.hor_3.Value == 1)
                        app.rot{ind}=app.strh;
                        if(app.dc{ind}(len) == 1)
                            if(ang == 0 || ang == 90)
                                app.rota{ind}=app.strh;
                            elseif(ang == 180 || ang == 270)
                                app.rota{ind}=app.strah;
                            end
                        elseif(app.dc{ind}(length(app.dc{ind})) == -1)
                            if(ang == 0 || ang == 90)
                                app.rota{ind}=app.strah;
                            elseif(ang == 180 || ang == 270)
                                app.rota{ind}=app.strh;
                            end
                        end
                    else
                        app.rot{ind}=app.strah;
                        if(app.dc{ind}(len) == 1)
                            if(ang == 0 || ang == 90)
                                app.rota{ind}(len)=app.strah;
                            elseif(ang == 180 || ang == 270)
                                app.rota{ind}(len)=app.strh;
                            end
                        elseif(app.dc{ind}(len) == -1)
                            if(ang == 0 || ang == 90)
                                app.rota{ind}(len)=app.strh;
                            elseif(ang == 180 || ang == 270)
                                app.rota{ind}(len)=app.strah;
                            end
                        end
                    end
                    app.dir{ind}(len) = app.strd;
                    app.dira{ind}(len) = app.stre;
                else
                    app.tipoe{ind}(len) = string(app.TipodeengrenagemDropDown.Value);
                    app.rot{ind}(len)=app.rot{ind}(1);
                    app.rota{ind}(len)=app.rota{ind}(1);
                    app.dir{ind}(len) = app.strd;
                    app.dira{ind}(len) = app.stre;
                end
                app.p{ind}{len}=[app.xeng_6.Value app.yeng_6.Value app.zeng_6.Value];
                app.Nomee.Value = '';
                app.nd1_3.Value = 10;
                app.pd_3.Value = 5;
                app.ap_3.Value = 0;
                app.pot_3.Value = 0;
                app.xeng_6.Value = 0;
                app.yeng_6.Value = 0;
                app.zeng_6.Value = 0;
                app.nd2.Value = 10;
                app.rot_3.Value = 0;
                app.ahor_3.Value = 0;
                app.hor_3.Value = 1;
                app.ar2.Value = 'Selecionar';
            
            elseif(app.TipodeengrenagemDropDown.Value == string('Sem Fim'))
                app.na{ind}(len)=app.nd2_2.Value;
                app.posa{ind}(len)=str2num(app.ar2_2.Value);
                app.n{ind}(len)=app.nd1_4.Value;
                app.pn{ind}(len)=app.pd_5.Value;
                if(app.AtritoaltoCheckBox.Value == 1)
                    app.fatr{ind}(len)= 1;
                elseif(app.AtritobaixoCheckBox.Value == 1)
                    app.fatr{ind}(len)= 2;
                end
                if(app.sistema==1)
                    app.pn{ind}(len)=1/app.pn{ind}(length(app.pn{ind}));
                end
                app.tetan{ind}(len)=app.ap_4.Value;
                app.phi{ind}(len)= 0;
                app.pot{ind}(len)=app.pot_4.Value;
                app.rotacao{ind}(len)=app.rot_4.Value;
                app.d{ind}(len) = app.dp_2.Value;
                app.lf{ind}(len)=0;
                app.dc{ind}(len)=0;
                ang=app.posa{ind}(len);
                miss2=0;
                if(length(app.rot{ind})==1)
                    miss=ismissing(app.rot{ind});
                    miss2=ismember(1,miss);
                end
                if(length(app.rot{ind}) == 0 || miss2)
                    app.tipoe{ind} = string(app.TipodeengrenagemDropDown.Value);
                    if(app.rd_3.Value == 1)
                        app.dir{ind} = app.strd;
                        app.dira{ind} = app.strd;
                    elseif(app.re_3.Value == 1)
                        app.dir{ind} = app.stre;
                        app.dira{ind} = app.stre;
                    end
                    if(app.hor_4.Value == 1)
                        app.rot{ind}=app.strh;
                        if(app.dir{ind}(len) == app.stre)
                            if(ang == 0 || ang == 270)
                                app.rota{ind}=app.strah;
                            elseif(ang == 180 || ang == 90)
                                app.rota{ind}=app.strh;
                            end
                        elseif(app.dir{ind}(len) == app.strd)
                            if(ang == 0 || ang == 270)
                                app.rota{ind}=app.strh;
                            elseif(ang == 180 || ang == 90)
                                app.rota{ind}=app.strah;
                            end
                        end
                    else
                        app.rot{ind}=app.strah;
                        if(app.dir{ind}(len) == app.stre)
                            if(ang == 0 || ang == 270)
                                app.rota{ind}=app.strh;
                            elseif(ang == 180 || ang == 90)
                                app.rota{ind}=app.strah;
                            end
                        elseif(app.dir{ind}(len) == app.strd)
                            if(ang == 0 || ang == 270)
                                app.rota{ind}=app.strah;
                            elseif(ang == 180 || ang == 90)
                                app.rota{ind}=app.strh;
                            end
                        end
                    end
                else
                    app.tipoe{ind}(len) = string(app.TipodeengrenagemDropDown.Value);
                    app.rot{ind}(len)=app.rot{ind}(1);
                    app.rota{ind}(len)=app.rota{ind}(1);
                    if(app.rd_3.Value == 1)
                        app.dir{ind}(len) = app.strd;
                        app.dira{ind}(len) = app.strd;
                    elseif(app.re_3.Value == 1)
                        app.dir{ind}(len) = app.stre;
                        app.dira{ind}(len) = app.strd;
                    end
                end
                app.p{ind}{len}=[app.xeng_7.Value app.yeng_7.Value app.zeng_7.Value];
                app.Nomee.Value = '';
                app.nd1_4.Value = 10;
                app.ap_4.Value = 0;
                app.dp_2.Value = 5;
                app.pot_4.Value = 0;
                app.xeng_7.Value = 0;
                app.yeng_7.Value = 0;
                app.zeng_7.Value = 0;
                app.nd2_2.Value = 10;
                app.pd_5.Value = 5;
                app.rot_4.Value = 0;
                app.ahor_4.Value = 0;
                app.rd_3.Value = 1;
                app.re_3.Value = 0;
                app.AtritoaltoCheckBox.Value = 1;
                app.AtritobaixoCheckBox.Value = 0;
                app.hor_4.Value = 1;
                app.ar2_2.Value = 'Selecionar';
            end
            
            %% Calculo dos diametros
            
            if(app.TipodeengrenagemDropDown.Value == string('Helicoidal') || app.TipodeengrenagemDropDown.Value == string('Dentes Retos'))
                app.d{ind}(len) = app.n{ind}(len)/(app.pn{ind}(len)*cosd(app.phi{ind}(len)));
                app.r{ind}(len) = app.d{ind}(len)/2;
                app.da{ind}(len) = app.na{ind}(len)/(app.pn{ind}(len)*cosd(app.phi{ind}(len)));
                app.ra{ind}(len) = app.da{ind}(len)/2;
            elseif(app.TipodeengrenagemDropDown.Value == string('Cônica D.R.'))
                app.d{ind}(len) = app.n{ind}(len)/(app.pn{ind}(len))-app.lf{ind}(len)*cosd(atand(app.na{ind}(len)/app.n{ind}(len)));
                app.r{ind}(len) = app.d{ind}(len)/2;
                app.da{ind}(len) = app.d{ind}(len)*app.na{ind}(len)/app.n{ind}(len);
                app.ra{ind}(len) = app.da{ind}(len)/2;
            elseif(app.TipodeengrenagemDropDown.Value == string('Sem Fim')) 
                app.r{ind}(len) = app.d{ind}(len)/2;
                app.da{ind}(len) = app.na{ind}(len)/app.pn{ind}(len);
                app.ra{ind}(len) = app.da{ind}(len)/2;
            end
            
            %% Posicao das engrenagens
            
            app.pa{ind}{len}=[0 0 0];
            if(app.TipodeengrenagemDropDown.Value == string('Helicoidal') || app.TipodeengrenagemDropDown.Value == string('Dentes Retos') || app.TipodeengrenagemDropDown.Value == string('Sem Fim'))
                app.pa{ind}{len}(app.dire{ind}(1)) = (app.r{ind}(len)+app.ra{ind}(len))*cosd(app.posa{ind}(len))+app.p{ind}{len}((app.dire{ind}(1)));
                app.pa{ind}{len}(app.dire{ind}(2)) = (app.r{ind}(len)+app.ra{ind}(len))*sind(app.posa{ind}(len))+app.p{ind}{len}((app.dire{ind}(2)));
                app.pa{ind}{len}(app.dire{ind}(3)) = app.p{ind}{len}(app.dire{ind}(3));
            elseif(app.TipodeengrenagemDropDown.Value == string('Cônica D.R.'))
                app.pa{ind}{len}(app.dire{ind}(1)) = app.r{ind}(len)*cosd(ang) + app.p{ind}{len}(app.dire{ind}(1));
                app.pa{ind}{len}(app.dire{ind}(2)) = app.r{ind}(len)*sind(ang) + app.p{ind}{len}(app.dire{ind}(2));
                app.pa{ind}{len}(app.dire{ind}(3)) = app.dc{ind}(len)*app.ra{ind}(len) + app.p{ind}{len}(app.dire{ind}(3));
            end
            
            
            %% Pontos de contato
            
            app.pc{ind}{len}=[0 0 0];
            app.pc{ind}{len}(app.dire{ind}(1)) = (app.r{ind}(len))*cosd(app.posa{ind}(len))+app.p{ind}{len}((app.dire{ind}(1)));
            app.pc{ind}{len}(app.dire{ind}(2)) = (app.r{ind}(len))*sind(app.posa{ind}(len))+app.p{ind}{len}((app.dire{ind}(2)));
            app.pc{ind}{len}(app.dire{ind}(3)) = app.p{ind}{len}(app.dire{ind}(3));
            
            
            %% Calculo dos modulos
            
            if(app.TipodeengrenagemDropDown.Value == string('Helicoidal') || app.TipodeengrenagemDropDown.Value == string('Dentes Retos'))
                if(app.sistema == 1)
                    app.ft{ind}(len) = abs(app.tm{ind}(len)/(app.r{ind}(len)/1000)); % Tangencial
                elseif(app.sistema == 0)
                    app.ft{ind}(len) = abs(app.tm{ind}(len)/app.r{ind}(len)); % Tangencial
                end
                app.fr{ind}(len) = abs(app.ft{ind}(len) * tand(app.tetan{ind}(len))/cosd(app.phi{ind}(len))); % Radial
                app.fa{ind}(len) = abs(app.ft{ind}(len) * tand(app.phi{ind}(len))); % Axial
            elseif(app.TipodeengrenagemDropDown.Value == string('Cônica D.R.'))
                angc=atand(app.na{ind}(len)/app.n{ind}(len));
                if(app.sistema == 1)
                    app.ft{ind}(len) = abs(app.tm{ind}(len)/(app.r{ind}(len)/1000)); % Tangencial
                elseif(app.sistema == 0)
                    app.ft{ind}(len) = abs(app.tm{ind}(len)/app.r{ind}(len)); % Tangencial
                end
                app.fr{ind}(len) = abs(app.ft{ind}(len) * tand(app.tetan{ind}(len))*cosd(angc)); % Radial
                app.fa{ind}(len) = abs(app.ft{ind}(len) * tand(app.tetan{ind}(len))*sind(angc)); % Axial
            elseif(app.TipodeengrenagemDropDown.Value == string('Sem Fim'))
                load('Fig13_41A.txt')
                load('Fig13_41B.txt')
                
                xa=Fig13_41A(:,1);
                ya=Fig13_41A(:,2);
                xb=Fig13_41B(:,1);
                yb=Fig13_41B(:,2);
                
                f1=fit(xa,ya,'smoothingspline');
                f2=fit(xb,yb,'smoothingspline');
                
                px=pi/(app.pn{ind}(len));
                l=px*app.n{ind}(len);
                lambda=atand(l/(pi*app.d{ind}(len)));
                vw=pi*app.d{ind}(len)*app.rotacao{ind}(len)/12;
                ng=app.n{ind}(len)/app.na{ind}(len)*app.rotacao{ind}(len);
                vg=pi*app.da{ind}(len)*ng/12;
                vs=vw/cosd(lambda);
                if(app.sistema == 1)
                    app.ft{ind}(len) = 60000*abs(app.pot{ind}(len)/vw); % Tangencial
                elseif(app.sistema == 0)
                    app.ft{ind}(len) = 33000*abs(app.pot{ind}(len)/vw);
                end
                if(app.fatr{ind}(len) == 1)
                    fat=f1(vs);
                elseif(app.fatr{ind}(len) == 2)
                    fat=f2(vs);
                end
                w=app.ft{ind}(len)/(cosd(app.tetan{ind}(len))*sind(lambda)+fat*cosd(lambda));
                app.fr{ind}(len) = w*sind(app.tetan{ind}(len));
                app.fa{ind}(len) = w*cosd(app.tetan{ind}(len)*cosd(lambda)-fat*sind(lambda));
                app.tm{ind}(len)=app.ft{ind}(len)/app.r{ind}(len);
            end
            
            
            %% Direcao das Forcas
            
            if(app.TipodeengrenagemDropDown.Value == string('Helicoidal') || app.TipodeengrenagemDropDown.Value == string('Dentes Retos'))
                if(app.dir{ind}(len) == app.strd)
                    if(app.rot{ind}(len) == app.strah)
                        app.f{ind}{len} = [0 0 0];
                        app.f{ind}{len}(app.dire{ind}(1)) = app.ft{ind}(len)*sind(app.posa{ind}(len))-app.fr{ind}(len)*cosd(app.posa{ind}(len));
                        app.f{ind}{len}(app.dire{ind}(2)) = -app.ft{ind}(len)*cosd(app.posa{ind}(len))-app.fr{ind}(len)*sind(app.posa{ind}(len));
                        app.f{ind}{len}(app.dire{ind}(3)) = app.fa{ind}(len);
                    elseif(app.rot{ind}(len) == app.strh)
                        app.f{ind}{len} = [0 0 0];
                        app.f{ind}{len}(app.dire{ind}(1)) = -app.ft{ind}(len)*sind(app.posa{ind}(len))-app.fr{ind}(len)*cosd(app.posa{ind}(len));
                        app.f{ind}{len}(app.dire{ind}(2)) = app.ft{ind}(len)*cosd(app.posa{ind}(len))-app.fr{ind}(len)*sind(app.posa{ind}(len));
                        app.f{ind}{len}(app.dire{ind}(3)) = -app.fa{ind}(len);
                    end
                elseif(app.dir{ind}(len) == app.stre)
                    if(app.rot{ind}(len) == app.strah)
                        app.f{ind}{len} = [0 0 0];
                        app.f{ind}{len}(app.dire{ind}(1)) = app.ft{ind}(len)*sind(app.posa{ind}(len))-app.fr{ind}(len)*cosd(app.posa{ind}(len));
                        app.f{ind}{len}(app.dire{ind}(2)) = -app.ft{ind}(len)*cosd(app.posa{ind}(len))-app.fr{ind}(len)*sind(app.posa{ind}(len));
                        app.f{ind}{len}(app.dire{ind}(3)) = -app.fa{ind}(len);
                    elseif(app.rot{ind}(len) == app.strh)
                        app.f{ind}{len} = [0 0 0];
                        app.f{ind}{len}(app.dire{ind}(1)) = -app.ft{ind}(len)*sind(app.posa{ind}(len))-app.fr{ind}(len)*cosd(app.posa{ind}(len));
                        app.f{ind}{len}(app.dire{ind}(2)) = app.ft{ind}(len)*cosd(app.posa{ind}(len))-app.fr{ind}(len)*sind(app.posa{ind}(len));
                        app.f{ind}{len}(app.dire{ind}(3)) = app.fa{ind}(len);
                    end
                end
            elseif(app.TipodeengrenagemDropDown.Value == string('Cônica D.R.'))
                app.f{ind}{len} = [0 0 0];
                if(app.rot{ind}{len}==app.strh)
                    rotc=-1;
                elseif(app.rot{ind}{len}==app.strah)
                    rotc=1;
                end
                app.f{ind}{len}(app.dire{ind}(1)) = +rotc*app.ft{ind}(len)*sind(ang)-app.fa{ind}(len)*cosd(ang);
                app.f{ind}{len}(app.dire{ind}(2)) = -rotc*app.ft{ind}(len)*cosd(ang)-app.fa{ind}(len)*sind(ang);
                app.f{ind}{len}(app.dire{ind}(3)) = -app.dc{ind}(len)*app.fr{ind}(len);
            elseif(app.TipodeengrenagemDropDown.Value == string('Sem Fim'))
                app.f{ind}{len} = [0 0 0];
                if(app.rot{ind}{len}==app.strh)
                    rotc=-1;
                elseif(app.rot{ind}{len}==app.strah)
                    rotc=1;
                end
                if(app.dir{ind}{len}==app.stre)
                    dirc=-1;
                elseif(app.dir{ind}{len}==app.strd)
                    dirc=1;
                end
                app.f{ind}{len}(app.dire{ind}(1)) = -rotc*app.ft{ind}(len)*sind(ang)+app.fr{ind}(len)*cosd(ang);
                app.f{ind}{len}(app.dire{ind}(2)) = rotc*app.ft{ind}(len)*cosd(ang)+app.fr{ind}(len)*sind(ang);
                app.f{ind}{len}(app.dire{ind}(3)) = -dirc*app.fa{ind}(len);
                app.f{ind}{len}=-app.f{ind}{len};
            end
            
            nomes2 = [];
            for i=1:length(app.butte)
                nomes2 = [nomes2 string(app.butte(i).Text)];
            end
            ind2 = find(nomes2 == string(app.EixoDropDown_2.Value));
            if(length(app.na) < ind2)
                app.na{ind2}=[];
                app.posa{ind2}=[];
                app.n{ind2}=[];
                app.pn{ind2}=[];
                app.lf{ind2}=[];
                app.dc{ind2}=[];
                app.tetan{ind2}=[];
                app.phi{ind2}=[];
                app.tm{ind2}=[];
                app.rot{ind2}=[];
                app.rota{ind2}=[];
                app.dir{ind2}=[];
                app.dira{ind2}=[];
                app.tipoe{ind2}=[];
                app.p{ind2}=[];
                app.d{ind2}=[];
                app.r{ind2}=[];
                app.da{ind2}=[];
                app.ra{ind2}=[];
                app.pa{ind2}=[];
                app.pc{ind2}=[];
                app.f{ind2}=[];
                app.fr{ind2}=[];
                app.fa{ind2}=[];
                app.ft{ind2}=[];
                app.rotacao{ind2}=[];
                app.pot{ind2}=[];
                app.fatr{ind2}=[];
            end
            
            if(not(req==-1))
                ind2 = req(3);
                l = req(4);
            else
                l=length(app.na{ind2})+1;
            end
            
            app.na{ind2}(l)=app.n{ind}(len);
            app.posa{ind2}(l)=app.posa{ind}(len)+180;
            app.fatr{ind2}(l)=app.fatr{ind}(len);
            app.n{ind2}(l)=app.na{ind}(len);
            app.pn{ind2}(l)=app.pn{ind}(len);
            app.tetan{ind2}(l)=app.tetan{ind}(len);
            app.phi{ind2}(l)=app.phi{ind}(len);
            app.pot{ind2}(l)=app.pot{ind}(len);
            app.rotacao{ind2}(l)=app.rotacao{ind}(len)*app.na{ind2}(l)/app.n{ind2}(l);
            if(app.TipodeengrenagemDropDown.Value == string('Cônica D.R.'))
                app.dc{ind2}(l) = app.dc{ind}(len)*(-cosd(ang)-sind(ang));
                app.lf{ind2}(l) = app.lf{ind}(len);
            else
                app.dc{ind2}(l) = 0;
                app.lf{ind2}(l) = 0;
            end
            if((length(app.rot{ind2})==1 && ismissing(app.rot{ind2})) || length(app.rot{ind2})==0)
                app.rot{ind2}=app.rota{ind}(len);
                app.rota{ind2}=app.rot{ind}(len);
                app.dir{ind2}=app.dira{ind}(len);
                app.dira{ind2}=app.dir{ind}(len);
                app.tipoe{ind2}=app.tipoe{ind}(len);
            else
                app.rot{ind2}(l)=app.rota{ind}(len);
                app.rota{ind2}(l)=app.rot{ind}(len);
                app.dir{ind2}(l)=app.dira{ind}(len);
                app.dira{ind2}(l)=app.dir{ind}(len);
                app.tipoe{ind2}(l)=app.tipoe{ind}(len);
            end
            app.p{ind2}{l}=app.pa{ind}{len};
            app.d{ind2}(l)=app.da{ind}(len);
            app.r{ind2}(l)=app.ra{ind}(len);
            app.da{ind2}(l)=app.d{ind}(len);
            app.tm{ind2}(l)=-app.tm{ind}(len)*app.d{ind2}(l)/app.d{ind}(len);
            app.ra{ind2}(l)=app.r{ind}(len);
            app.pa{ind2}{l}=app.p{ind}{len};
            app.pc{ind2}{l}=app.pc{ind}{len};
            app.f{ind2}{l}=-app.f{ind}{len};
            app.fr{ind2}(l)=app.fr{ind}(len);
            app.fa{ind2}(l)=app.fa{ind}(len);
            app.ft{ind2}(l)=app.ft{ind}(len);
            
            if(app.TipodeengrenagemDropDown.Value == string('Dentes Retos'))
                app.xeng_2.Value = app.pm{(ind-1)*2+1}(1);
                app.yeng_2.Value = app.pm{(ind-1)*2+1}(2);
                app.zeng_2.Value = app.pm{(ind-1)*2+1}(3);
                if(app.dire{ind}(3)==1)
                    app.xeng_2.Value = 0;
                elseif(app.dire{ind}(3)==2)
                    app.yeng_2.Value = 0;
                elseif(app.dire{ind}(3)==3)
                    app.zeng_2.Value = 0;
                end
            elseif(app.TipodeengrenagemDropDown.Value == string('Helicoidal'))
                app.xeng.Value = app.pm{(ind-1)*2+1}(1);
                app.yeng.Value = app.pm{(ind-1)*2+1}(2);
                app.zeng.Value = app.pm{(ind-1)*2+1}(3);
                if(app.dire{ind}(3)==1)
                    app.xeng.Value = 0;
                elseif(app.dire{ind}(3)==2)
                    app.yeng.Value = 0;
                elseif(app.dire{ind}(3)==3)
                    app.zeng.Value = 0;
                end
            end
        end
        
        function results = mancaldiferente(app)
            app.AdicionarEixoButton.Enable = 'off';
            if(app.xCheckBox.Value == 1)
                if(not(app.xm1.Value == app.xm2.Value))
                    app.AdicionarEixoButton.Enable = 'on';
                end
            elseif(app.yCheckBox.Value == 1)
                if(not(app.ym1.Value == app.ym2.Value))
                    app.AdicionarEixoButton.Enable = 'on';
                end
            elseif(app.zCheckBox.Value == 1)
                if(not(app.zm1.Value == app.zm2.Value))
                    app.AdicionarEixoButton.Enable = 'on';
                end
            end
        end
        
        function results = liberareng(app)
            app.AdicionarEngrenagensButton.Enable = 'off';
            texto = string(app.EixoDropDown.Value);
            texto2 = string(app.EixoDropDown_2.Value);
            comparar = [string('Selecionar') string('Adicionar Eixo')];
            a=texto == comparar;
            b=texto2 == comparar;
            c=(ismember(1,a) || ismember(1,b));
            if(string(app.TipodeengrenagemDropDown.Value) == string('Dentes Retos'))
                if(not(app.pot_2.Value == 0 || c))
                    app.AdicionarEngrenagensButton.Enable = 'on';
                end
            elseif(string(app.TipodeengrenagemDropDown.Value) == string('Helicoidal'))
                if(not(app.pot1.Value == 0 || c))
                    app.AdicionarEngrenagensButton.Enable = 'on';
                end
            elseif(string(app.TipodeengrenagemDropDown.Value) == string('Cônica D.R.'))
                if(not(app.pot_3.Value == 0 || c))
                    app.AdicionarEngrenagensButton.Enable = 'on';
                end
            elseif(string(app.TipodeengrenagemDropDown.Value) == string('Sem Fim'))
                if(not(app.pot_4.Value == 0 || c))
                    app.AdicionarEngrenagensButton.Enable = 'on';
                end
            end
        end
        
        function results = mancaldiferente2(app)
            app.AdicionarEixoButton_2.Enable = 'off';
            if(app.xCheckBox_2.Value == 1)
                if(not(app.xeng_4.Value == app.xeng_5.Value))
                    app.AdicionarEixoButton_2.Enable = 'on';
                end
            elseif(app.yCheckBox_2.Value == 1)
                if(not(app.yeng_4.Value == app.yeng_5.Value))
                    app.AdicionarEixoButton_2.Enable = 'on';
                end
            elseif(app.zCheckBox_2.Value == 1)
                if(not(app.zeng_4.Value == app.zeng_5.Value))
                    app.AdicionarEixoButton_2.Enable = 'on';
                end
            end
            
        end
        
        function results = atualizarposicaoeng(app)
            ind=[];
            if(length(app.butte)>0)
                nomes = [];
                for i=1:length(app.butte)
                    nomes = [nomes string(app.butte(i).Text)];
                end
                ind = find(nomes == string(app.EixoDropDown.Value));
            end
            
            if(length(ind)>0)
                if(string(app.TipodeengrenagemDropDown.Value) == string('Dentes Retos'))
                    app.xeng_2.Enable = 'off';
                    app.yeng_2.Enable = 'off';
                    app.zeng_2.Enable = 'off';
                    if(app.dire{ind}(3)==1)
                        app.yeng_2.Value = app.pm{(ind-1)*2+1}(2);
                        app.zeng_2.Value = app.pm{(ind-1)*2+1}(3);
                        app.xeng_2.Enable = 'on';
                    elseif(app.dire{ind}(3)==2)
                        app.xeng_2.Value = app.pm{(ind-1)*2+1}(1);
                        app.zeng_2.Value = app.pm{(ind-1)*2+1}(3);
                        app.yeng_2.Enable = 'on';
                    elseif(app.dire{ind}(3)==3)
                        app.xeng_2.Value = app.pm{(ind-1)*2+1}(1);
                        app.yeng_2.Value = app.pm{(ind-1)*2+1}(2);
                        app.zeng_2.Enable = 'on';
                    end
                elseif(string(app.TipodeengrenagemDropDown.Value) == string('Helicoidal'))
                    app.xeng.Enable = 'off';
                    app.yeng.Enable = 'off';
                    app.zeng.Enable = 'off';
                    if(app.dire{ind}(3)==1)
                        app.yeng.Value = app.pm{(ind-1)*2+1}(2);
                        app.zeng.Value = app.pm{(ind-1)*2+1}(3);
                        app.xeng.Enable = 'on';
                    elseif(app.dire{ind}(3)==2)
                        app.xeng.Value = app.pm{(ind-1)*2+1}(1);
                        app.zeng.Value = app.pm{(ind-1)*2+1}(3);
                        app.yeng.Enable = 'on';
                    elseif(app.dire{ind}(3)==3)
                        app.xeng.Value = app.pm{(ind-1)*2+1}(1);
                        app.yeng.Value = app.pm{(ind-1)*2+1}(2);
                        app.zeng.Enable = 'on';
                    end
                elseif(string(app.TipodeengrenagemDropDown.Value) == string('Cônica D.R.'))
                    app.xeng_6.Enable = 'off';
                    app.yeng_6.Enable = 'off';
                    app.zeng_6.Enable = 'off';
                    if(app.dire{ind}(3)==1)
                        app.yeng_6.Value = app.pm{(ind-1)*2+1}(2);
                        app.zeng_6.Value = app.pm{(ind-1)*2+1}(3);
                        app.xeng_6.Enable = 'on';
                    elseif(app.dire{ind}(3)==2)
                        app.xeng_6.Value = app.pm{(ind-1)*2+1}(1);
                        app.zeng_6.Value = app.pm{(ind-1)*2+1}(3);
                        app.yeng_6.Enable = 'on';
                    elseif(app.dire{ind}(3)==3)
                        app.xeng_6.Value = app.pm{(ind-1)*2+1}(1);
                        app.yeng_6.Value = app.pm{(ind-1)*2+1}(2);
                        app.zeng_6.Enable = 'on';
                    end
                elseif(string(app.TipodeengrenagemDropDown.Value) == string('Sem Fim'))
                    app.xeng_7.Enable = 'off';
                    app.yeng_7.Enable = 'off';
                    app.zeng_7.Enable = 'off';
                    if(app.dire{ind}(3)==1)
                        app.yeng_7.Value = app.pm{(ind-1)*2+1}(2);
                        app.zeng_7.Value = app.pm{(ind-1)*2+1}(3);
                        app.xeng_7.Enable = 'on';
                    elseif(app.dire{ind}(3)==2)
                        app.xeng_7.Value = app.pm{(ind-1)*2+1}(1);
                        app.zeng_7.Value = app.pm{(ind-1)*2+1}(3);
                        app.yeng_7.Enable = 'on';
                    elseif(app.dire{ind}(3)==3)
                        app.xeng_7.Value = app.pm{(ind-1)*2+1}(1);
                        app.yeng_7.Value = app.pm{(ind-1)*2+1}(2);
                        app.zeng_7.Enable = 'on';
                    end
                end
            end
            if(string(app.chamada) == string('m'))
                if(not(string(app.EixoDropDown.Value) == string('Selecionar') || string(app.EixoDropDown.Value) == string('Adicionar Eixo')))
                    app.EixoDropDown_2.Enable = 'on';
                else
                    app.EixoDropDown_2.Enable = 'off';
                    app.EixoDropDown_2.Value = 'Selecionar';
                end
                
                atualizardropdown(app);
            end
            liberareng(app);
        end
        
        function results = acharindice(app,a)
            for(i=1:length(a.Items))
                if(string(a.Value) == string(a.Items{i}))
                    results=i;
                    break
                end
            end
        end
        
        function results = mudarscc(app)
            value = app.GraudacoroaDropDown.Value;
            value2 = string(app.TrattrmicocoroaDropDown.Value);
            value1 = string(app.MaterialdacoroaDropDown.Value);
            item2 = app.TrattrmicocoroaDropDown.Items;
            item1 = app.MaterialdacoroaDropDown.Items;
            item=app.GraudacoroaDropDown.Items;
            ind=acharindice(app,app.GraudacoroaDropDown);
            ind2=acharindice(app,app.TrattrmicocoroaDropDown);
            ind1=acharindice(app,app.MaterialdacoroaDropDown);
            dsdp=app.DurezapEditField.Value;
            dsdc=app.DurezacEditField.Value;
            tab=[322*dsdp+29100 349*dsdp+34300 NaN;
                  170e3 190e3 NaN;
                  180e3 225e3 275e3;
                  150e3 163e3 175e3;
                  155e3 172e3 189e3;
                  170e3 183e3 195e3;
                  176e3 196e3 216e3;
                  84e3 NaN NaN;
                  84e3 NaN NaN;
                  102e3 NaN NaN;
                  55e3 NaN NaN;
                  70e3 NaN NaN;
                  80e3 NaN NaN;
                  30e3 NaN NaN;
                  65e3 NaN NaN];
            tab2=[77.3*dsdp+12800 102*dsdp+16400 NaN;
                 33000 38000 NaN;
                 55000 65000 75000;
                 82.3*dsdp+12150 108.6*dsdp+15890 108.6*dsdp+15890;
                 82.3*dsdp+12150 108.6*dsdp+15890 108.6*dsdp+15890;
                 86.2*dsdp+12730 113.8*dsdp+16650 105.2*dsdp+22280;
                 86.2*dsdp+12730 113.8*dsdp+16650 105.2*dsdp+22280;
                 25000 NaN NaN;
                 25000 NaN NaN;
                 36000 NaN NaN;
                 5000 NaN NaN;
                 8500 NaN NaN;
                 13000 NaN NaN;
                 5700 NaN NaN;
                 23600 NaN NaN];
            if(string(value2)=="Outro")
            else
                if(value1==string(item1{1}))
                    app.ScadacoroapsiEditField.Value = tab(ind2,ind);
                    app.StadacoroapsiEditField.Value = tab2(ind2,ind);
                elseif(value1==string(item1{2}) || value1==string(item1{3}) || value1==string(item1{4}))
                    app.ScadacoroapsiEditField.Value = tab((ind2+4+ind1-2),(ind));
                    app.StadacoroapsiEditField.Value = tab2((ind2+4+ind1-2),(ind));
                elseif(not(value1=="Outro"))
                    app.ScadacoroapsiEditField.Value = tab((ind2+4+ind1-2),1);
                    app.StadacoroapsiEditField.Value = tab2((ind2+4+ind1-2),1);
                end     
            end
            if(app.sistema==1)
                app.ScadacoroapsiEditField.Value=app.ScadacoroapsiEditField.Value*6894.76;
                app.StadacoroapsiEditField.Value=app.StadacoroapsiEditField.Value*6894.76;
            end
        end
        

        function results = mudarscp(app)
            value = app.GraudopinhoDropDown.Value;
            value2 = string(app.TrattrmicopinhoDropDown.Value);
            value1 = string(app.MaterialdopinhoDropDown.Value);
            item2 = app.TrattrmicopinhoDropDown.Items;
            item1 = app.MaterialdopinhoDropDown.Items;
            item=app.GraudopinhoDropDown.Items;
            ind=acharindice(app,app.GraudopinhoDropDown);
            ind2=acharindice(app,app.TrattrmicopinhoDropDown);
            ind1=acharindice(app,app.MaterialdopinhoDropDown);
            dsdp=app.DurezapEditField.Value;
            dsdc=app.DurezacEditField.Value;
            tab=[322*dsdc+29100 349*dsdc+34300 NaN;
                  170e3 190e3 NaN;
                  180e3 225e3 275e3;
                  150e3 163e3 175e3;
                  155e3 172e3 189e3;
                  170e3 183e3 195e3;
                  176e3 196e3 216e3;
                  84e3 NaN NaN;
                  84e3 NaN NaN;
                  102e3 NaN NaN;
                  55e3 NaN NaN;
                  70e3 NaN NaN;
                  80e3 NaN NaN;
                  30e3 NaN NaN;
                  65e3 NaN NaN];
             tab2=[77.3*dsdp+12800 102*dsdp+16400 NaN;
                 33000 38000 NaN;
                 55000 65000 75000;
                 82.3*dsdp+12150 108.6*dsdp+15890 108.6*dsdp+15890;
                 82.3*dsdp+12150 108.6*dsdp+15890 108.6*dsdp+15890;
                 86.2*dsdp+12730 113.8*dsdp+16650 105.2*dsdp+22280;
                 86.2*dsdp+12730 113.8*dsdp+16650 105.2*dsdp+22280;
                 25000 NaN NaN;
                 25000 NaN NaN;
                 36000 NaN NaN;
                 5000 NaN NaN;
                 8500 NaN NaN;
                 13000 NaN NaN;
                 5700 NaN NaN;
                 23600 NaN NaN];
            if(string(value2)=="Outro")
            else
                if(value1==string(item1{1}))
                    app.ScadopinhopsiEditField.Value = tab(ind2,ind);
                    app.StadopinhopsiEditField.Value = tab2(ind2,ind);  
                elseif(value1==string(item1{2}) || value1==string(item1{3}) || value1==string(item1{4}))
                    app.ScadopinhopsiEditField.Value = tab((ind2+4+ind1-2),(ind));
                    app.StadopinhopsiEditField.Value = tab2((ind2+4+ind1-2),(ind));
                elseif(not(value1=="Outro"))
                    app.ScadopinhopsiEditField.Value = tab((ind2+4+ind1-2),1);
                    app.StadopinhopsiEditField.Value = tab2((ind2+4+ind1-2),1);
                end     
            end
            if(app.sistema==1)
                app.ScadopinhopsiEditField.Value=app.ScadopinhopsiEditField.Value*6894.76;
                app.StadopinhopsiEditField.Value=app.StadopinhopsiEditField.Value*6894.76;
            end
        end
        
        function results = mudarep(app)
            ind=acharindice(app,app.MaterialdopinhoDropDown);
            tab=[30e6 24e6 22e6 17e6];
            if(ind<=4)
                app.EdopinhopsiEditField.Value = tab(1);
            elseif(ind<=7)
                app.EdopinhopsiEditField.Value = tab(2);
            elseif(ind<=10)
                app.EdopinhopsiEditField.Value = tab(3);
            elseif(ind<=12)
                app.EdopinhopsiEditField.Value = tab(4);
            end
            if(app.sistema==1)
                app.EdopinhopsiEditField.Value = app.EdopinhopsiEditField.Value*6894.76;
            end
        end
        
        function results = mudarec(app)
            ind=acharindice(app,app.MaterialdacoroaDropDown);
            tab=[30e6 24e6 22e6 17e6];
            if(ind<=4)
                app.EdacoroapsiEditField.Value = tab(1);
            elseif(ind<=7)
                app.EdacoroapsiEditField.Value = tab(2);
            elseif(ind<=10)
                app.EdacoroapsiEditField.Value = tab(3);
            elseif(ind<=12)
                app.EdacoroapsiEditField.Value = tab(4);
            end
            if(app.sistema==1)
                app.EdacoroapsiEditField.Value = app.EdacoroapsiEditField.Value*6894.76;
            end
        end
        
        function results = adicionardinamica(app)
            nomes = [];
            for i=1:length(app.butt)
                nomes = [nomes string(app.butt(i).Text)];
            end
            ind = find(nomes == string(app.ElementosDropDown.Value));
            app.dinamica(ind)=1;
            vex=app.refe{ind}(1);
            veng=app.refe{ind}(2);
            vexa=app.refe{ind}(3);
            venga=app.refe{ind}(4);
            N(1)=app.n{vex}(veng);
            N(2)=app.na{vex}(veng);
            if(N(1)>=N(2))
                PIN=2;
                COR=1;
            else
                PIN=1;
                COR=2;
            end
            NP=N(PIN);
            NC=N(COR);
            AH=app.phi{vex}(veng);
            AP=app.tetan{vex}(veng);
            if(PIN==1)
                ROT=app.rotacao{vex}(veng);
            else
                ROT=app.rotacao{vexa}(venga);
            end
            POT=app.pot{vex}(veng);
            PD=app.pn{vex}(veng);
            if(app.sistema==1)
                PD=25.4*PD;
            end
            LF=app.LarguradefaceEditField.Value;
            if(app.sistema==1)
                LF=LF/25.4;
            end
            NQ=app.NumqEditField.Value;
            PEG=acharindice(app,app.PosiodasengsDropDown);
            S1=abs(app.pm{(vex-1)*2+1}(app.dire{vex}(3))-app.pm{(vex-1)*2+2}(app.dire{vex}(3)));
            S2=abs(app.pm{(vexa-1)*2+1}(app.dire{vexa}(3))-app.pm{(vexa-1)*2+2}(app.dire{vexa}(3)));
            DISTM1=abs(app.pm{(vex-1)*2+1}(app.dire{vex}(3))+app.pm{(vex-1)*2+2}(app.dire{vex}(3)))/2;
            DISTM2=abs(app.pm{(vexa-1)*2+1}(app.dire{vexa}(3))+app.pm{(vexa-1)*2+2}(app.dire{vexa}(3)))/2;
            S11=abs(app.p{vex}{veng}(app.dire{vex}(3))-DISTM1);
            S22=abs(app.p{vexa}{venga}(app.dire{vexa}(3))-DISTM2);
            SV1=S11/S1;
            SV2=S22/S2;
            if(PIN==1)
                SP=SV1;
                SG=SV2;
            else
                SP=SV2;
                SG=SV1;
            end
            DSP=app.DurezapEditField.Value;
            DSC=app.DurezacEditField.Value;
            POIS=0.3;
            VID=app.VidaEditField.Value;
            CONF=app.ConfEditField.Value;
            PFD=acharindice(app,app.CoroamentoDropDown);
            CMAT=acharindice(app,app.TipodeengDropDown); 
            VK0=[acharindice(app,app.FontedepotnciaDropDown),acharindice(app,app.MquinaacionadaDropDown)];
            CEP=acharindice(app,app.EngrenamentoDropDown);
            MB=app.MbEditField.Value;
            TEMP=app.TempEditField.Value;
            if(app.sistema==1)
                TEMP=TEMP*1.8+32;
            end
            app.Stp(ind)=app.StadopinhopsiEditField.Value;
            app.Stg(ind)=app.StadacoroapsiEditField.Value;
            app.Scp(ind)=app.ScadopinhopsiEditField.Value;
            app.Scg(ind)=app.ScadacoroapsiEditField.Value;
            app.EC(ind)=app.EdacoroapsiEditField.Value;
            app.EP(ind)=app.EdopinhopsiEditField.Value;
            if(app.sistema==1)
                app.Stp(ind)=app.Stp(ind)/6894.76;
                app.Stg(ind)=app.Stg(ind)/6894.76;
                app.Scp(ind)=app.Scp(ind)/6894.76;
                app.Scg(ind)=app.Scg(ind)/6894.76;
                app.EC(ind)=app.EC(ind)/6894.76;
                app.EP(ind)=app.EP(ind)/6894.76;
            end
            
            app.Cp(ind)=(pi*((1-POIS^2)/app.EC(ind)+(1-POIS^2)/app.EP(ind)))^(-1/2);
            TETA=atand(tand(AP)/cosd(AH));
            dp=NP/(PD*cosd(AH));
            dg=NC/(PD*cosd(AH));
            rp=dp/2;
            rg=dg/2;
            v=pi*dp*ROT/12;
            wt=33000*POT/v;
            if(app.sistema==1)
                wt=1.34102*wt;
            end
            
            if(string(app.tipoe{vex}(veng))=="Dentes Retos")
                mn=1;
                
                jc1=load('jc1.txt');
                pjc1=polyfit(log(jc1(:,1)),log(jc1(:,2)),size(jc1,2));
                
                j1000=load('j1000.txt');
                pj1000=polyfit(log(j1000(:,1)),log(j1000(:,2)),size(j1000,2));
                
                j170=load('j170.txt');
                pj170=polyfit(log(j170(:,1)),log(j170(:,2)),size(j170,2));
                
                j85=load('j85.txt');
                pj85=polyfit(log(j85(:,1)),log(j85(:,2)),size(j85,2));
                
                j50=load('j50.txt');
                pj50=polyfit(log(j50(:,1)),log(j50(:,2)),size(j50,2));
                
                j35=load('j35.txt');
                pj35=polyfit(log(j35(:,1)),log(j35(:,2)),size(j35,2));
                
                j25=load('j25.txt');
                pj25=polyfit(log(j25(:,1)),log(j25(:,2)),size(j25,2));
                
                j17=load('j17.txt');
                pj17=polyfit(log(j17(:,1)),log(j17(:,2)),size(j17,2));
                
                ndj=[NP NC];
                j=[0 0];
                for(i=1:2)
                    if(ndj(i)<=17)
                        j(i)=exp(polyval(pjc1,log(ndj(i))));
                    elseif(ndj(3-i)<=17)
                        j(i)=exp(polyval(pj17,log(ndj(i))));
                    elseif(ndj(3-i)<=25)
                        j(i)=exp(polyval(pj25,log(ndj(i))))+(ndj(3-i)-17)*(exp(polyval(pj25,log(ndj(i))))-exp(polyval(pj17,log(ndj(i)))))/(25-17);
                    elseif(ndj(3-i)<=35)
                        j(i)=exp(polyval(pj35,log(ndj(i))))+(ndj(3-i)-25)*(exp(polyval(pj35,log(ndj(i))))-exp(polyval(pj25,log(ndj(i)))))/(35-25);
                    elseif(ndj(3-i)<=50)
                        j(i)=exp(polyval(pj50,log(ndj(i))))+(ndj(3-i)-35)*(exp(polyval(pj50,log(ndj(i))))-exp(polyval(pj35,log(ndj(i)))))/(50-35);
                    elseif(ndj(3-i)<=85)
                        j(i)=exp(polyval(pj85,log(ndj(i))))+(ndj(3-i)-50)*(exp(polyval(pj85,log(ndj(i))))-exp(polyval(pj50,log(ndj(i)))))/(85-50);
                    elseif(ndj(3-i)<=170)
                        j(i)=exp(polyval(pj170,log(ndj(i))))+(ndj(3-i)-85)*(exp(polyval(pj170,log(ndj(i))))-exp(polyval(pj85,log(ndj(i)))))/(170-85);
                    elseif(ndj(3-i)<=1000)
                        j(i)=exp(polyval(pj1000,log(ndj(i))))+(ndj(3-i)-170)*(exp(polyval(pj1000,log(ndj(i))))-exp(polyval(pj170,log(ndj(i)))))/(1000-170);
                    end
                end
                
                app.jg(ind)=j(2);
                app.jp(ind)=j(1);
            elseif(string(app.tipoe{vex}(veng)) == "Helicoidal")
                jl20=load('jl20.txt');
                pjl20=polyfit(jl20(:,1),jl20(:,2),size(jl20,2));
                
                jl30=load('jl30.txt');
                pjl30=polyfit(jl30(:,1),jl30(:,2),size(jl30,2));
                
                jl60=load('jl60.txt');
                pjl60=polyfit(jl60(:,1),jl60(:,2),size(jl60,2));
                
                jl150=load('jl150.txt');
                pjl150=polyfit(jl150(:,1),jl150(:,2),size(jl150,2));
                
                jl500=load('jl500.txt');
                pjl500=polyfit(jl500(:,1),jl500(:,2),size(jl500,2));
                
                fm20=load('fm20.txt');
                pfm20=polyfit(fm20(:,1),fm20(:,2),size(fm20,2));
                
                fm30=load('fm30.txt');
                pfm30=polyfit(fm30(:,1),fm30(:,2),size(fm30,2));
                
                fm50=load('fm50.txt');
                pfm50=polyfit(fm50(:,1),fm50(:,2),size(fm50,2));
                
                fm75=load('fm75.txt');
                pfm75=polyfit(fm75(:,1),fm75(:,2),size(fm75,2));
                
                fm150=load('fm150.txt');
                pfm150=polyfit(fm150(:,1),fm150(:,2),size(fm150,2));
                
                fm500=load('fm500.txt');
                pfm500=polyfit(fm500(:,1),fm500(:,2),size(fm500,2));
                
                ndj=[NP NC];
                jl=[0 0];
                for(i=1:2)
                    if(ndj(i)<20)
                        jl(i)=polyval(pjl20,AH)-0.04*(20-ndj(i))/7;
                    elseif(ndj(i)<30)
                        jl(i)=polyval(pjl20,AH)+(ndj(i)-20)*(polyval(pjl30,AH)-polyval(pjl20,AH))/(30-20);
                    elseif(ndj(i)<60)
                        jl(i)=polyval(pjl30,AH)+(ndj(i)-30)*(polyval(pjl60,AH)-polyval(pjl30,AH))/(60-30);
                    elseif(ndj(i)<150)
                        jl(i)=polyval(pjl60,AH)+(ndj(i)-60)*(polyval(pjl150,AH)-polyval(pjl60,AH))/(150-60);
                    elseif(ndj(i)<500)
                        jl(i)=polyval(pjl150,AH)+(ndj(i)-150)*(polyval(pjl500,AH)-polyval(pjl150,AH))/(500-150);
                    end
                end
                fam=[0 0];
                for(i=1:2)
                    if(ndj(i)<20)
                        fam(i)=polyval(pfm20,AH)-0.04*(20-ndj(i))/7;
                    elseif(ndj(i)<30)
                        fam(i)=polyval(pfm20,AH)+(ndj(i)-20)*(polyval(pfm30,AH)-polyval(pfm20,AH))/(30-20);
                    elseif(ndj(i)<50)
                        fam(i)=polyval(pfm30,AH)+(ndj(i)-30)*(polyval(pfm50,AH)-polyval(pfm30,AH))/(50-30);
                    elseif(ndj(i)<75)
                        fam(i)=polyval(pfm50,AH)+(ndj(i)-50)*(polyval(pfm75,AH)-polyval(pfm50,AH))/(75-50);
                    elseif(ndj(i)<150)
                        fam(i)=polyval(pfm60,AH)+(ndj(i)-60)*(polyval(pfm150,AH)-polyval(pfm60,AH))/(150-75);
                    elseif(ndj(i)<500)
                        fam(i)=polyval(pfm150,AH)+(ndj(i)-150)*(polyval(pfm500,AH)-polyval(pfm150,AH))/(500-150);
                    end
                end
                app.jg(ind)=jl(2)*fam(2);
                app.jp(ind)=jl(1)*fam(1);
                
                pnmn=pi/PD*cosd(AP);
                ad=1/PD;
                rbp=rp*cosd(TETA);
                rbg=rg*cosd(TETA);
                teste1=((rp+ad)^2-rbp^2)^(1/2);
                teste2=((rg+ad)^2-rbg^2)^(1/2);
                teste3=(rp+rg)*sind(TETA);
                if(teste1>teste3)
                    teste1=teste3;
                end
                if(teste2>teste3)
                    teste2=teste3;
                end
                Zmn=teste1+teste2-teste3;
                mn=pnmn/(0.95*Zmn);
                
            end
            
            % DETERMINACAO DO K0, 2 INPUTS, PINHAO E COROA
            
            K0t= [1 1.25 1.75;
                 1.25 1.5 2;
                 1.5 1.75 2.24];
            app.K0(ind)=K0t(VK0(1),VK0(2));
            
            % DETERMINACAO DO KV, 1 INPUT, QUALIDADE
            
            B=0.25*(12-NQ)^(2/3);
            A=50+56*(1-B);
            app.Kv(ind)=((A+sqrt(v))/A)^B;
            
            % COMPARAR VELOCIDADE MAXIMA
            Vtmax=(A+(NQ-3))^2;
            if(v>Vtmax)
                fprintf('Velocidade máxima excedida. V = %.1f | Vmax = %.1f', v, Vtmax);
            end
            ndt=[12 13 14 15 16 17 18 19 20 21 22 24 26 28 30 34 38 43 50 60 75 100 150 300 400];
            Ynd=[0.245 0.261 0.277 0.290 0.296 0.303 0.309 0.314 0.322 0.328 0.331 0.337 0.346 0.353 0.359 0.371 0.384 0.397 0.409 0.422 0.435 0.447 0.460 0.472 0.480];
            YP=interp1(ndt,Ynd,NP);
            YG=interp1(ndt,Ynd,NC);
            
            %DETERMINACAO DE KSP, 1 INPUT, LARGURA DE FACE
            app.Ksp(ind)=1.192*(LF*sqrt(YP)/PD)^(0.0535);
            if(app.Ksp(ind)<1)
                app.Ksp(ind) = 1;
            end
            app.Ksg(ind)=1.192*(LF*sqrt(YG)/PD)^(0.0535);
            if(app.Ksg(ind) < 1)
                app.Ksg(ind) = 1;
            end
            
            if(LF<=1)
                if(LF/(10*dp)<0.05)
                    Cpfp=0.05-0.025;
                else
                    Cpfp=LF/(10*dp)-0.025;
                end
            elseif(LF<=17)
                if(LF/(10*dp)<0.05)
                    Cpfp=0.05-0.0375+0.0125*LF;
                else
                    Cpfp=LF/(10*dp)-0.0375+0.0125*LF;
                end
            elseif(LF<=40)
                if(LF/(10*dp)<0.05)
                    Cpfp=0.05-0.1109+0.0207*LF-0.000228*LF^2;
                else
                    Cpfp=LF/(10*dp)-0.1109+0.0207*LF-0.000228*LF^2;
                end
            end
            
            if(LF<=1)
                if(LF/(10*dg)<0.05)
                    Cpfg=0.05-0.025;
                else
                    Cpfg=LF/(10*dg)-0.025;
                end
            elseif(LF<=17)
                if(LF/(10*dg)<0.05)
                    Cpfg=0.05-0.0375+0.0125*LF;
                else
                    Cpfg=LF/(10*dg)-0.0375+0.0125*LF;
                end
            elseif(LF<=40)
                if(LF/(10*dg)<0.05)
                    Cpfg=0.05-0.1109+0.0207*LF-0.000228*LF^2;
                else
                    Cpfg=LF/(10*dg)-0.1109+0.0207*LF-0.000228*LF^2;
                end
            end
            
            % DETERMINACAO DE CMC, 1 INPUT, COROAMENTO
            
            if(PFD==1)
                Cmc=1;
            elseif(PFD==2)
                Cmc=0.8;
            end
            
            % DETERMINACAO DE CPM, 1 INPUT, POSICAO RELATIVA
            
            if(SP<0.125)
                Cpmp=1;
            else
                Cpmp=1.1;
            end
            if(SG<0.125)
                Cpmg=1;
            else
                Cpmg=1.1;
            end
            
            % DETERMINACAO DE CE, 1 INPUT, TIPO DE MONTAGEM
            
            if(CEP == 1 || CEP == 2)
                Ce = 0.8;
            elseif(CEP == 3)
                Ce = 1;
            end
            
            cmaT=[0.247 0.0167 -0.765e-4;
                  0.127 0.0158 -0.930e-4;
                  0.0675 0.0128 -0.926e-4;
                  0.00360 0.0102 -0.822e-4];
            
            % DETERMINACAO DE CMA, 1 INPUT, TIPO DE ENGRENAGEM
            
            Cma=cmaT(CMAT,1) + cmaT(CMAT,2)*LF + cmaT(CMAT,3)*LF^2;
            
            app.Kmp(ind)=1+Cmc*(Cpfp*Cpmp+Cma*Ce);
            app.Kmg(ind)=1+Cmc*(Cpfg*Cpmg+Cma*Ce);
            
            % DETERMINACAO DE MB, 2 INPUTS, TR E HT
            
            
            if(MB<1.2)
                app.Kb(ind)=1.6*log(2.242/MB);
            else
                app.Kb(ind)=1;
            end
            
            mg = NC/NP;
            
            % DETERMINACAO DE Ys e Zs, 1 INPUT, VIDA (MUDA PARA HELICOIDAL)
            
            VID2=VID/mg;
            if(VID>3e6)
                app.Ynp(ind)=1.3558*VID^(-0.0178);
            elseif(VID>1e3)
                Ynx=[160 200 250 300 400];
                Yny=[2.3194*VID^(-0.0538) 3.517*VID^(-0.0817) 4.9404*VID^(-0.1045) 6.1514*VID^(-0.1192) 9.4518*VID^(-0.148)];
                app.Ynp(ind)=interp1(Ynx,Yny,DSP,'pchip');
            else
                Ynx=[160 200 250 300 400];
                Yny=[2.3194*1e3^(-0.0538) 3.517*1e3^(-0.0817) 4.9404*1e3^(-0.1045) 6.1514*1e3^(-0.1192) 9.4518*1e3^(-0.148)];
                app.Ynp(ind)=interp1(Ynx,Yny,DSP,'phcip');
            end
            
            if(VID2>3e6)
                app.Yng(ind)=1.3558*VID2^(-0.0178);
            elseif(VID2>1e3)
                Ynx=[160 200 250 300 400];
                Yny=[2.3194*VID2^(-0.0538) 3.517*VID2^(-0.0817) 4.9404*VID2^(-0.1045) 6.1514*VID2^(-0.1192) 9.4518*VID2^(-0.148)];
                app.Yng(ind)=interp1(Ynx,Yny,DSC,'pchip');
            else
                Ynx=[160 200 250 300 400];
                Yny=[2.3194*1e3^(-0.0538) 3.517*1e3^(-0.0817) 4.9404*1e3^(-0.1045) 6.1514*1e3^(-0.1192) 9.4518*1e3^(-0.148)];
                app.Yng(ind)=interp1(Ynx,Yny,DSC,'phcip');
            end


            if(VID>1e7)
                app.Znp(ind)=1.4488*VID^(-0.023);
            else
                app.Znp(ind)=2.466*VID^(-0.056);
            end
            
            if(VID2>1e7)
                app.Zng(ind)=1.4488*VID2^(-0.023);
            else
                app.Zng(ind)=2.466*VID2^(-0.056);
            end
            
            
            % DETERMINACAO DE KR, 1 INPUT, COEFICIENTE DE CONFIABILIDADE
            
            kr=[0.9999 1.5;
                0.999 1.25;
                0.99 1;
                0.9 0.85;
                0.5 0.7];
            checar=0;
            for(i=1:size(kr,1))
                if(CONF == kr(i,1))
                    app.Kr(ind)=kr(i,2);
                    checar=1;
                    break
                end
            end
            if(checar == 0)
                if(CONF<0.99)
                    app.Kr(ind)=0.658-0.0759*log(1-CONF);
                elseif(CONF>0.99)
                    app.Kr(ind)=0.5-0.109*log(1-CONF);
                end
            end
            
            % DETERMINACAO DE KT, 1 INPUT, TEMPERATURA DE OPERACAO
            
            if(TEMP<250)
                app.Kt(ind)=1;
            else
                app.Kt(ind)=1.3;
            end
            
            app.Cf(ind) = 1;
            % DETERMINACAO DE I, 1 INPUT, POSICAO (INTERNA OU EXTERNA)
            
            if(PEG==1)
                app.I(ind)=cosd(TETA)*sind(TETA)/(2*mn)*mg/(mg+1);
            elseif(PEG==2)
                app.I(ind)=cosd(TETA)*sind(TETA)/(2*mn)*mg/(mg-1);
            end
 
%             Determinacao de A', 2 inputs, dureza da coroa e do pinhao
            
            
            if(DSP/DSC<1.2)
                Al = 0;
            elseif(DSP/DSC>1.7)
                Al = 0.00698;
            else
                Al = 8.98e-3*DSP/DSC-8.29*1e-3;
            end
            
            Ch = 1 + Al*(mg-1);
            
            
            app.sigmap(ind)=wt*app.K0(ind)*app.Kv(ind)*app.Ksp(ind)*PD*cosd(AH)/LF*app.Kmp(ind)*app.Kb(ind)/app.jp(ind);
            app.sigmag(ind)=wt*app.K0(ind)*app.Kv(ind)*app.Ksg(ind)*PD*cosd(AH)/LF*app.Kmg(ind)*app.Kb(ind)/app.jg(ind);
            app.Sfp(ind)=app.Stp(ind)*app.Ynp(ind)/(app.Kt(ind)*app.Kr(ind)*app.sigmap(ind));
            app.Sfg(ind)=app.Stg(ind)*app.Yng(ind)/(app.Kt(ind)*app.Kr(ind)*app.sigmag(ind));
            
            app.sigmacp(ind)=app.Cp(ind)*(wt*app.K0(ind)*app.Kv(ind)*app.Ksp(ind)*app.Kmp(ind)/(dp*LF)*app.Cf(ind)/app.I(ind))^(1/2);
            app.sigmacg(ind)=app.Cp(ind)*(wt*app.K0(ind)*app.Kv(ind)*app.Ksg(ind)*app.Kmg(ind)/(dp*LF)*app.Cf(ind)/app.I(ind))^(1/2);
            app.Shp(ind)=app.Scp(ind)*app.Znp(ind)/(app.Kt(ind)*app.Kr(ind)*app.sigmacp(ind));
            app.Shg(ind)=app.Scg(ind)*app.Zng(ind)/(app.Kt(ind)*app.Kr(ind)*app.sigmacg(ind));

    
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %                                     try
            %                                         S=load('tccworkspace.mat')
            %                                         app.sy = S.app.sy;
            %                                     catch
            %                                         return;
            %                                     end
        end

        % Button pushed function: EngrenagensButton
        function EngrenagensButtonPushed(app, event)
            app.PanelEngrenagem.Visible = 'on';
            app.PanelEixo.Visible = 'off';
            app.PanelResultado.Visible = 'off';
            app.AnaliseDinamica.Visible = 'off';
            app.AdicionarEngrenagensButton.Visible='on';
            app.AtualizarEngrenagensButton.Visible='off';
            app.DeletarEngrenagensButton.Visible='off';
            app.TextArea.Visible='off';
            app.EixoDropDown.Value = 'Selecionar';
            app.EixoDropDown_2.Value = 'Selecionar';
            app.EixoDropDown_2.Enable = 'off';
            app.rot_1.Enable = 'on';
            app.hor.Enable = 'on';
            app.ahor.Enable = 'on';
            app.rot_2.Enable = 'on';
            app.hor_2.Enable = 'on';
            app.ahor_2.Enable = 'on';
            app.rot_3.Enable = 'on';
            app.hor_3.Enable = 'on';
            app.ahor_3.Enable = 'on';
            app.xeng.Enable = 'on';
            app.xeng_2.Enable = 'on';
            app.xeng_6.Enable = 'on';
            app.xeng_7.Enable = 'on';
            app.yeng.Enable = 'on';
            app.yeng_2.Enable = 'on';
            app.yeng_6.Enable = 'on';
            app.yeng_7.Enable = 'on';
            app.zeng.Enable = 'on';
            app.zeng_2.Enable = 'on';
            app.zeng_6.Enable = 'on';
            app.zeng_7.Enable = 'on';
            if(length(app.nomeex) == 0)
                app.EixoDropDown.Items = {'Selecionar' 'Adicionar Eixo'};
                app.EixoDropDown_2.Items = {'Selecionar' 'Adicionar Eixo'};
            else
                textdrop = {'Selecionar' 'Adicionar Eixo'};
                for i=1:length(app.nomeex)
                    textdrop{i+2} = char(app.nomeex(i));
                end
                app.EixoDropDown.Items = textdrop;
            end
            atualizardropdown(app);
        end

        % Button pushed function: AdicionarEngrenagensButton
        function AdicionarEngrenagensButtonPushed(app, event)
            app.AdicionarEixoPanel.Visible = 'off';
            
            adicionarengrenagens(app,-1,-1);
            nomes = [];
            for i=1:length(app.butte)
                nomes = [nomes string(app.butte(i).Text)];
            end
            ind = find(nomes == string(app.EixoDropDown.Value));
            
            nomes2 = [];
            for i=1:length(app.butte)
                nomes2 = [nomes2 string(app.butte(i).Text)];
            end
            ind2 = find(nomes2 == string(app.EixoDropDown_2.Value));
            
            app.refe{length(app.nomeep)}=[ind, length(app.p{ind}), ind2, length(app.p{ind2})];
            
            if(length(app.nomeex) == 0)
                app.EixoDropDown.Items = {'Selecionar' 'Adicionar Eixo'};
                app.EixoDropDown_2.Items = {'Selecionar' 'Adicionar Eixo'};
            else
                textdrop = {'Selecionar' 'Adicionar Eixo'};
                for i=1:length(app.nomeex)
                    textdrop{i+2} = char(app.nomeex(i));
                end
                app.EixoDropDown.Items = textdrop;
                atualizardropdown(app);
            end
            if(string(app.EixoDropDown.Value) == string('Selecionar') || string(app.EixoDropDown.Value) == string('Adicionar Eixo'))
                app.EixoDropDown_2.Value='Selecionar';
                app.EixoDropDown_2.Enable = 'off';
            end
            
            texto = char(app.nomee(length(app.nomee)));
            if(length(app.nomeep)==1)
                app.butt=uibutton(app.PanelEngrenagem,'Position',[341-6*length(char(app.nomeep))/2,250+(length(app.nomeep)-1)*-30,100+6*length(char(app.nomeep)),22],'Text',texto);
            else
                app.butt(length(app.butt)+1)=uibutton(app.PanelEngrenagem,'Position',[341-6*length(char(app.nomeep(length(app.nomeep))))/2,250+(length(app.nomeep)-1)*-30,100+6*length(char(app.nomeep(length(app.nomeep)))),22],'Text',texto);
            end
            app.butt(length(app.butt)).ButtonPushedFcn = @app.botaoengrenagem;
        end

        % Button pushed function: EixosButton
        function EixosButtonPushed(app, event)
            app.PanelEixo.Visible = 'on';
            app.PanelEngrenagem.Visible = 'off';
            app.PanelResultado.Visible = 'off';
            app.AdicionarEixoButton.Visible='on';
            app.AtualizarEixoButton.Visible='off';
            app.DeletarEixoButton.Visible='off';
            app.AdicionarEixoPanel.Visible = 'off';
            app.AnaliseDinamica.Visible = 'off';
            app.TextArea.Visible='off';
        end

        % Button pushed function: AdicionarEixoButton
        function AdicionarEixoButtonPushed(app, event)
            app.AdicionarEixoButton.Enable = 'off';
            if(length(app.nomeex)==0)
                app.nomeex=string(['Eixo ' num2str(length(app.nomeex)+1) ' ' app.Nomeex.Value]);
                app.nomeexp=string(app.Nomeex.Value);
            else
                app.nomeex(length(app.nomeex)+1)=string(['Eixo ' num2str(length(app.nomeex)+1) ' ' app.Nomeex.Value]);
                app.nomeexp(length(app.nomeexp)+1)=string(app.Nomeex.Value);
            end
            if(app.xCheckBox.Value == 1)
                app.dire{length(app.dire)+1} = [2 3 1];
            elseif(app.yCheckBox.Value == 1)
                app.dire{length(app.dire)+1} = [3 1 2];
            elseif(app.zCheckBox.Value == 1)
                app.dire{length(app.dire)+1} = [1 2 3];
            end
            app.Sy(length(app.Sy)+1) = app.sy.Value;
            app.ns(length(app.ns)+1) = app.fs.Value;
            
            app.pm{length(app.pm)+1} = [app.xm1.Value app.ym1.Value app.zm1.Value];
            app.pm{length(app.pm)+1} = [app.xm2.Value app.ym2.Value app.zm2.Value];
            
            ind=length(app.nomeex);
            app.na{ind}=[];
            app.posa{ind}=[];
            app.n{ind}=[];
            app.pn{ind}=[];
            app.tetan{ind}=[];
            app.phi{ind}=[];
            app.tm{ind}=[];
            app.rot{ind}=[];
            app.rota{ind}=[];
            app.dir{ind}=[];
            app.dira{ind}=[];
            app.tipoe{ind}=[];
            app.p{ind}=[];
            app.d{ind}=[];
            app.r{ind}=[];
            app.da{ind}=[];
            app.ra{ind}=[];
            app.pa{ind}=[];
            app.pc{ind}=[];
            app.f{ind}=[];
            app.fr{ind}=[];
            app.fa{ind}=[];
            app.ft{ind}=[];
            app.rotacao{ind}=[];
            app.pot{ind}=[];
            
            app.Nomeex.Value = '';
            app.sy.Value = 100e3;
            if(app.sistema==1)
                app.sy.Value = app.sy.Value*6894.76;
            end
            app.fs.Value = 1;
            app.xm1.Value = 0;
            app.ym1.Value = 0;
            app.zm1.Value = 0;
            app.xm2.Value = 0;
            app.ym2.Value = 0;
            app.zm2.Value = 0;
            
           texto = char(app.nomeex(length(app.nomeex)));
            if(length(app.Sy)==1)
                app.butte=uibutton(app.PanelEixo,'Position',[341-6*length(char(app.nomeexp))/2,400+(length(app.Sy)-1)*-30,50+6*length(char(app.nomeexp)),22],'Text',texto);
            else
                app.butte(length(app.butte)+1)=uibutton(app.PanelEixo,'Position',[341-6*length(char(app.nomeexp(length(app.nomeexp))))/2,400+(length(app.Sy)-1)*-30,50+6*length(char(app.nomeexp(length(app.nomeexp)))),22],'Text',texto);
            end
            app.butte(length(app.butte)).ButtonPushedFcn = @app.botaoeixo;
        end

        % Value changed function: rd
        function rdValueChanged(app, event)
            value = app.rd.Value;
            app.rd.Value = 1;
            app.re.Value = 0;
        end

        % Value changed function: re
        function reValueChanged(app, event)
            value = app.re.Value;
            app.re.Value = 1;
            app.rd.Value = 0;
        end

        % Button pushed function: ResultadoButton
        function ResultadoButtonPushed(app, event)
            app.PanelEixo.Visible = 'off';
            app.PanelEngrenagem.Visible = 'off';
            app.PanelResultado.Visible = 'on';
            app.AdicionarEixoPanel.Visible = 'off';
            app.SelecionarElementoDropDown.Visible = 'off';
            app.SelecionarElementoDropDownLabel.Visible = 'off';
            app.FxEditField.Visible = 'off';
            app.FyEditField.Visible = 'off';
            app.FzEditField.Visible = 'off';
            app.FxEditField.Value = '';
            app.FyEditField.Value = '';
            app.FzEditField.Value = '';
            app.FxEditField_2Label.Visible = 'off';
            app.FyEditFieldLabel.Visible = 'off';
            app.FzEditFieldLabel.Visible = 'off';
            app.AnaliseDinamica.Visible = 'off';
            app.TextArea.Visible='off';
            
            format shortg
            
            if(length(app.nomeex) == 0)
                app.SelecionarEixoDropDown.Items = {'Selecionar'};
            else
                textdrop = {'Selecionar'};
                for i=1:length(app.nomeex)
                    for j=1:length(app.refe)
                        if(app.refe{j}(1) == i || app.refe{j}(3) == i)
                            if(not(ismember(char(app.nomeex(i)),textdrop)))
                                textdrop{length(textdrop)+1} = char(app.nomeex(i));
                            end
                        end
                    end
                end
                app.SelecionarEixoDropDown.Items = textdrop;
            end
            
            app.SelecionarEixoDropDown.Value = 'Selecionar';
            
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            app.DimetrodoEixoEditField.Value = '';
            app.TorqueMximoEditField.Value = '';
            app.MomentoMximoEditField.Value = '';
           
            
            %% Determinacao do mancal suporte de forcas axiais
            for l=1:length(app.Sy)
                if(length(app.f{l})>0)
                    Fm=[];
                    Fm=sym('fm%d_%d',[2,3]);
                    
                    for i=1:2
                        m(i) = false;
                    end
                    
                    contm=0;
                    for i=1:length(app.f{l})
                        contm = app.f{l}{i}(app.dire{l}(3)) + contm;
                    end
                    if(contm>=0)
                        if(app.pm{(l-1)*2+1}(app.dire{l}(3)) > app.pm{(l-1)*2+2}(app.dire{l}(3)))
                            m(1) = true;
                        elseif(app.pm{(l-1)*2+1}(app.dire{l}(3)) < app.pm{(l-1)*2+2}(app.dire{l}(3)))
                            m(2) = true;
                        end
                    elseif(contm<0)
                        if(app.pm{(l-1)*2+1}(app.dire{l}(3)) > app.pm{(l-1)*2+2}(app.dire{l}(3)))
                            m(2) = true;
                        elseif(app.pm{(l-1)*2+1}(app.dire{l}(3)) < app.pm{(l-1)*2+2}(app.dire{l}(3)))
                            m(1) = true;
                        end
                    end
                    
                    for i=1:2
                        if m(i) == false
                            Fm(i,app.dire{l}(3))=0;
                            Fmc=Fm;
                            Fmc(i,:)=[];
                        end
                    end
                    %% Calculo de Fm1 e Fm2
                    
                    % Distancias das forcas aos mancais
                    distm=[];
                    for i=1:2
                        for j=1:length(app.f{l})
                            if(app.sistema == 1)
                                distm{i}{j} = (app.pc{l}{j}-app.pm{(l-1)*2+i})/1000;
                            else
                                distm{i}{j} = (app.pc{l}{j}-app.pm{(l-1)*2+i});
                            end
                        end
                        pmholder=[];
                        if(app.sistema == 1)
                            pmholder{1} = (app.pm{(l-1)*2+1})/1000;
                            pmholder{2} = (app.pm{(l-1)*2+2})/1000;
                        else
                            pmholder{1} = (app.pm{(l-1)*2+1});
                            pmholder{2} = (app.pm{(l-1)*2+2});
                        end
                        pmholder(i)=[];
                        if(app.sistema == 1)
                            distm{i}{j+1} =pmholder{1}-(app.pm{(l-1)*2+i})/1000;
                        elseif(app.sistema == 0)
                            distm{i}{j+1} =pmholder{1}-(app.pm{(l-1)*2+i});
                        end
                    end
                    clear vettorq
                    clear tz
                    syms tz
                    vettorq(app.dire{l}(3)) = tz;
                    vettorq([app.dire{l}(2) app.dire{l}(1)]) = 0;
                    for i=1:2
                        if m(i)
                            eqm=0;
                            for j=1:length(app.f{l})
                                eqm = cross(distm{i}{j},app.f{l}{j}) + eqm;
                            end
                            for j=length(app.f{l})+1:length(app.f{l})+1
                                eqm = cross(distm{i}{j},Fmc(j-length(app.f{l}),:)) + eqm;
                            end
                            eqm = eqm + vettorq == 0;
                            sol = solve(eqm, [Fmc(j-length(app.f{l}),:),tz]);
                            solc = struct2cell(sol);
                            for j=1:length(m)
                                if not(m(j))
                                    for k=1:3
                                        Fm(j,k)=solc{k};
                                    end
                                end
                            end
                            tz = eval(solc{4});
                            eqm=0;
                            for j=1:length(app.f{l})
                                eqm = app.f{l}{j} + eqm;
                            end
                            for j=1:size(Fm,1)
                                eqm = Fm(j,:) + eqm;
                            end
                            sol = solve(eqm, [Fm(i,:)]);
                            solc = struct2cell(sol);
                            for j=1:3
                                Fm(i,j) = solc{j};
                            end
                        end
                    end
                    
                    Fm = eval(Fm);
                    for i=1:size(Fm,1)
                        app.fm{(l-1)*2+i} = Fm(i,:);
                    end
                    
                    %% Deslocando as forcas para o eixo
                    pfd=[];
                    mom=[];
                    for i=1:length(app.f{l})
                        if(app.sistema == 1)
                            pfd{i} = (app.pc{l}{i}-app.p{l}{i})/1000;
                        else
                            pfd{i} = (app.pc{l}{i}-app.p{l}{i});
                        end
                        mom{i} = cross(pfd{i},app.f{l}{i});
                    end
                    posic=[];
                    ftot=[];
                    for i=1:2
                        if(app.sistema == 1)
                            posic(i)=(app.pm{(l-1)*2+i}(app.dire{l}(3)))/1000;
                        else
                            posic(i)=(app.pm{(l-1)*2+i}(app.dire{l}(3)));
                        end
                        ftot{i}=app.fm{(l-1)*2+i}';
                    end
                    
                    for i=1:length(app.p{l})
                        if(app.sistema == 1)
                            posic = [posic app.p{l}{i}(app.dire{l}(3))/1000];
                        else
                            posic = [posic app.p{l}{i}(app.dire{l}(3))];
                        end
                        ftot{length(ftot)+1} = app.f{l}{i}';
                    end
                    
                    momtot = {[0;0;0],[0;0;0]};
                    for i=1:length(mom)
                        momtot{length(momtot)+1} = mom{i}';
                    end
                    [stops,stopsI] = sort(posic,'ascend');
                    
                    app.M{l}=cell(3,length(stops)-1);
                    
                    for i=1:length(stops)-1
                        app.M{l}{1,i} = linspace(posic(stopsI(i)),posic(stopsI(i+1)));
                        Mparcialx(1:100) = 0;
                        Mparcialy(1:100) = 0;
                        for j=1:i
                            for k=1:length(app.M{l}{1,i})
                                Mparcialx(k) = (posic(stopsI(j))-app.M{l}{1,i}(k))*ftot{stopsI(j)}(app.dire{l}(1))+momtot{stopsI(j)}(app.dire{l}(2))+Mparcialx(k);
                                Mparcialy(k) = (posic(stopsI(j))-app.M{l}{1,i}(k))*ftot{stopsI(j)}(app.dire{l}(2))-momtot{stopsI(j)}(app.dire{l}(1))+Mparcialy(k);
                            end
                        end
                        app.M{l}{2,i} = Mparcialx;
                        app.M{l}{3,i} = Mparcialy;
                        for(conti=1:length(app.M{l}{2,i}))
                            if(abs(app.M{l}{2,i}(conti))<1e-4)
                                app.M{l}{2,i}(conti)=0;
                            end
                            if(abs(app.M{l}{3,i}(conti))<1e-4)
                                app.M{l}{3,i}(conti)=0;
                            end
                        end
                    end
                    app.M{l}{1,length(stops)-1}(101)=app.M{l}{1,length(stops)-1}(100);
                    app.M{l}{2,length(stops)-1}(101)=app.M{l}{2,length(stops)-1}(100)+momtot{stopsI(length(stops))}(app.dire{l}(2));
                    app.M{l}{3,length(stops)-1}(101)=app.M{l}{3,length(stops)-1}(100)-momtot{stopsI(length(stops))}(app.dire{l}(1));
                    
                    %% Momento resultante
                    
                    Mr = (cell2mat(app.M{l}(2,:)).^2+cell2mat(app.M{l}(3,:)).^2).^(1/2);
                    app.Mrmax(l) = max(Mr);
                    for i=1:length(momtot)
                        tor(i) = momtot{i}(app.dire{l}(3));
                    end
                    app.Tmax(l) = max(abs(tor));
                    
                    %% Determinando diametro do eixo
                     
                     app.de(l) = (16*app.ns(l)/(pi*app.Sy(l))*(app.Mrmax(l)^2+app.Tmax(l)^2)^(1/2))^(1/3);
                     app.de(l) = (round(app.de(l),3));
                     if(app.sistema == 1)
                         app.de(l) = app.de(l)*1000;
                     end
                end
            end
        end

        % Close request function: ElementosUIFigure
        function ElementosUIFigureCloseRequest(app, event)
            %             save('tccworkspace.mat');
            delete(app);
        end

        % Value changed function: TipodeengrenagemDropDown
        function TipoEngDropDownValueChanged(app, event)
            app.AdicionarEngrenagensButton.Visible='on';
            app.AtualizarEngrenagensButton.Visible='off';
            app.DeletarEngrenagensButton.Visible='off';
            value = app.TipodeengrenagemDropDown.Value;
            app.nd2.Value = 10;
            app.ar.Value = 0;
            app.EixoDropDown.Value = 'Selecionar';
            app.EixoDropDown_2.Value = 'Selecionar';
            app.EixoDropDown_2.Enable = 'off';
            app.AdicionarEixoPanel.Visible = 'off';
            
            if(value == string('Dentes Retos'))
                app.EngrenagemDentesRetos.Visible = 'on';
                app.EngrenagemHelicoidal.Visible = 'off';
                app.EngrenagemConicaDR.Visible = 'off';
                app.EngrenagemSemFim.Visible = 'off';
                app.EngrenagemAcopladaPanel_2.Visible = 'off';
                app.EngrenagemAcopladaPanel.Visible = 'on';
                app.ar.Visible = 'on';
                app.ar2.Visible = 'off';
            elseif(value == string('Helicoidal'))
                app.EngrenagemHelicoidal.Visible = 'on';
                app.EngrenagemDentesRetos.Visible = 'off';
                app.EngrenagemConicaDR.Visible = 'off';
                app.EngrenagemSemFim.Visible = 'off';
                app.EngrenagemAcopladaPanel_2.Visible = 'off';
                app.EngrenagemAcopladaPanel.Visible = 'on';
                app.ar.Visible = 'on';
                app.ar2.Visible = 'off';
            elseif(value == string('Cônica D.R.'))
                app.EngrenagemConicaDR.Visible = 'on';
                app.ar.Visible = 'off';
                app.ar2.Visible = 'on';
                app.ar2.Value = 'Selecionar';
                app.EngrenagemHelicoidal.Visible = 'of';
                app.EngrenagemDentesRetos.Visible = 'off';
                app.EngrenagemSemFim.Visible = 'off';
                app.EngrenagemAcopladaPanel_2.Visible = 'off';
                app.EngrenagemAcopladaPanel.Visible = 'on';
            elseif(value == string('Sem Fim'))
                app.EngrenagemHelicoidal.Visible = 'off';
                app.EngrenagemDentesRetos.Visible = 'off';
                app.EngrenagemConicaDR.Visible = 'off';
                app.EngrenagemSemFim.Visible = 'on';
                app.EngrenagemAcopladaPanel_2.Visible = 'on';
                app.EngrenagemAcopladaPanel.Visible = 'off';
                app.ar.Visible = 'off';
                app.ar2.Visible = 'on';
            end
            
                
        end

        % Callback function
        function rd_2ValueChanged(app, event)
            app.re_2.Value = 0;
            app.rd_2.Value = 1;
        end

        % Callback function
        function re_2ValueChanged(app, event)
            app.rd_2.Value = 0;
            app.re_2.Value = 1;
        end

        % Button pushed function: AtualizarEngrenagensButton
        function AtualizarEngrenagensButtonPushed(app, event)
            but=app.bot;
            nomes = [];
            for i=1:length(app.butte)
                nomes = [nomes string(app.butte(i).Text)];
            end
            ind = find(nomes == string(app.EixoDropDown.Value));
            nomes2 = [];
            for i=1:length(app.butte)
                nomes2 = [nomes2 string(app.butte(i).Text)];
            end
            ind2 = find(nomes2 == string(app.EixoDropDown_2.Value));
            if(ind == app.refe{but}(1) && ind2 == app.refe{but}(3))
                deletarengrenagens(app,but,1)
                adicionarengrenagens(app,app.refe{but},but)
            else
                deletarengrenagens(app,but,0)
                for(i=1:length(app.refe))
                    if(app.refe{i}(1) == app.refe{but}(1) && app.refe{i}(2) > app.refe{but}(2))
                        app.refe{i}(2) = app.refe{i}(2)-1;
                    end
                    if(app.refe{i}(1) == app.refe{but}(3) && app.refe{i}(2) > app.refe{but}(4))
                        app.refe{i}(2) = app.refe{i}(2)-1;
                    end
                    if(app.refe{i}(3) == app.refe{but}(1) && app.refe{i}(4) > app.refe{but}(2))
                        app.refe{i}(4) = app.refe{i}(4)-1;
                    end
                    if(app.refe{i}(3) == app.refe{but}(3) && app.refe{i}(4) > app.refe{but}(4))
                        app.refe{i}(4) = app.refe{i}(4)-1;
                    end
                end
                if(length(app.n)<ind)
                    if(length(app.n)<ind2)
                        app.refe{but}=[ind,1,ind2,1];
                    else
                        app.refe{but}=[ind,1,ind2,length(app.n{ind2})+1];
                    end
                else
                    if(length(app.n)<ind2)
                        app.refe{but}=[ind,length(app.n{ind})+1,ind2,1];
                    else
                        app.refe{but}=[ind,length(app.n{ind})+1,ind2,length(app.n{ind2})+1];
                    end
                end
                
                adicionarengrenagens(app,-1,but)
            end
            app.AtualizarEngrenagensButton.Visible = 'off';
            app.DeletarEngrenagensButton.Visible = 'off';
            app.AdicionarEngrenagensButton.Visible = 'on';
            app.pot1.Enable = 'on';
            app.pot_2.Enable = 'on';
            app.pot_3.Enable = 'on';
            app.rot_1.Enable = 'on';
            app.hor.Enable = 'on';
            app.ahor.Enable = 'on';
            app.rot_2.Enable = 'on';
            app.hor_2.Enable = 'on';
            app.ahor_2.Enable = 'on';
            app.rot_3.Enable = 'on';
            app.hor_3.Enable = 'on';
            app.ahor_3.Enable = 'on';
            app.xeng.Enable = 'on';
            app.xeng_2.Enable = 'on';
            app.xeng_6.Enable = 'on';
            app.xeng_7.Enable = 'on';
            app.yeng.Enable = 'on';
            app.yeng_2.Enable = 'on';
            app.yeng_6.Enable = 'on';
            app.yeng_7.Enable = 'on';
            app.zeng.Enable = 'on';
            app.zeng_2.Enable = 'on';
            app.zeng_6.Enable = 'on';
            app.zeng_7.Enable = 'on';
        end

        % Button pushed function: AtualizarEixoButton
        function AtualizarEixoButtonPushed(app, event)
            but=app.botex;
            app.nomeex(but)=['Eixo ' num2str(but) ' ' app.Nomeex.Value];
            app.nomeexp(but)=app.Nomeex.Value;
            app.butte(but).Text=char(app.nomeex(but));
            app.butte(but).Position([1,3]) = [341-6*length(char(app.nomeexp(but)))/2,50+6*length(char(app.nomeexp(but)))];
            app.Sy(but) = app.sy.Value;
            app.ns(but) = app.fs.Value;
            
            app.pm{but*2-1} = [app.xm1.Value app.ym1.Value app.zm1.Value];
            app.pm{but*2} = [app.xm2.Value app.ym2.Value app.zm2.Value];
            
            app.Nomeex.Value = '';
            app.sy.Value = 100e3;
            app.fs.Value = 1;
            app.xm1.Value = 0;
            app.ym1.Value = 0;
            app.zm1.Value = 0;
            app.xm2.Value = 0;
            app.ym2.Value = 0;
            app.zm2.Value = 0;
            
            if(app.xCheckBox.Value == 1)
                app.dire{but} = [2 3 1];
            elseif(app.yCheckBox.Value == 1)
                app.dire{but} = [3 1 2];
            elseif(app.zCheckBox.Value ==1 )
                app.dire{but} = [1 2 3];
            end
            
            app.AdicionarEixoButton.Visible='on';
            app.AtualizarEixoButton.Visible='off';
            app.DeletarEixoButton.Visible='off';
        end

        % Button pushed function: DeletarEngrenagensButton
        function DeletarEngrenagensButtonPushed(app, event)
            but=app.bot;
            deletarengrenagens(app,but,0);
            for i=1:length(app.refe)
                if((app.refe{i}(2)>app.refe{but}(2)) && (app.refe{i}(1) == app.refe{but}(1)))
                    app.refe{i}(2) = app.refe{i}(2)-1;
                end
                if((app.refe{i}(2)>app.refe{but}(4)) && (app.refe{i}(1) == app.refe{but}(3)))
                    app.refe{i}(2) = app.refe{i}(2)-1;
                end
                if((app.refe{i}(4)>app.refe{but}(4)) && (app.refe{i}(3) == app.refe{but}(3)))
                    app.refe{i}(4) = app.refe{i}(4)-1;
                end
                if((app.refe{i}(4)>app.refe{but}(2)) && (app.refe{i}(3) == app.refe{but}(1)))
                    app.refe{i}(4) = app.refe{i}(4)-1;
                end
            end
                
            app.refe(but)=[];
            app.EixoDropDown.Value = ('Selecionar');
            app.EixoDropDown_2.Value = ('Selecionar');
            app.AdicionarEngrenagensButton.Enable = 'off';
            if(app.TipodeengrenagemDropDown.Value == string('Helicoidal'))
                app.nd1.Value = 10;
                app.pd.Value = 5;
                app.ah.Value = 0;
                app.ap.Value = 0;
                app.pot1.Value = 0;
                app.xeng.Value = 0;
                app.yeng.Value = 0;
                app.zeng.Value = 0;
                app.rd.Value = 1;
                app.re.Value = 0;
                app.nd2.Value = 10;
                app.ar.Value = 0;
                app.hor.Value=1;
                app.ahor.Value = 0;
                app.pot1.Value = 0;
                app.rot_1.Value = 0;
            elseif(app.TipodeengrenagemDropDown.Value == string('Dentes Retos'))
                app.Nomee.Value = '';
                app.nd1_2.Value = 10;
                app.pd_2.Value = 5;
                app.ap_2.Value = 0;
                app.pot_2.Value = 0;
                app.xeng_2.Value = 0;
                app.yeng_2.Value = 0;
                app.zeng_2.Value = 0;
                app.nd2.Value = 10;
                app.ar.Value = 0;
                app.hor_2.Value=1;
                app.ahor_2.Value = 0;
                app.pot_2.Value = 0;
                app.rot_2.Value = 0;
            elseif(app.TipodeengrenagemDropDown.Value == string('Cônica D.R.'))
                app.Nomee.Value = '';
                app.nd1_3.Value = 10;
                app.pd_3.Value = 5;
                app.ap_3.Value = 0;
                app.pot_3.Value = 0;
                app.xeng_6.Value = 0;
                app.yeng_6.Value = 0;
                app.zeng_6.Value = 0;
                app.cone1.Value = 1;
                app.cone2.Value = 0;
                app.lff.Value = 1;
                app.hor_3.Value = 1;
                app.ahor_3.Value = 0;
                app.rot_3.Value = 0;
            end
            delete(app.butt(but));
            app.butt(but)=[];
            for(i=but:length(app.butt))
                app.butt(i).Text = ['Engrenagens ' num2str(i) ' ' char(app.nomeep(i))];
                app.butt(i).Position(2)=app.butt(i).Position(2)+30;
            end
            app.AdicionarEngrenagensButton.Visible='on';
            app.AtualizarEngrenagensButton.Visible='off';
            app.DeletarEngrenagensButton.Visible='off';
            app.EixoDropDown_2.Enable = 'off';
            app.EixoDropDown_2.Value = 'Selecionar';
            app.pot1.Enable = 'on';
            app.pot_2.Enable = 'on';
            app.pot_3.Enable = 'on';
            app.rot_1.Enable = 'on';
            app.hor.Enable = 'on';
            app.ahor.Enable = 'on';
            app.rot_2.Enable = 'on';
            app.hor_2.Enable = 'on';
            app.ahor_2.Enable = 'on';
            app.rot_3.Enable = 'on';
            app.hor_3.Enable = 'on';
            app.ahor_3.Enable = 'on';
            app.xeng.Enable = 'on';
            app.xeng_2.Enable = 'on';
            app.xeng_6.Enable = 'on';
            app.xeng_7.Enable = 'on';
            app.yeng.Enable = 'on';
            app.yeng_2.Enable = 'on';
            app.yeng_6.Enable = 'on';
            app.yeng_7.Enable = 'on';
            app.zeng.Enable = 'on';
            app.zeng_2.Enable = 'on';
            app.zeng_6.Enable = 'on';
            app.zeng_7.Enable = 'on';
        end

        % Button pushed function: DeletarEixoButton
        function DeletarEixoButtonPushed(app, event)
            but=app.botex;
            app.nomeex(but)=[];
            app.nomeexp(but)=[];
            
            app.Sy(but) = [];
            app.ns(but) = [];
            app.pm((but-1)*2+1) = [];
            app.pm((but-1)*2+1) = [];
            app.dire(but) = [];
            
            if(length(app.na)>=but)
                app.na(but)=[];
                app.posa(but)=[];
                app.n(but)=[];
                app.pn(but)=[];
                app.tetan(but)=[];
                app.phi(but)= [];
                app.tm(but)=[];
                
                app.tipoe(but) = [];
                app.rot(but)=[];
                app.rota(but)=[];
                app.dir(but) = [];
                app.dira(but) = [];
                app.p(but)=[];
                app.d(but) = [];
                app.r(but) = [];
                app.da(but) = [];
                app.ra(but) = [];
                app.pa(but) = [];
                app.pc(but)=[];
                app.ft(but) = []; % Tangencial
                app.fr(but) = []; % Radial
                app.fa(but) = []; % Axial
                app.f(but) = [];
            end
            
            app.Nomeex.Value = '';
            app.sy.Value = 100e3;
            app.fs.Value = 1;
            app.xm1.Value = 0;
            app.ym1.Value = 0;
            app.zm1.Value = 0;
            app.xm2.Value = 0;
            app.ym2.Value = 0;
            app.zm2.Value = 0;
                
           
            delete(app.butte(but));
            app.butte(but)=[];
            for(i=but:length(app.butte))
                app.butte(i).Text = ['Eixo ' num2str(i) ' ' char(app.nomeexp(i))];
                app.butte(i).Position(2)=app.butte(i).Position(2)+30;
                app.nomeex(i)=string(['Eixo ' num2str(i) ' ' char(app.nomeexp(i))]);
            end
            app.AdicionarEixoButton.Visible='on';
            app.AtualizarEixoButton.Visible='off';
            app.DeletarEixoButton.Visible='off';
            
            for(i=1:length(app.refe))
                if(app.refe{i}(1) > but)
                    app.refe{i}(1) = app.refe{i}(1) - 1;
                end
                if(app.refe{i}(3) > but)
                    app.refe{i}(3) = app.refe{i}(3) - 1;
                end
            end
        end

        % Value changed function: xCheckBox
        function xCheckBoxValueChanged(app, event)
            app.xCheckBox.Value = 1;
            app.yCheckBox.Value = 0;
            app.zCheckBox.Value = 0;
            app.ym2.Enable = 'off';
            app.zm2.Enable = 'off';
            app.xm2.Enable = 'on';
            app.AdicionarEixoButton.Enable = 'off';
            app.ym2.Value = app.ym1.Value;
            app.zm2.Value = app.zm1.Value;
        end

        % Value changed function: yCheckBox
        function yCheckBoxValueChanged(app, event)
            app.xCheckBox.Value = 0;
            app.yCheckBox.Value = 1;
            app.zCheckBox.Value = 0;
            app.xm2.Enable = 'off';
            app.zm2.Enable = 'off';
            app.ym2.Enable = 'on';
            app.AdicionarEixoButton.Enable = 'off';
            app.xm2.Value = app.xm1.Value;
            app.zm2.Value = app.zm1.Value;
            
        end

        % Value changed function: zCheckBox
        function zCheckBoxValueChanged(app, event)
            app.xCheckBox.Value = 0;
            app.yCheckBox.Value = 0;
            app.zCheckBox.Value = 1;
            app.ym2.Enable = 'off';
            app.xm2.Enable = 'off';
            app.zm2.Enable = 'on';
            app.AdicionarEixoButton.Enable = 'off';
            app.xm2.Value = app.xm1.Value;
            app.ym2.Value = app.ym1.Value;
            
        end

        % Value changed function: xm1
        function xm1ValueChanged(app, event)
            value = app.xm1.Value;
            if(not(app.xCheckBox.Value == 1))
                app.xm2.Value = value;
            end
            mancaldiferente(app);
            
        end

        % Value changed function: ym1
        function ym1ValueChanged(app, event)
            value = app.ym1.Value;
            if(not(app.yCheckBox.Value == 1))
                app.ym2.Value = value;
            end
            mancaldiferente(app);
        end

        % Value changed function: zm1
        function zm1ValueChanged(app, event)
            value = app.zm1.Value;
            if(not(app.zCheckBox.Value == 1))
                app.zm2.Value = value;
            end
            mancaldiferente(app);
        end

        % Value changed function: EixoDropDown
        function EixoDropDownValueChanged(app, event)
            value = string(app.EixoDropDown.Value);
            app.rot_1.Enable = 'on';
            app.hor.Enable = 'on';
            app.ahor.Enable = 'on';
            app.rot_2.Enable = 'on';
            app.hor_2.Enable = 'on';
            app.ahor_2.Enable = 'on';
            app.rot_3.Enable = 'on';
            app.hor_3.Enable = 'on';
            app.ahor_3.Enable = 'on';
            app.rot_4.Enable = 'on';
            app.hor_4.Enable = 'on';
            app.ahor_4.Enable = 'on';
            
            ind=[];
            if(length(app.butte)>0)
                nomes = [];
                for i=1:length(app.butte)
                    nomes = [nomes string(app.butte(i).Text)];
                end
                ind = find(nomes == string(app.EixoDropDown.Value));
            end
            if(not(value == string('Selecionar') || value == string('Adicionar Eixos')))
                app.EixoDropDown_2.Enable = 'on';
            end
            app.AdicionarEixoPanel.Visible = 'off';
            if(string(app.TipodeengrenagemDropDown.Value) == string('Dentes Retos'))
                app.xeng_2.Enable = 'on';
                app.yeng_2.Enable = 'on';
                app.zeng_2.Enable = 'on';
                if(length(ind)>0)
                    if(length(app.f{ind})>0)
                        app.rot_2.Enable = 'off';
                        app.hor_2.Enable = 'off';
                        app.ahor_2.Enable = 'off';
                        if(app.rot{ind}(1) == app.strh)
                            app.hor_2.Value = 1;
                            app.ahor_2.Value = 0;
                        elseif(app.rot{ind}(1) == app.strah)
                            app.hor_2.Value = 0;
                            app.ahor_2.Value = 1;
                        end
                        app.rot_2.Value = app.rotacao{ind}(1);
                        sumpot=-sum(app.pot{ind}(1:length(app.pot{ind})))+2*app.pot{ind}(1);
                        app.pot_2.Value = sumpot;
                    end
                end
            elseif(string(app.TipodeengrenagemDropDown.Value) == string('Helicoidal'))
                app.xeng.Enable = 'on';
                app.yeng.Enable = 'on';
                app.zeng.Enable = 'on';
                if(length(ind)>0)
                    if(length(app.f{ind})>0)
                        app.rot_1.Enable = 'off';
                        app.hor.Enable = 'off';
                        app.ahor.Enable = 'off';
                        if(app.rot{ind}(1) == app.strh)
                            app.hor.Value = 1;
                            app.ahor.Value = 0;
                        elseif(app.rot{ind}(1) == app.strah)
                            app.hor.Value = 0;
                            app.ahor.Value = 1;
                        end
                        app.rot_1.Value = app.rotacao{ind}(1);
                        sumpot=-sum(app.pot{ind}(1:length(app.pot{ind})))+2*app.pot{ind}(1);
                        app.pot1.Value = sumpot;
                    end
                end
            elseif(string(app.TipodeengrenagemDropDown.Value) == string('Cônica D.R.'))
                app.xeng_6.Enable = 'on';
                app.yeng_6.Enable = 'on';
                app.zeng_6.Enable = 'on';
                if(length(ind)>0)
                    if(length(app.f{ind})>0)
                        app.rot_3.Enable = 'off';
                        app.hor_3.Enable = 'off';
                        app.ahor_3.Enable = 'off';
                        if(app.rot{ind}(1) == app.strh)
                            app.hor_3.Value = 1;
                            app.ahor_3.Value = 0;
                        elseif(app.rot{ind}(1) == app.strah)
                            app.hor_3.Value = 0;
                            app.ahor_3.Value = 1;
                        end
                        app.rot_3.Value = app.rotacao{ind}(1);
                        sumpot=-sum(app.pot{ind}(1:length(app.pot{ind})))+2*app.pot{ind}(1);
                        app.pot_3.Value = sumpot;
                    end
                end
            elseif(string(app.TipodeengrenagemDropDown.Value) == string('Sem Fim'))
                app.xeng_7.Enable = 'on';
                app.yeng_7.Enable = 'on';
                app.zeng_7.Enable = 'on';
                if(length(ind)>0)
                    if(length(app.f{ind})>0)
                        app.rot_4.Enable = 'off';
                        app.hor_4.Enable = 'off';
                        app.ahor_4.Enable = 'off';
                        if(app.rot{ind}(1) == app.strh)
                            app.hor_4.Value = 1;
                            app.ahor_4.Value = 0;
                        elseif(app.rot{ind}(1) == app.strah)
                            app.hor_4.Value = 0;
                            app.ahor_4.Value = 1;
                        end
                        app.rot_4.Value = app.rotacao{ind}(1);
                        sumpot=-sum(app.pot{ind}(1:length(app.pot{ind})))+2*app.pot{ind}(1);
                        app.pot_4.Value = sumpot;
                    end
                end
            end
            liberareng(app)
            if(value == string('Adicionar Eixo'))
                app.AdicionarEixoPanel.Visible = 'on';
                app.xeng_4.Enable = 'on';
                app.yeng_4.Enable = 'on';
                app.zeng_4.Enable = 'on';
                app.zeng_4.Value = 0;
                app.zeng_5.Value = 0;
                if(string(app.TipodeengrenagemDropDown.Value) == string('Dentes Retos'))
                    app.yeng_4.Value = app.yeng_2.Value;
                    app.yeng_5.Value = app.yeng_2.Value;
                    app.xeng_4.Value = app.xeng_2.Value;
                    app.xeng_5.Value = app.xeng_2.Value;
                elseif(string(app.TipodeengrenagemDropDown.Value) == string('Helicoidal'))
                    app.yeng_4.Value = app.yeng.Value;
                    app.yeng_5.Value = app.yeng.Value;
                    app.xeng_4.Value = app.xeng.Value;
                    app.xeng_5.Value = app.xeng.Value;
                elseif(string(app.TipodeengrenagemDropDown.Value) == string('Cônica D.R.'))
                    app.yeng_4.Value = app.yeng_6.Value;
                    app.yeng_5.Value = app.yeng_6.Value;
                    app.xeng_4.Value = app.xeng_6.Value;
                    app.xeng_5.Value = app.xeng_6.Value;
                elseif(string(app.TipodeengrenagemDropDown.Value) == string('Sem Fim'))
                    app.yeng_4.Value = app.yeng_7.Value;
                    app.yeng_5.Value = app.yeng_7.Value;
                    app.xeng_4.Value = app.xeng_7.Value;
                    app.xeng_5.Value = app.xeng_7.Value;
                end
                app.chamada = 'm';
            end
            atualizarposicaoeng(app);
            app.EixoDropDown_2.Value = 'Selecionar';
            
        end

        % Value changed function: EixoDropDown_2
        function EixoDropDown_2ValueChanged(app, event)
            value = string(app.EixoDropDown_2.Value);
            app.AdicionarEixoPanel.Visible = 'off';
            liberareng(app)
            if(value == string('Adicionar Eixo'))
                app.AdicionarEixoPanel.Visible = 'on';
                app.chamada = 'a';
                if(string(app.AdicionarEixoPanel.Visible) == string('on'))
                    atualizareixoeng(app)
                end
            end
            
        end

        % Value changed function: xCheckBox_2
        function xCheckBox_2ValueChanged(app, event)
            app.xCheckBox_2.Value = 1;
            app.yCheckBox_2.Value = 0;
            app.zCheckBox_2.Value = 0;
            app.yeng_5.Enable = 'off';
            app.zeng_5.Enable = 'off';
            app.xeng_4.Enable = 'on';
            app.xeng_5.Enable = 'on';
            app.AdicionarEixoButton_2.Enable = 'off';
            app.xeng_4.Value = 0;
            app.xeng_5.Value = 0;
            if(string(app.chamada)) == string('m')
                if(string(app.TipodeengrenagemDropDown.Value)) == string('Helicoidal')
                    app.yeng_4.Value = app.yeng.Value;
                    app.yeng_5.Value = app.yeng.Value;
                    app.zeng_4.Value = app.zeng.Value;
                    app.zeng_5.Value = app.zeng.Value;
                elseif(string(app.TipodeengrenagemDropDown.Value)) == string('Dentes Retos')
                    app.yeng_4.Value = app.yeng_2.Value;
                    app.yeng_5.Value = app.yeng_2.Value;
                    app.zeng_4.Value = app.zeng_2.Value;
                    app.zeng_5.Value = app.zeng_2.Value;
                end
            elseif(string(app.chamada)) == string('a')
            end
        end

        % Value changed function: yCheckBox_2
        function yCheckBox_2ValueChanged(app, event)
            app.xCheckBox_2.Value = 0;
            app.yCheckBox_2.Value = 1;
            app.zCheckBox_2.Value = 0;
            app.xeng_5.Enable = 'off';
            app.zeng_5.Enable = 'off';
            app.yeng_4.Enable = 'on';
            app.yeng_5.Enable = 'on';
            app.AdicionarEixoButton_2.Enable = 'off';
            app.yeng_4.Value = 0;
            app.yeng_5.Value = 0;
            if(string(app.chamada)) == string('m')
                if(string(app.TipodeengrenagemDropDown.Value)) == string('Helicoidal')
                    app.xeng_4.Value = app.xeng.Value;
                    app.xeng_5.Value = app.xeng.Value;
                    app.zeng_4.Value = app.zeng.Value;
                    app.zeng_5.Value = app.zeng.Value;
                elseif(string(app.TipodeengrenagemDropDown.Value)) == string('Dentes Retos')
                    app.xeng_4.Value = app.xeng_2.Value;
                    app.xeng_5.Value = app.xeng_2.Value;
                    app.zeng_4.Value = app.zeng_2.Value;
                    app.zeng_5.Value = app.zeng_2.Value;
                end
            elseif(string(app.chamada)) == string('a')
            end
        end

        % Value changed function: zCheckBox_2
        function zCheckBox_2ValueChanged(app, event)
            app.xCheckBox_2.Value = 0;
            app.yCheckBox_2.Value = 0;
            app.zCheckBox_2.Value = 1;
            app.xeng_5.Enable = 'off';
            app.yeng_5.Enable = 'off';
            app.zeng_4.Enable = 'on';
            app.zeng_5.Enable = 'on';
            app.AdicionarEixoButton_2.Enable = 'off';
            app.zeng_4.Value = 0;
            app.zeng_5.Value = 0;
            if(string(app.chamada)) == string('m')
                if(string(app.TipodeengrenagemDropDown.Value)) == string('Helicoidal')
                    app.xeng_4.Value = app.xeng.Value;
                    app.xeng_5.Value = app.xeng.Value;
                    app.yeng_4.Value = app.yeng.Value;
                    app.yeng_5.Value = app.yeng.Value;
                elseif(string(app.TipodeengrenagemDropDown.Value)) == string('Dentes Retos')
                    app.xeng_4.Value = app.xeng_2.Value;
                    app.xeng_5.Value = app.xeng_2.Value;
                    app.yeng_4.Value = app.yeng_2.Value;
                    app.yeng_5.Value = app.yeng_2.Value;
                end
            elseif(string(app.chamada)) == string('a')
            end
        end

        % Value changed function: xeng
        function xengValueChanged(app, event)
            value = app.xeng.Value;
            if(not(app.xCheckBox_2.Value == 1))
                app.xeng_5.Value = value;
                app.xeng_4.Value = value;
            end
        end

        % Value changed function: yeng
        function yengValueChanged(app, event)
            value = app.yeng.Value;
            if(not(app.yCheckBox_2.Value == 1))
                app.yeng_5.Value = value;
                app.yeng_4.Value = value;
            end
            
        end

        % Value changed function: zeng
        function zengValueChanged(app, event)
            value = app.zeng.Value;
            if(not(app.zCheckBox_2.Value == 1))
                app.zeng_5.Value = value;
                app.zeng_4.Value = value;
            end
            
        end

        % Value changed function: xeng_2
        function xeng_2ValueChanged(app, event)
            value = app.xeng_2.Value;
            if(not(app.xCheckBox_2.Value == 1))
                app.xeng_5.Value = value;
                app.xeng_4.Value = value;
            end
        end

        % Value changed function: yeng_2
        function yeng_2ValueChanged(app, event)
            value = app.yeng_2.Value;
            if(not(app.yCheckBox_2.Value == 1))
                app.yeng_5.Value = value;
                app.yeng_4.Value = value;
            end
        end

        % Value changed function: zeng_2
        function zeng_2ValueChanged(app, event)
            value = app.zeng_2.Value;
            if(not(app.zCheckBox_2.Value == 1))
                app.zeng_5.Value = value;
                app.zeng_4.Value = value;
            end
        end

        % Button pushed function: AdicionarEixoButton_2
        function AdicionarEixoButton_2Pushed(app, event)
            app.AdicionarEixoButton_2.Enable = 'off';
            if(length(app.nomeex)==0)
                app.nomeex=string(['Eixo ' num2str(length(app.nomeex)+1) ' ' app.Nomeex2.Value]);
                app.nomeexp=string(app.Nomeex2.Value);
            else
                app.nomeex(length(app.nomeex)+1)=string(['Eixo ' num2str(length(app.nomeex)+1) ' ' app.Nomeex2.Value]);
                app.nomeexp(length(app.nomeexp)+1)=string(app.Nomeex2.Value);
            end
            if(app.xCheckBox_2.Value == 1)
                app.dire{length(app.dire)+1} = [2 3 1];
            elseif(app.yCheckBox_2.Value == 1)
                app.dire{length(app.dire)+1} = [3 1 2];
            elseif(app.zCheckBox_2.Value == 1)
                app.dire{length(app.dire)+1} = [1 2 3];
            end
            app.Sy(length(app.Sy)+1) = app.sy2.Value;
            app.ns(length(app.ns)+1) = app.fs2.Value;
            
            app.pm{length(app.pm)+1} = [app.xeng_4.Value app.yeng_4.Value app.zeng_4.Value];
            app.pm{length(app.pm)+1} = [app.xeng_5.Value app.yeng_5.Value app.zeng_5.Value];
            
            ind=length(app.nomeex);
            app.na{ind}=[];
            app.posa{ind}=[];
            app.n{ind}=[];
            app.pn{ind}=[];
            app.tetan{ind}=[];
            app.phi{ind}=[];
            app.tm{ind}=[];
            app.rot{ind}=[];
            app.rota{ind}=[];
            app.dir{ind}=[];
            app.dira{ind}=[];
            app.tipoe{ind}=[];
            app.p{ind}=[];
            app.d{ind}=[];
            app.r{ind}=[];
            app.da{ind}=[];
            app.ra{ind}=[];
            app.pa{ind}=[];
            app.pc{ind}=[];
            app.f{ind}=[];
            app.fr{ind}=[];
            app.fa{ind}=[];
            app.ft{ind}=[];
            app.rotacao{ind}=[];
            app.pot{ind}=[];
            
            app.Nomeex2.Value = '';
            app.sy2.Value = 100e3;
            if(app.sistema==1)
                app.sy2.Value = app.sy2.Value*6894.76;
            end
            app.fs2.Value = 1;
            app.xeng_4.Value = 0;
            app.yeng_4.Value = 0;
            app.zeng_4.Value = 0;
            app.xeng_5.Value = 0;
            app.yeng_5.Value = 0;
            app.zeng_5.Value = 0;
            
           texto = char(app.nomeex(length(app.nomeex)));
            if(length(app.Sy)==1)
                app.butte=uibutton(app.PanelEixo,'Position',[341-6*length(char(app.nomeexp))/2,400+(length(app.Sy)-1)*-30,50+6*length(char(app.nomeexp)),22],'Text',texto);
            else
                app.butte(length(app.butte)+1)=uibutton(app.PanelEixo,'Position',[341-6*length(char(app.nomeexp(length(app.nomeexp))))/2,400+(length(app.Sy)-1)*-30,50+6*length(char(app.nomeexp(length(app.nomeexp)))),22],'Text',texto);
            end
            app.butte(length(app.butte)).ButtonPushedFcn = @app.botaoeixo;
            
            if(length(app.nomeex) == 0)
                app.EixoDropDown.Items = {'Selecionar' 'Adicionar Eixo'};
                app.EixoDropDown_2.Items = {'Selecionar' 'Adicionar Eixo'};
            else
                textdrop = {'Selecionar' 'Adicionar Eixo'};
                for i=1:length(app.nomeex)
                    textdrop{i+2} = char(app.nomeex(i));
                end
                app.EixoDropDown.Items = textdrop;
                atualizardropdown(app);
            end
            if(string(app.chamada) == string('m'))
                app.EixoDropDown.Value = char(app.nomeex(length(app.nomeex)));
                app.EixoDropDown_2.Enable = 'on';
            elseif(string(app.chamada) == string('a'))
                app.EixoDropDown_2.Value = char(app.nomeex(length(app.nomeex)));
                app.EixoDropDown_2.Enable = 'on';
            end
            app.AdicionarEixoPanel.Visible = 'off';
            liberareng(app)
            atualizarposicaoeng(app)
            app.chamada=string('m');
        end

        % Value changed function: nd2
        function nd2ValueChanged(app, event)
            atualizardropdown(app)
            if(length(app.chamada) > 0)
                if(string(app.chamada) == string('a'))
                    if(string(app.AdicionarEixoPanel.Visible) == string('on'))
                        atualizareixoeng(app)
                    end
                end
            end    
        end

        % Value changed function: ar
        function arValueChanged(app, event)
            atualizardropdown(app);
            if(length(app.chamada) > 0)
                if(string(app.chamada) == string('a'))
                    if(string(app.AdicionarEixoPanel.Visible) == string('on'))
                        atualizareixoeng(app)
                    end
                end
            end
        end

        % Value changed function: nd1
        function nd1ValueChanged(app, event)
            atualizardropdown(app);
            if(length(app.chamada) > 0)
                if(string(app.chamada) == string('a'))
                    if(string(app.AdicionarEixoPanel.Visible) == string('on'))
                        atualizareixoeng(app)
                    end
                end
            end    
        end

        % Value changed function: pd
        function pdValueChanged(app, event)
            atualizardropdown(app);
            if(length(app.chamada) > 0)
                if(string(app.chamada) == string('a'))
                    if(string(app.AdicionarEixoPanel.Visible) == string('on'))
                        atualizareixoeng(app)
                    end
                end
            end    
        end

        % Value changed function: ah
        function ahValueChanged(app, event)
            atualizardropdown(app);
            if(length(app.chamada) > 0)
                if(string(app.chamada) == string('a'))
                    if(string(app.AdicionarEixoPanel.Visible) == string('on'))
                        atualizareixoeng(app)
                    end
                end
            end    
        end

        % Value changed function: nd1_2
        function nd1_2ValueChanged(app, event)
            atualizardropdown(app);
            if(length(app.chamada) > 0)
                if(string(app.chamada) == string('a'))
                    if(string(app.AdicionarEixoPanel.Visible) == string('on'))
                        atualizareixoeng(app)
                    end
                end
            end    
        end

        % Value changed function: pd_2
        function pd_2ValueChanged(app, event)
            atualizardropdown(app);
            if(length(app.chamada) > 0)
                if(string(app.chamada) == string('a'))
                    if(string(app.AdicionarEixoPanel.Visible) == string('on'))
                        atualizareixoeng(app)
                    end
                end
            end    
        end

        % Value changed function: SelecionarEixoDropDown
        function SelecionarEixoDropDownValueChanged(app, event)
            if(string(app.SelecionarEixoDropDown.Value) == string('Selecionar'))
                cla(app.UIAxes);
                cla(app.UIAxes_2);
                app.DimetrodoEixoEditField.Value = '';
                app.TorqueMximoEditField.Value = '';
                app.MomentoMximoEditField.Value = '';
                app.SelecionarElementoDropDown.Visible = 'off';
                app.SelecionarElementoDropDownLabel.Visible = 'off';
                app.FxEditField.Visible = 'off';
                app.FyEditField.Visible = 'off';
                app.FzEditField.Visible = 'off';
                app.FxEditField_2Label.Visible = 'off';
                app.FyEditFieldLabel.Visible = 'off';
                app.FzEditFieldLabel.Visible = 'off';
                app.FxEditField.Value = '';
                app.FyEditField.Value = '';
                app.FzEditField.Value = '';
            end
            if(not(string(app.SelecionarEixoDropDown.Value) == string('Selecionar')))
                nomes = [];
                for i=1:length(app.butte)
                    nomes = [nomes string(app.butte(i).Text)];
                end
                ind = find(nomes == string(app.SelecionarEixoDropDown.Value));
                app.exf=ind;
                
                if(app.sistema == 1)
                    uni1 = 'mm';
                    uni2 = 'Nm';
                elseif(app.sistema == 0)
                    uni1 = 'pol';
                    uni2 = 'lbf.pol';
                end
                app.DimetrodoEixoEditField.Value = [sprintf('%.2f ', app.de(ind)) uni1];
                app.TorqueMximoEditField.Value = [sprintf('%.1f ', app.Tmax(ind)) uni2];
                app.MomentoMximoEditField.Value = [sprintf('%.1f ',app.Mrmax(ind)) uni2];
                
                % Plotando os graficos de momento
                
                cla(app.UIAxes);
                cla(app.UIAxes_2);
                minplot = 0;
                maxplot = 0;
                for i=1:size(app.M{ind},2)
                    if(app.sistema == 1)
                        plot(app.UIAxes,1000*app.M{ind}{1,i},app.M{ind}{2,i},'k')
                    elseif(app.sistema == 0)
                        plot(app.UIAxes,app.M{ind}{1,i},app.M{ind}{2,i},'k')
                    end
                    hold(app.UIAxes, 'on')
                    if(i<size(app.M{ind},2))
                        if(app.M{ind}{2,i}(100)~=app.M{ind}{2,i+1}(1))
                            if(app.sistema == 1)
                                plot(app.UIAxes, [1000*app.M{ind}{1,i}(100),app.M{ind}{1,i+1}(1)],[1000*app.M{ind}{2,i}(100),app.M{ind}{2,i+1}(1)],'k')
                            elseif(app.sistema == 0)
                                plot(app.UIAxes, [app.M{ind}{1,i}(100),app.M{ind}{1,i+1}(1)],[app.M{ind}{2,i}(100),app.M{ind}{2,i+1}(1)],'k')
                            end
                        end
                    end
                    if(app.M{ind}{2,1}(1)~=0)
                        if(app.sistema == 1)
                            plot(app.UIAxes, [1000*app.M{ind}{1,1}(1),1000*app.M{ind}{1,1}(1)],[0,app.M{ind}{2,1}(1)],'k','LineWidth',2)
                        elseif(app.sistema == 0)
                            plot(app.UIAxes, [app.M{ind}{1,1}(1),app.M{ind}{1,1}(1)],[0,app.M{ind}{2,1}(1)],'k','LineWidth',2)
                        end
                    end
                    if(minplot>min(app.M{ind}{2,i}))
                        minplot = min(app.M{ind}{2,i});
                    end
                    if(maxplot<max(app.M{ind}{2,i}))
                        maxplot = max(app.M{ind}{2,i});
                    end
                end
                xlimmin=min([app.M{ind}{1,1}(1) app.M{ind}{1,size(app.M{ind},2)}(101)]);
                xlimmax=max([app.M{ind}{1,1}(1) app.M{ind}{1,size(app.M{ind},2)}(101)]);
                if(app.sistema == 1)
                    xlim(app.UIAxes, 1000*[xlimmin xlimmax])
                elseif(app.sistema == 0)
                    xlim(app.UIAxes, [xlimmin xlimmax])
                end
                
                if(minplot~=maxplot)
                    ylim(app.UIAxes, [minplot maxplot]);
                else
                    ylim(app.UIAxes, [-1 1]);
                end
                grid(app.UIAxes, 'on')
                xlabel(app.UIAxes, sprintf('Posição (%s)',uni1))
                ylabel(app.UIAxes, sprintf('Momento (%s)',uni2))
                if(app.dire{1}(3) == 3)
                    labelg1 = 'ZX';
                    labelg2 = 'ZY';
                elseif(app.dire{1}(3) == 1)
                    labelg1 = 'XY';
                    labelg2 = 'XZ';
                elseif(app.dire{1}(3) == 2)
                    labelg1 = 'YZ';
                    labelg2 = 'YX';
                end
                    
                title(app.UIAxes, ['Momento fletor no plano ' labelg1])
                
                minplot = 0;
                maxplot = 0;
                for i=1:size(app.M{ind},2)
                    if(app.sistema == 1)
                        plot(app.UIAxes_2, 1000*app.M{ind}{1,i},app.M{ind}{3,i},'k')
                    elseif(app.sistema == 0)
                        plot(app.UIAxes_2, app.M{ind}{1,i},app.M{ind}{3,i},'k')
                    end
                    hold(app.UIAxes_2, 'on')
                    if(i<size(app.M{ind},2))
                        if(app.M{ind}{3,i}(100)~=app.M{ind}{3,i+1}(1))
                            if(app.sistema == 1)
                                plot(app.UIAxes_2, [1000*app.M{ind}{1,i}(100),1000*app.M{ind}{1,i+1}(1)],[app.M{ind}{3,i}(100),app.M{ind}{3,i+1}(1)],'k')
                            elseif(app.sistema == 0)
                                plot(app.UIAxes_2, [app.M{ind}{1,i}(100),app.M{ind}{1,i+1}(1)],[app.M{ind}{3,i}(100),app.M{ind}{3,i+1}(1)],'k')
                            end
                        end
                    end
                    if(app.M{ind}{3,1}(1)~=0)
                        if(app.sistema == 1)
                            plot(app.UIAxes_2, [1000*app.M{ind}{1,1}(1),1000*app.M{ind}{1,1}(1)],[0,app.M{ind}{3,1}(1)],'k','LineWidth',2)
                        elseif(app.sistema == 0)
                            plot(app.UIAxes_2, [app.M{ind}{1,1}(1),app.M{ind}{1,1}(1)],[0,app.M{ind}{3,1}(1)],'k','LineWidth',2)
                        end
                    end
                    if(minplot>min(app.M{ind}{3,i}))
                        minplot = min(app.M{ind}{3,i});
                    end
                    if(maxplot<max(app.M{ind}{3,i}))
                        maxplot = max(app.M{ind}{3,i});
                    end
                end
                if(app.sistema == 1)
                    xlim(app.UIAxes_2, ([1000*xlimmin 1000*xlimmax]))
                elseif(app.sistema == 0)
                    xlim(app.UIAxes_2, ([xlimmin xlimmax]))
                end
                
                if(minplot~=maxplot)
                    ylim(app.UIAxes_2, [minplot maxplot]);
                else
                    ylim(app.UIAxes_2, [-1 1]);
                end
                grid(app.UIAxes_2, 'on')
                xlabel(app.UIAxes_2, sprintf('Posição (%s)',uni1))
                ylabel(app.UIAxes_2, sprintf('Momento (%s)',uni2))
                title(app.UIAxes_2, ['Momento fletor no plano ' labelg2])
                app.FxEditField.Visible = 'on';
                app.FyEditField.Visible = 'on';
                app.FzEditField.Visible = 'on';
                app.SelecionarElementoDropDown.Visible = 'on';
                app.SelecionarElementoDropDownLabel.Visible = 'on';
                app.FxEditField_2Label.Visible = 'on';
                app.FyEditFieldLabel.Visible = 'on';
                app.FzEditFieldLabel.Visible = 'on';
                refeng=[];
                for(ne=1:length(app.refe))
                    if(ind == app.refe{ne}(1) || ind == app.refe{ne}(3))
                        refeng(length(refeng)+1)=ne;
                    end
                end
                textdrop = {'Selecionar','Mancal 1','Mancal 2'};
                for i=1:length(refeng)
                    textdrop{length(textdrop)+1} = char(sprintf('Engrenagens %d',refeng(i)));
                end
                app.SelecionarElementoDropDown.Items = textdrop;
            end
            
        end

        % Value changed function: xm2
        function xm2ValueChanged(app, event)
            value = app.xm2.Value;
            mancaldiferente(app);
        end

        % Value changed function: zm2
        function zm2ValueChanged(app, event)
            value = app.zm2.Value;
            mancaldiferente(app);
        end

        % Value changed function: ym2
        function ym2ValueChanged(app, event)
            value = app.ym2.Value;
            mancaldiferente(app);
        end

        % Value changed function: pot1
        function pot1ValueChanged(app, event)
            value = app.pot1.Value;
            liberareng(app);
        end

        % Value changed function: pot_2
        function pot_2ValueChanged(app, event)
            value = app.pot_2.Value;
            liberareng(app);
        end

        % Value changed function: xeng_4
        function xeng_4ValueChanged(app, event)
            value = app.xeng_4.Value;
            mancaldiferente2(app);
        end

        % Value changed function: yeng_4
        function yeng_4ValueChanged(app, event)
            value = app.yeng_4.Value;
            mancaldiferente2(app)
        end

        % Value changed function: zeng_4
        function zeng_4ValueChanged(app, event)
            value = app.zeng_4.Value;
            mancaldiferente2(app)
        end

        % Value changed function: xeng_5
        function xeng_5ValueChanged(app, event)
            value = app.xeng_5.Value;
            mancaldiferente2(app)
        end

        % Value changed function: yeng_5
        function yeng_5ValueChanged(app, event)
            value = app.yeng_5.Value;
            mancaldiferente2(app)
        end

        % Value changed function: zeng_5
        function zeng_5ValueChanged(app, event)
            value = app.zeng_5.Value;
            mancaldiferente2(app)
        end

        % Value changed function: cone1
        function cone1ValueChanged(app, event)
            value = app.cone1.Value;
            app.cone1.Value = 1;
            app.cone2.Value = 0;
            atualizardropdown(app);
            if(length(app.chamada) > 0)
                if(string(app.chamada) == string('a'))
                    if(string(app.AdicionarEixoPanel.Visible) == string('on'))
                        atualizareixoeng(app)
                    end
                end
            end  
        end

        % Value changed function: cone2
        function cone2ValueChanged(app, event)
            value = app.cone2.Value;
            app.cone2.Value = 1;
            app.cone1.Value = 0;
            atualizardropdown(app);
            if(length(app.chamada) > 0)
                if(string(app.chamada) == string('a'))
                    if(string(app.AdicionarEixoPanel.Visible) == string('on'))
                        atualizareixoeng(app)
                    end
                end
            end  
        end

        % Value changed function: nd1_3
        function nd1_3ValueChanged(app, event)
            value = app.nd1_3.Value;
            atualizardropdown(app);
            if(length(app.chamada) > 0)
                if(string(app.chamada) == string('a'))
                    if(string(app.AdicionarEixoPanel.Visible) == string('on'))
                        atualizareixoeng(app)
                    end
                end
            end  
        end

        % Value changed function: pd_3
        function pd_3ValueChanged(app, event)
            value = app.pd_3.Value;
            atualizardropdown(app);
            if(length(app.chamada) > 0)
                if(string(app.chamada) == string('a'))
                    if(string(app.AdicionarEixoPanel.Visible) == string('on'))
                        atualizareixoeng(app)
                    end
                end
            end  
        end

        % Value changed function: xeng_6
        function xeng_6ValueChanged(app, event)
            value = app.xeng_6.Value;
            atualizardropdown(app);
            if(length(app.chamada) > 0)
                if(string(app.chamada) == string('a'))
                    if(string(app.AdicionarEixoPanel.Visible) == string('on'))
                        atualizareixoeng(app)
                    end
                end
            end  
        end

        % Value changed function: yeng_6
        function yeng_6ValueChanged(app, event)
            value = app.yeng_6.Value;
            atualizardropdown(app);
            if(length(app.chamada) > 0)
                if(string(app.chamada) == string('a'))
                    if(string(app.AdicionarEixoPanel.Visible) == string('on'))
                        atualizareixoeng(app)
                    end
                end
            end  
        end

        % Value changed function: zeng_6
        function zeng_6ValueChanged(app, event)
            value = app.zeng_6.Value;
            atualizardropdown(app);
            if(length(app.chamada) > 0)
                if(string(app.chamada) == string('a'))
                    if(string(app.AdicionarEixoPanel.Visible) == string('on'))
                        atualizareixoeng(app)
                    end
                end
            end  
        end

        % Value changed function: lff
        function lffValueChanged(app, event)
            value = app.lff.Value;
            atualizardropdown(app);
            if(length(app.chamada) > 0)
                if(string(app.chamada) == string('a'))
                    if(string(app.AdicionarEixoPanel.Visible) == string('on'))
                        atualizareixoeng(app)
                    end
                end
            end  
        end

        % Value changed function: ar2
        function ar2ValueChanged(app, event)
            value = app.ar2.Value;
            atualizardropdown(app);
            if(length(app.chamada) > 0)
                if(string(app.chamada) == string('a'))
                    if(string(app.AdicionarEixoPanel.Visible) == string('on'))
                        atualizareixoeng(app)
                    end
                end
            end  
        end

        % Value changed function: pot_3
        function pot_3ValueChanged(app, event)
            value = app.pot_3.Value;
            liberareng(app);
        end

        % Button pushed function: SistemaInternacionalButton
        function SistemaInternacionalButtonPushed(app, event)
            app.NovoProjetoLabel.Visible = 'off';
            app.SistemaInternacionalButton.Visible = 'off';
            app.SistemaInglsButton.Visible = 'off';
            app.EngrenagensButton.Visible = 'on';
            app.EixosButton.Visible = 'on';
            app.ResultadoButton.Visible = 'on';
            app.RelatrioButton.Visible = 'on';
            app.sistema = 1;
            app.Passodiametral1inLabel_3.Text = 'Módulo (mm):';
            app.Passodiametral1inLabel_3.HorizontalAlignment = 'left';
            app.Passodiametral1inLabel_2.Text = 'Módulo (mm):';
            app.Passodiametral1inLabel_2.HorizontalAlignment = 'left';
            app.Passodiametral1inLabel.Text = 'Módulo (mm):';
            app.Passodiametral1inLabel.HorizontalAlignment = 'left';
            app.LarguradefaceinLabel.Text = 'Larg. de face (mm)';
            app.LarguradefaceinLabel.HorizontalAlignment = 'left';
            app.PotnciacvLabel.Text = "Potência (kW):";
            app.PotnciacvLabel_2.Text = "Potência (kW):";
            app.PotnciacvLabel_3.Text = "Potência (kW):";
            app.PotnciacvLabel_4.Text = "Potência (kW):";
            app.Passodiametral1inLabel_4.Text = "Módulo (mm):";
            app.ngulorelativoLabel.Visible = 'off';
            app.TempdefuncFLabel.Text = 'Temp. de func. (°C):';
            app.LarguradefaceinLabel_2.Text = 'Larg. de face (mm):';
            app.EdacoroapsiLabel.Text = 'E da coroa (Pa):';
            app.EdopinhopsiEditFieldLabel.Text = 'E do pinhão (Pa):';
            app.ScadacoroapsiEditFieldLabel.Text = 'Sca da coroa (Pa):';
            app.ScadopinhopsiEditFieldLabel.Text = 'Sca do pinhão (Pa):';
            app.StadacoroapsiEditFieldLabel.Text = 'Sta da coroa (Pa):';
            app.StadopinhopsiEditFieldLabel.Text = 'Sta do pinhão (Pa):';
            app.TempdefuncFLabel_2.Text = 'Temp. de func. (°C):';
            app.LarguradefaceEditField.Value = app.LarguradefaceEditField.Value*25.4;
            app.EdacoroapsiEditField.Value = app.EdacoroapsiEditField.Value*6894.76;
            app.EdopinhopsiEditField.Value = app.EdopinhopsiEditField.Value*6894.76;
            app.ScadacoroapsiEditField.Value = app.ScadacoroapsiEditField.Value*6894.76;
            app.StadacoroapsiEditField.Value = app.StadacoroapsiEditField.Value*6894.76;
            app.StadopinhopsiEditField.Value = app.StadopinhopsiEditField.Value*6894.76;
            app.ScadopinhopsiEditField.Value = app.ScadopinhopsiEditField.Value*6894.76;
            app.sy.Value=app.sy.Value*6894.76;
            app.sy2.Value=app.sy2.Value*6894.76;
        end

        % Button pushed function: SistemaInglsButton
        function SistemaInglsButtonPushed(app, event)
            app.NovoProjetoLabel.Visible = 'off';
            app.SistemaInternacionalButton.Visible = 'off';
            app.SistemaInglsButton.Visible = 'off';
            app.EngrenagensButton.Visible = 'on';
            app.RelatrioButton.Visible = 'on';
            app.EixosButton.Visible = 'on';
            app.ResultadoButton.Visible = 'on';
            app.sistema = 0;
            app.ngulorelativoLabel.Visible = 'off';
            app.SutMPaLabel.Text = 'Sut (ksi):';
            app.Sutf.Value = 72.52;
        end

        % Value changed function: hor_3
        function hor_3ValueChanged(app, event)
            value = app.hor_3.Value;
            app.hor_3.Value = 1;
            app.ahor_3.Value = 0;
        end

        % Value changed function: ahor_3
        function ahor_3ValueChanged(app, event)
            value = app.ahor_3.Value;
            app.ahor_3.Value = 1;
            app.hor_3.Value = 0;
        end

        % Value changed function: hor_2
        function hor_2ValueChanged(app, event)
            value = app.hor_2.Value;
            app.hor_2.Value = 1;
            app.ahor_2.Value = 0;
        end

        % Value changed function: ahor_2
        function ahor_2ValueChanged(app, event)
            value = app.ahor_2.Value;
            app.ahor_2.Value = 1;
            app.hor_2.Value = 0;
        end

        % Value changed function: hor
        function horValueChanged(app, event)
            value = app.hor.Value;
            app.hor.Value = 1;
            app.ahor.Value = 0;
        end

        % Value changed function: ahor
        function ahorValueChanged(app, event)
            value = app.ahor.Value;
            app.ahor.Value = 1;
            app.hor.Value = 0;
        end

        % Value changed function: AtritoaltoCheckBox
        function AtritoaltoCheckBoxValueChanged(app, event)
            value = app.AtritoaltoCheckBox.Value;
            app.AtritoaltoCheckBox.Value = 1;
            app.AtritobaixoCheckBox.Value = 0;
        end

        % Value changed function: AtritobaixoCheckBox
        function AtritobaixoCheckBoxValueChanged(app, event)
            value = app.AtritobaixoCheckBox.Value;
            app.AtritoaltoCheckBox.Value = 0;
            app.AtritobaixoCheckBox.Value = 1;
        end

        % Value changed function: ahor_4
        function ahor_4ValueChanged(app, event)
            value = app.ahor_4.Value;
            app.ahor_4.Value = 1;
            app.hor_4.Value = 0;
        end

        % Value changed function: hor_4
        function hor_4ValueChanged(app, event)
            value = app.hor_4.Value;
            app.ahor_4.Value = 0;
            app.hor_4.Value = 1;
        end

        % Value changed function: pot_4
        function pot_4ValueChanged(app, event)
            value = app.pot_4.Value;
            liberareng(app)
        end

        % Value changed function: nd2_2
        function nd2_2ValueChanged(app, event)
            atualizardropdown(app);
            if(length(app.chamada) > 0)
                if(string(app.chamada) == string('a'))
                    if(string(app.AdicionarEixoPanel.Visible) == string('on'))
                        atualizareixoeng(app)
                    end
                end
            end  
        end

        % Value changed function: ar2_2
        function ar2_2ValueChanged(app, event)
            atualizardropdown(app);
            if(length(app.chamada) > 0)
                if(string(app.chamada) == string('a'))
                    if(string(app.AdicionarEixoPanel.Visible) == string('on'))
                        atualizareixoeng(app)
                    end
                end
            end  
        end

        % Value changed function: pd_5
        function pd_5ValueChanged(app, event)
            atualizardropdown(app);
            if(length(app.chamada) > 0)
                if(string(app.chamada) == string('a'))
                    if(string(app.AdicionarEixoPanel.Visible) == string('on'))
                        atualizareixoeng(app)
                    end
                end
            end  
        end

        % Value changed function: dp_2
        function dp_2ValueChanged(app, event)
            atualizardropdown(app);
            if(length(app.chamada) > 0)
                if(string(app.chamada) == string('a'))
                    if(string(app.AdicionarEixoPanel.Visible) == string('on'))
                        atualizareixoeng(app)
                    end
                end
            end  
        end

        % Value changed function: xeng_7
        function xeng_7ValueChanged(app, event)
            value = app.xeng_7.Value;
            if(not(app.xCheckBox_2.Value == 1))
                app.xeng_5.Value = value;
                app.xeng_4.Value = value;
            end
        end

        % Value changed function: yeng_7
        function yeng_7ValueChanged(app, event)
            value = app.yeng_7.Value;
            if(not(app.yCheckBox_2.Value == 1))
                app.yeng_5.Value = value;
                app.yeng_4.Value = value;
            end
        end

        % Value changed function: zeng_7
        function zeng_7ValueChanged(app, event)
            value = app.zeng_7.Value;
            if(not(app.zCheckBox_2.Value == 1))
                app.zeng_5.Value = value;
                app.zeng_4.Value = value;
            end
        end

        % Value changed function: SelecionarElementoDropDown
        function SelecionarElementoDropDownValueChanged(app, event)
            value = app.SelecionarElementoDropDown.Value;
            e=app.exf;
            if(not(string(value) == string('Selecionar')))
                if(string(value) == string('Mancal 1'))
                    if(app.sistema == 1)
                        app.FxEditField.Value = sprintf('%.3f kN',app.fm{(e-1)*2+1}(1)/1000);
                        app.FyEditField.Value = sprintf('%.3f kN',app.fm{(e-1)*2+1}(2)/1000);
                        app.FzEditField.Value = sprintf('%.3f kN',app.fm{(e-1)*2+1}(3)/1000);
                    elseif(app.sistema == 0)
                        app.FxEditField.Value = sprintf('%.2f lbf',app.fm{(e-1)*2+1}(1));
                        app.FyEditField.Value = sprintf('%.2f lbf',app.fm{(e-1)*2+1}(2));
                        app.FzEditField.Value = sprintf('%.2f lbf',app.fm{(e-1)*2+1}(3));
                    end
                elseif(string(value) == string('Mancal 2'))
                    if(app.sistema == 1)
                        app.FxEditField.Value = sprintf('%.3f kN',app.fm{(e-1)*2+2}(1)/1000);
                        app.FyEditField.Value = sprintf('%.3f kN',app.fm{(e-1)*2+2}(2)/1000);
                        app.FzEditField.Value = sprintf('%.3f kN',app.fm{(e-1)*2+2}(3)/1000);
                    elseif(app.sistema == 0)
                        app.FxEditField.Value = sprintf('%.2f lbf',app.fm{(e-1)*2+2}(1));
                        app.FyEditField.Value = sprintf('%.2f lbf',app.fm{(e-1)*2+2}(2));
                        app.FzEditField.Value = sprintf('%.2f lbf',app.fm{(e-1)*2+2}(3));
                    end
                else
                    nomes = [];
                    for i=1:length(app.butt)
                        nomes = [nomes string(app.butt(i).Text)];
                    end
                    ind = find(nomes == string(sprintf('%s ',value)));
                    if(e == app.refe{ind}(1))
                        engs=app.refe{ind}(2);
                    elseif(e == app.refe{ind}(3))
                        engs=app.refe{ind}(4);
                    end
                    if(app.sistema == 1)
                        app.FxEditField.Value = sprintf('%.3f kN',app.f{e}{engs}(1)/1000);
                        app.FyEditField.Value = sprintf('%.3f kN',app.f{e}{engs}(2)/1000);
                        app.FzEditField.Value = sprintf('%.3f kN',app.f{e}{engs}(3)/1000);
                    elseif(app.sistema == 0)
                        app.FxEditField.Value = sprintf('%.2f lbf',app.f{e}{engs}(1));
                        app.FyEditField.Value = sprintf('%.2f lbf',app.f{e}{engs}(2));
                        app.FzEditField.Value = sprintf('%.2f lbf',app.f{e}{engs}(3));
                    end
                end

            end
                        
                
        end

        % Button pushed function: AnliseDinmicaButton
        function AnliseDinmicaButtonPushed(app, event)
            app.PanelResultado.Visible = 'off';
            app.AnaliseDinamica.Visible = 'on';
            app.EngrenagensdeDentesRetosPanel.Visible = 'off';
            
            if(length(app.nomee) == 0)
                app.SelecionarEixoDropDown.Items = {'Selecionar'};
            else
                textdrop = {'Selecionar'};
                for i=1:length(app.nomee)
                    textdrop{length(textdrop)+1} = char(app.nomee(i));
                end
                for i=1:length(app.nomeex)
                    textdrop{length(textdrop)+1} = char(app.nomeex(i));
                end
                app.ElementosDropDown.Items = textdrop;
            end
            
            app.ElementosDropDown.Value = 'Selecionar';
            
        end

        % Value changed function: ElementosDropDown
        function ElementosDropDownValueChanged(app, event)
            value = app.ElementosDropDown.Value;
            veng=[];
            veng2=[];
            if(not(string(value) == "Selecionar"))
                for(i=1:length(app.nomee))
                    if(value == string(app.nomee(i)))
                        veng=i;
                        break
                    end
                end
                for(i=1:length(app.nomeex))
                    if(value == string(app.nomeex(i)))
                        veng2=i;
                        break
                    end
                end
                if(length(veng)>0)
                    vex=app.refe{veng}(1);
                    vpeg=app.refe{veng}(2);
                    if(string(app.tipoe{vex}(vpeg)) == "Dentes Retos")
                        app.EngrenagensdeDentesRetosPanel.Visible = 'on';
                        app.EixosPanel.Visible='off';
                        app.EngrenagensdeDentesRetosPanel.Title = 'Engrenagens de Dentes Retos';
                    end
                    if(string(app.tipoe{vex}(vpeg)) == "Helicoidal")
                        app.EngrenagensdeDentesRetosPanel.Visible = 'on';
                        app.EngrenagensdeDentesRetosPanel.Title = 'Engrenagens de Dentes Helicoidais';
                    end
                end
                if(length(veng2)>0)
                    app.EixosPanel.Visible='on';
                    app.EngrenagensdeDentesRetosPanel.Visible = 'off';
                end
            else
                app.EngrenagensdeDentesRetosPanel.Visible = 'off';
            end
        end

        % Value changed function: MaterialdacoroaDropDown
        function MaterialdacoroaDropDownValueChanged(app, event)
            value = app.MaterialdacoroaDropDown.Value;
            app.TrattrmicocoroaDropDown.Enable = 'on';
            app.EdacoroapsiEditField.Enable = 'off';
            app.GraudacoroaDropDown.Enable = 'on';
            if(string(value)==string(app.MaterialdacoroaDropDown.Items{1}))
                app.TrattrmicocoroaDropDown.Items = {''};
                app.TrattrmicocoroaDropDown.Items = {'Endurecido por completo' 'Endurecido por chama ou indução' 'Carbonetado e endurecido' 'Nitretado (aços endurecidos por completo)' 'Outro'};
            elseif(string(value)==string(app.MaterialdacoroaDropDown.Items{2}) || string(value)==string(app.MaterialdacoroaDropDown.Items{3}) || string(value)==string(app.MaterialdacoroaDropDown.Items{4}))
                app.TrattrmicocoroaDropDown.Items = {''};
                app.TrattrmicocoroaDropDown.Items = {'Nitretado (aços endurecidos por completo)' 'Outro'};
            elseif(string(value)==string(app.MaterialdacoroaDropDown.Items{5}))
                app.TrattrmicocoroaDropDown.Items = {''};
                app.TrattrmicocoroaDropDown.Items = {'Recozido' 'Outro'};
            elseif(string(value)==string(app.MaterialdacoroaDropDown.Items{6}) || string(value)==string(app.MaterialdacoroaDropDown.Items{7}))
                app.TrattrmicocoroaDropDown.Items = {''};
                app.TrattrmicocoroaDropDown.Items = {'Temperado e revenido' 'Outro'};
            elseif(string(value)==string(app.MaterialdacoroaDropDown.Items{8}) || string(value)==string(app.MaterialdacoroaDropDown.Items{9}) || string(value)==string(app.MaterialdacoroaDropDown.Items{10}))
                app.TrattrmicocoroaDropDown.Items = {''};
                app.TrattrmicocoroaDropDown.Items = {'Como fundido' 'Outro'};
            elseif(string(value)==string(app.MaterialdacoroaDropDown.Items{11}))
                app.TrattrmicocoroaDropDown.Items = {''};
                app.TrattrmicocoroaDropDown.Items = {'Fundido em areia' 'Outro'};
            elseif(string(value)==string(app.MaterialdacoroaDropDown.Items{12}))
                app.TrattrmicocoroaDropDown.Items = {''};
                app.TrattrmicocoroaDropDown.Items = {'Tratado termicamente' 'Outro'};
            elseif(string(value)==string(app.MaterialdacoroaDropDown.Items{13}))
                app.TrattrmicocoroaDropDown.Items = {'Outro'};
                app.TrattrmicocoroaDropDown.Enable = 'off';
                app.EdacoroapsiEditField.Enable = 'on';
                app.ScadacoroapsiEditField.Enable = 'on';
                app.StadacoroapsiEditField.Enable = 'on';
            end
            if(not(string(app.TrattrmicocoroaDropDown.Value) == "Outro"))
                app.ScadacoroapsiEditField.Enable = 'off';
                app.StadacoroapsiEditField.Enable = 'off';
            end
            value2 = string(app.TrattrmicocoroaDropDown.Value);
            value1 = string(app.MaterialdacoroaDropDown.Value);
            item2 = app.TrattrmicocoroaDropDown.Items;
            item1 = app.MaterialdacoroaDropDown.Items;
            app.ScadacoroapsiEditField.Enable = 'off';
            app.StadacoroapsiEditField.Enable = 'off';
            app.GraudacoroaDropDown.Items = {''};
            if(string(value2)=="Outro")
                app.ScadacoroapsiEditField.Enable = 'on';
                app.StadacoroapsiEditField.Enable = 'on';
                app.GraudacoroaDropDown.Enable = 'off';
            else
                app.GraudacoroaDropDown.Enable = 'on';
                if(value1==string(item1{1}))
                    if(value2==string(item2{1}) || value2==string(item2{2}) || value2==string(item2{3})) 
                        app.GraudacoroaDropDown.Items = {'1' '2'};
                    elseif(value2==string(item2{4}) || value2==string(item2{5}))
                        app.GraudacoroaDropDown.Items = {'1' '2' '3'};
                    else
                        app.GraudacoroaDropDown.Enable = 'off';
                    end
                elseif(value1==string(item1{2}) || value1==string(item1{3}) || value1==string(item1{4}))
                    if(value2==string(item2{1}))
                        app.GraudacoroaDropDown.Items = {'1' '2' '3'};
                    else
                        app.GraudacoroaDropDown.Enable = 'off';
                    end
                else
                    app.GraudacoroaDropDown.Enable = 'off';
                end     
            end
            mudarscc(app);
            mudarec(app);
        end

        % Value changed function: MaterialdopinhoDropDown
        function MaterialdopinhoDropDownValueChanged(app, event)
            value = app.MaterialdopinhoDropDown.Value;
            app.TrattrmicopinhoDropDown.Enable = 'on';
            app.EdopinhopsiEditField.Enable = 'off';
            app.GraudopinhoDropDown.Enable = 'on';
            if(string(value)==string(app.MaterialdopinhoDropDown.Items{1}))
                app.TrattrmicopinhoDropDown.Items = {''};
                app.TrattrmicopinhoDropDown.Items = {'Endurecido por completo' 'Endurecido por chama ou indução' 'Carbonetado e endurecido' 'Nitretado (aços endurecidos por completo)' 'Outro'};
            elseif(string(value)==string(app.MaterialdopinhoDropDown.Items{2}) || string(value)==string(app.MaterialdopinhoDropDown.Items{3}) || string(value)==string(app.MaterialdopinhoDropDown.Items{4}))
                app.TrattrmicopinhoDropDown.Items = {''};
                app.TrattrmicopinhoDropDown.Items = {'Nitretado (aços endurecidos por completo)' 'Outro'};
            elseif(string(value)==string(app.MaterialdopinhoDropDown.Items{5}))
                app.TrattrmicopinhoDropDown.Items = {''};
                app.TrattrmicopinhoDropDown.Items = {'Recozido' 'Outro'};
            elseif(string(value)==string(app.MaterialdopinhoDropDown.Items{6}) || string(value)==string(app.MaterialdopinhoDropDown.Items{7}))
                app.TrattrmicopinhoDropDown.Items = {''};
                app.TrattrmicopinhoDropDown.Items = {'Temperado e revenido' 'Outro'};
            elseif(string(value)==string(app.MaterialdopinhoDropDown.Items{8}) || string(value)==string(app.MaterialdopinhoDropDown.Items{9}) || string(value)==string(app.MaterialdopinhoDropDown.Items{10}))
                app.TrattrmicopinhoDropDown.Items = {''};
                app.TrattrmicopinhoDropDown.Items = {'Como fundido' 'Outro'};
            elseif(string(value)==string(app.MaterialdopinhoDropDown.Items{11}))
                app.TrattrmicopinhoDropDown.Items = {''};
                app.TrattrmicopinhoDropDown.Items = {'Fundido em areia' 'Outro'};
            elseif(string(value)==string(app.MaterialdopinhoDropDown.Items{12}))
                app.TrattrmicopinhoDropDown.Items = {''};
                app.TrattrmicopinhoDropDown.Items = {'Tratado termicamente' 'Outro'};
            elseif(string(value)==string(app.MaterialdopinhoDropDown.Items{13}))
                app.TrattrmicopinhoDropDown.Items = {'Outro'};
                app.TrattrmicopinhoDropDown.Enable = 'off';
                app.EdopinhopsiEditField.Enable = 'on';
                app.ScadopinhopsiEditField.Enable = 'on';
                app.StadopinhopsiEditField.Enable = 'on';
            end
            if(not(string(app.TrattrmicopinhoDropDown.Value) == "Outro"))
                app.ScadopinhopsiEditField.Enable = 'off';
                app.StadopinhopsiEditField.Enable = 'off';
            end
            value2 = string(app.TrattrmicopinhoDropDown.Value);
            value1 = string(app.MaterialdopinhoDropDown.Value);
            item2 = app.TrattrmicopinhoDropDown.Items;
            item1 = app.MaterialdopinhoDropDown.Items;
            app.ScadopinhopsiEditField.Enable = 'off';
            app.StadopinhopsiEditField.Enable = 'off';
            app.GraudopinhoDropDown.Items = {''};
            if(string(value2)=="Outro")
                app.ScadopinhopsiEditField.Enable = 'on';
                app.StadopinhopsiEditField.Enable = 'on';
                app.GraudopinhoDropDown.Enable = 'off';
            else
                app.GraudopinhoDropDown.Enable = 'on';
                if(value1==string(item1{1}))
                    if(value2==string(item2{1}) || value2==string(item2{2}) || value2==string(item2{3})) 
                        app.GraudopinhoDropDown.Items = {'1' '2'};
                    elseif(value2==string(item2{4}) || value2==string(item2{5}))
                        app.GraudopinhoDropDown.Items = {'1' '2' '3'};
                    else
                        app.GraudopinhoDropDown.Enable = 'off';
                    end
                elseif(value1==string(item1{2}) || value1==string(item1{3}) || value1==string(item1{4}))
                    if(value2==string(item2{1}))
                        app.GraudopinhoDropDown.Items = {'1' '2' '3'};
                    else
                        app.GraudopinhoDropDown.Enable = 'off';
                    end
                else
                    app.GraudopinhoDropDown.Enable = 'off';
                end     
            end
            mudarscp(app);
            mudarep(app);
        end

        % Value changed function: TrattrmicocoroaDropDown
        function TrattrmicocoroaDropDownValueChanged(app, event)
            value2 = string(app.TrattrmicocoroaDropDown.Value);
            value1 = string(app.MaterialdacoroaDropDown.Value);
            item2 = app.TrattrmicocoroaDropDown.Items;
            item1 = app.MaterialdacoroaDropDown.Items;
            app.ScadacoroapsiEditField.Enable = 'off';
            app.StadacoroapsiEditField.Enable = 'off';
            app.GraudacoroaDropDown.Items = {''};
            if(string(value2)=="Outro")
                app.ScadacoroapsiEditField.Enable = 'on';
                app.StadacoroapsiEditField.Enable = 'on';
                app.GraudacoroaDropDown.Enable = 'off';
            else
                app.GraudacoroaDropDown.Enable = 'on';
                if(value1==string(item1{1}))
                    if(value2==string(item2{1}) || value2==string(item2{2})) 
                        app.GraudacoroaDropDown.Items = {'1' '2'};
                    elseif(value2==string(item2{3}) || value2==string(item2{4}))
                        app.GraudacoroaDropDown.Items = {'1' '2' '3'};
                    else
                        app.GraudacoroaDropDown.Enable = 'off';
                    end
                elseif(value1==string(item1{2}) || value1==string(item1{3}) || value1==string(item1{4}))
                    if(value2==string(item2{1}))
                        app.GraudacoroaDropDown.Items = {'1' '2' '3'};
                    else
                        app.GraudacoroaDropDown.Enable = 'off';
                    end
                else
                    app.GraudacoroaDropDown.Enable = 'off';
                end     
            end
            mudarscc(app);
        end

        % Value changed function: TrattrmicopinhoDropDown
        function TrattrmicopinhoDropDownValueChanged(app, event)
            value2 = string(app.TrattrmicopinhoDropDown.Value);
            value1 = string(app.MaterialdopinhoDropDown.Value);
            item2 = app.TrattrmicopinhoDropDown.Items;
            item1 = app.MaterialdopinhoDropDown.Items;
            app.ScadopinhopsiEditField.Enable = 'off';
            app.StadopinhopsiEditField.Enable = 'off';
            app.GraudopinhoDropDown.Items = {''};
            if(string(value2)=="Outro")
                app.ScadopinhopsiEditField.Enable = 'on';
                app.StadopinhopsiEditField.Enable = 'on';
                app.GraudopinhoDropDown.Enable = 'off';
            else
                app.GraudopinhoDropDown.Enable = 'on';
                if(value1==string(item1{1}))
                    if(value2==string(item2{1}) || value2==string(item2{2})) 
                        app.GraudopinhoDropDown.Items = {'1' '2'};
                    elseif(value2==string(item2{3}) || value2==string(item2{4}))
                        app.GraudopinhoDropDown.Items = {'1' '2' '3'};
                    else
                        app.GraudopinhoDropDown.Enable = 'off';
                    end
                elseif(value1==string(item1{2}) || value1==string(item1{3}) || value1==string(item1{4}))
                    if(value2==string(item2{1}))
                        app.GraudopinhoDropDown.Items = {'1' '2' '3'};
                    else
                        app.GraudopinhoDropDown.Enable = 'off';
                    end
                else
                    app.GraudopinhoDropDown.Enable = 'off';
                end     
            end
            mudarscp(app);
        end

        % Value changed function: CaractdoaroDropDown
        function CaractdoaroDropDownValueChanged(app, event)
            value = app.CaractdoaroDropDown.Value;
            app.MbEditField.Enable = 'off';
            if(string(value)==string(app.CaractdoaroDropDown.Items{2}))
                app.MbEditField.Enable = 'on';
                app.MbEditField.Value = 1;
            else
                app.MbEditField.Value = 1.2;
            end
        end

        % Button pushed function: AdicionarDadosButton
        function AdicionarDadosButtonPushed(app, event)
            adicionardinamica(app);
        end

        % Value changed function: GraudacoroaDropDown
        function GraudacoroaDropDownValueChanged(app, event)
            mudarscc(app);
        end

        % Value changed function: GraudopinhoDropDown
        function GraudopinhoDropDownValueChanged(app, event)
            mudarscp(app);
        end

        % Button pushed function: AtualizarButton
        function AtualizarButtonPushed(app, event)
            
        end

        % Value changed function: DurezacEditField
        function DurezacEditFieldValueChanged(app, event)
            mudarscc(app);
            mudarstc(app);
        end

        % Value changed function: DurezapEditField
        function DurezapEditFieldValueChanged(app, event)
            mudarstp(app);
            mudarstp(app);
        end

        % Button pushed function: RelatrioButton
        function RelatrioButtonPushed(app, event)
            if(app.sistema == 0)
                unid=' lbf';
                unid2=' in';
                unid3=' lbf.in';
            else
                unid=' N';
                unid2=' mm';
                unid3=' N.m';
            end
            fileID = fopen('relatorio.txt','w');
            fprintf(fileID,'=======================================================\n');
            fprintf(fileID,'                        RELATÓRIO                      \n');
            fprintf(fileID,'=======================================================\n\n');
            if(length(app.nomeex))==0
                fprintf(fileID,'Nenhum elemento foi declarado\n\n');
            else
                fprintf(fileID,'ANÁLISE ESTÁTICA:\n\n');
                if(length(app.nomeex)>0)
                    for i=1:length(app.nomeex)
                        fprintf(fileID,'======= %s =======\n\n',app.nomeex(i));
                        if(length(app.de)<i || (length(app.de)>=i && app.de(i)==0))
                            fprintf(fileID,'Nenhum elemento declarado no %s \n\n', app.nomeex(i));
                        end
                        if(length(app.de)>=i)
                            if(app.de(i)>0)
                                fprintf(fileID,'Diâmetro mínimo: %.2f%s\n',app.de(i),unid2);
                                fprintf(fileID,'Torque máximo: %.2f%s\n',app.Tmax(i),unid3);
                                fprintf(fileID,'Momento máximo: %.2f%s\n\n',app.Mrmax(i),unid3);
                                
                                for j=(i-1)*2+1:(i-1)*2+2
                                    fprintf(fileID,'Reação no Mancal %d:\n\n',j-(i-1)*2);
                                    fprintf(fileID,'Fx = %.3f%s\n',app.fm{j}(1),unid);
                                    fprintf(fileID,'Fy = %.3f%s\n',app.fm{j}(2),unid);
                                    fprintf(fileID,'Fz = %.3f%s\n\n',app.fm{j}(3),unid);
                                end
                                for j=1:length(app.f{i})
                                    for k=1:length(app.refe)
                                        if((i==app.refe{k}(1) && j==app.refe{k}(2)) || (i==app.refe{k}(3) && j==app.refe{k}(4)))
                                            ref=k;
                                            break
                                        end
                                    end
                                    fprintf(fileID,'Forças nas %s:\n\n',app.nomee(ref));
                                    fprintf(fileID,'Fx = %.3f%s\n',app.f{i}{j}(1),unid);
                                    fprintf(fileID,'Fy = %.3f%s\n',app.f{i}{j}(2),unid);
                                    fprintf(fileID,'Fz = %.3f%s\n\n',app.f{i}{j}(3),unid);
                                end
                                
                            end
                        end  
                    end
                end
                fprintf(fileID,'===================================\n\n');
                fprintf(fileID,'ANÁLISE DINÂMICA:\n\n');
                if(isempty(app.dinamica) && isempty(app.dinamicae))
                    fprintf(fileID,'Nenhuma análise dinâmica foi executada\n\n');
                else
                    dina=[];
                    dina2=[];
                    if(length(app.dinamica)>0)
                        for i=1:length(app.dinamica)
                            if(app.dinamica(i)==1)
                                dina(length(dina)+1)=i;
                            end
                        end
                    end
                    if(length(app.dinamicae)>0)
                        for i=1:length(app.dinamicae)
                            if(app.dinamicae(i)==1)
                                dina2(length(dina2)+1)=i;
                            end
                        end
                    end
                    for(i=1:length(app.nomee))
                        fprintf(fileID,'======= %s =======\n\n',app.nomee(i));
                        if(not(ismember(i,dina)))
                            fprintf(fileID,'A análise dinâmica não foi realizada nesse par de engrenagens\n\n');
                        else
                            if(string(app.tipoe{app.refe{i}(1)}(app.refe{i}(2))) == "Dentes Retos" || string(app.tipoe{app.refe{i}(1)}(app.refe{i}(2))) == "Helicoidal")
                                fprintf(fileID,'Coeficientes de segurança do pinhão\n\n');
                                fprintf(fileID,'Contra fadiga: %.2f\n', app.Sfp(i));
                                fprintf(fileID,'Contra escoamento: %.2f\n', app.Shp(i));
                                if(app.Sfp(i)>(app.Shp(i))^2)
                                    fprintf(fileID,'Falha no pinhão proveniente de ESCOAMENTO\n\n');
                                else
                                    fprintf(fileID,'Falha no pinhão proveniente de FADIGA\n\n');
                                end
                                fprintf(fileID,'Coeficientes de segurança da coroa\n\n');
                                fprintf(fileID,'Contra fadiga: %.2f\n', app.Sfg(i));
                                fprintf(fileID,'Contra escoamento: %.2f\n', app.Shg(i));
                                if(app.Sfg(i)>(app.Shg(i))^2)
                                    fprintf(fileID,'Falha na coroa proveniente de ESCOAMENTO\n\n');
                                else
                                    fprintf(fileID,'Falha na coroa proveniente de FADIGA\n\n');
                                end
                            end
                        end
                    end
                    for(i=1:length(app.nomeex))
                        fprintf(fileID,'======= %s =======\n\n',app.nomeex(i));
                        if(not(ismember(i,dina2)))
                            fprintf(fileID,'A análise dinâmica não foi realizada nesse eixo\n\n');
                        else
                            if(app.sistema==1)
                                fprintf(fileID,'Diâmetro mínimo: %.2f%s\n',app.dfe(i)*1000,unid2);
                            else
                                fprintf(fileID,'Diâmetro mínimo: %.2f%s\n',app.dfe(i),unid2);
                            end
                            if(app.critf(i)==1)
                                fprintf(fileID,'Falha no eixo devido à FADIGA\n\n');
                            elseif(app.critf(i)==2)
                                fprintf(fileID,'Falha no eixo devido ao ESCOAMENTO\n\n');
                            end
                        end
                    end
                end    
            end
            
                
            fprintf(fileID,'=======================================================');
            fclose(fileID);
            
            fid=fopen('relatorio.txt');
            tline=fgetl(fid);
            t=[];
            while ischar(tline)
                t{length(t)+1}=string(tline);
                tline=fgetl(fid);
            end
            app.TextArea.Value=t
            app.TextArea.Visible='on';


        end

        % Button pushed function: AdicionarDadosButton_2
        function AdicionarDadosButton_2Pushed(app, event)
            nomes = [];
            for i=1:length(app.butte)
                nomes = [nomes string(app.butte(i).Text)];
            end
            ind = find(nomes == string(app.ElementosDropDown.Value));
            app.dinamicae(ind)=1;
            Sy2=app.Sy(ind);
            if(app.sistema==0)
                Sy2=Sy2*6894.76;
            end
            Sut=app.Sutf.Value*1e6;
            if(app.sistema==0)
                Sut=Sut*6.89476;
            end
            tipokat=acharindice(app,app.TratamentosuperficialDropDown);
            crit=acharindice(app,app.CritriodefalhaDropDown);
            tc=app.TempEditField_2.Value;
            if(app.sistema==0)
                tc=(tc-32)/1.8;
            end
            conf=app.ConfEditField_2.Value;
            Ma=app.Mrmax(ind);
            Tm=app.Tmax(ind);
            if(app.sistema==0)
                Ma=Ma*0.112985;
                Tm=Tm*0.112985;
            end
            Mm=0;
            Ta=0;
            n2=app.ns(ind);
            d2=0.05;
            [nf,nf2]=deixo(d2,Ma,Tm,Mm,Ta,Sut,tipokat,tc,conf,Sy2);
            if(nf>n2 && nf2>n2)
                while(nf>n2 && nf2>n2)
                    d2=d2-0.0001;
                    [nf,nf2]=deixo(d2,Ma,Tm,Mm,Ta,Sut,tipokat,tc,conf,Sy2);
                end
            else
                while(nf<n2 || nf2<n2)
                    d2=d2+0.0001;
                    [nf,nf2]=deixo(d2,Ma,Tm,Mm,Ta,Sut,tipokat,tc,conf,Sy2);
                end
            end
            if(app.sistema==1)
                app.dfe(ind)=d2;
            else
                app.dfe(ind)=d2*1000/25.4;
            end
            if(nf<nf2)
                app.critf(ind)=1;
            else
                app.critf(ind)=2;
            end
            
            
            function [nf,nf2]= deixo(d2,Ma,Tm,Mm,Ta,Sut,tipokat,tc,conf,Sy2)
                sigmaa=((32*Ma/(pi*d2^3))^2+3*((16*Ta)/(pi*d2^3))^2)^(1/2);
                sigmam=((32*Mm/(pi*d2^3))^2+3*((16*Tm)/(pi*d2^3))^2)^(1/2);
            
            
                %Sel
            
                if(Sut<1400e6)
                    Sel=0.5*Sut;
                else
                    Sel=700;
                end
                % Ka
            
            
                kat=[1.34 1.58 -0.085;
                     2.7 4.51 -0.265;
                     14.4 57.7 -0.718;
                     39.9 272  -0.995];
            
                 ka=kat(tipokat,2)*(Sut/(1e6))^kat(tipokat,3);
            
                 % Kb
            
                 if(d2<0.051)
                     kb=((d2*1000)/7.62)^(-0.107);
                 else
                     kb=1.51*(d2*1000)^(-0.157);
                 end
            
                 % Kc
            
                 kc=1;

                 % Kd
            
                 kd=0.9877+0.6507e-3*tc-0.3414e-5*tc^2+0.5621e-8*tc^3-6.246e-12*tc^4;
            
                 % Ke
            
                 if(conf<=0.9)
                     ke=1-(conf-0.5)/(0.9-0.5)*(1-0.897);
                 elseif(conf<=0.95)
                     ke=0.897-(conf-0.9)/(0.95-0.9)*(0.897-0.868);
                 elseif(conf<=0.99)
                     ke=0.868-(conf-0.95)/(0.99-0.95)*(0.868-0.814);
                 elseif(conf<=0.999)
                     ke=0.814-(conf-0.99)/(0.999-0.99)*(0.814-0.753);
                 elseif(conf<=0.9999)
                     ke=0.753-(conf-0.999)/(0.9999-0.999)*(0.753-0.702);
                 elseif(conf<=0.99999)
                     ke=0.702-(conf-0.9999)/(0.99999-0.9999)*(0.702-0.659);
                 elseif(conf<=0.999999)
                     ke=0.659-(conf-0.99999)/(0.999999-0.99999)*(0.659-0.620);
                 end
                
                kf=1;
                Se=ka*kb*kc*kd*ke*kf*Sel;
            
            
                r2=sigmaa/sigmam;
                if(crit==1)
                    nf=1/(sigmaa/Se+sigmam/Sut);
                    Sa=r2*Se*Sut/(r2*Sut+Se);
                    Sm=Sa/r2;
                elseif(crit==2)
                    nf=1/2*(Sut/sigmam)^2*sigmaa/Se*(-1+sqrt(1+(2*sigmam*Se/(Sut*sigmaa))^2));
                    Sm=Sut^2/(2*Se)*(1-sqrt(1+(2*Se/Sut)^2*(1-Sy2/Se)));
                    Sa=Sy2-Sm;
                    rcrit=Sa/Sm;
                elseif(crit==3)
                    nf=sqrt(1/((sigmaa/Se)^2+(sigmam/Sy2)^2));
                    Sa=sqrt(r2^2*Se^2*Sy2^2/(Se^2+r2^2*Sy2^2));
                    Sm=Sa/r2;
                    rcrit=Sa/Sm;
                end
                nf2=Sy2/(sigmam+sigmaa);
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ElementosUIFigure = uifigure;
            app.ElementosUIFigure.Name = 'Elementos';
            app.ElementosUIFigure.CloseRequestFcn = createCallbackFcn(app, @ElementosUIFigureCloseRequest, true);
            app.EngrenagensButton = uibutton(app.ElementosUIFigure, 'push');
            app.EngrenagensButton.ButtonPushedFcn = createCallbackFcn(app, @EngrenagensButtonPushed, true);
            app.EngrenagensButton.Visible = 'off';
            app.EngrenagensButton.Text = 'Engrenagens';
            app.PanelEngrenagem = uipanel(app.ElementosUIFigure);
            app.PanelEngrenagem.Visible = 'off';
            app.EngrenagemHelicoidal = uipanel(app.PanelEngrenagem);
            app.EngrenagemHelicoidal.Title = 'Engrenagem Motora';
            app.EngrenagemHelicoidal.Visible = 'off';
            app.NmerodedentesLabel = uilabel(app.EngrenagemHelicoidal);
            app.NmerodedentesLabel.Text = 'Número de dentes:';
            app.nd1 = uieditfield(app.EngrenagemHelicoidal, 'numeric');
            app.nd1.UpperLimitInclusive = 'off';
            app.nd1.Limits = [1 Inf];
            app.nd1.RoundFractionalValues = 'on';
            app.nd1.ValueDisplayFormat = '%.0f';
            app.nd1.ValueChangedFcn = createCallbackFcn(app, @nd1ValueChanged, true);
            app.nd1.Value = 10;
            app.Passodiametral1inLabel = uilabel(app.EngrenagemHelicoidal);
            app.Passodiametral1inLabel.Text = 'Passo diametral (1/in):';
            app.pd = uieditfield(app.EngrenagemHelicoidal, 'numeric');
            app.pd.Limits = [1 Inf];
            app.pd.ValueChangedFcn = createCallbackFcn(app, @pdValueChanged, true);
            app.pd.Value = 5;
            app.ngulodepressoLabel_2 = uilabel(app.EngrenagemHelicoidal);
            app.ngulodepressoLabel_2.Text = 'Ângulo de pressão:';
            app.ap = uieditfield(app.EngrenagemHelicoidal, 'numeric');
            app.ngulodehliceLabel = uilabel(app.EngrenagemHelicoidal);
            app.ngulodehliceLabel.Text = 'Ângulo de hélice:';
            app.ah = uieditfield(app.EngrenagemHelicoidal, 'numeric');
            app.ah.ValueChangedFcn = createCallbackFcn(app, @ahValueChanged, true);
            app.PotnciacvLabel_2 = uilabel(app.EngrenagemHelicoidal);
            app.PotnciacvLabel_2.Text = 'Potência (cv):';
            app.pot1 = uieditfield(app.EngrenagemHelicoidal, 'numeric');
            app.pot1.ValueChangedFcn = createCallbackFcn(app, @pot1ValueChanged, true);
            app.PosioLabel = uilabel(app.EngrenagemHelicoidal);
            app.PosioLabel.Text = 'Posição';
            app.xEditFieldLabel = uilabel(app.EngrenagemHelicoidal);
            app.xEditFieldLabel.Text = 'x';
            app.xeng = uieditfield(app.EngrenagemHelicoidal, 'numeric');
            app.xeng.ValueChangedFcn = createCallbackFcn(app, @xengValueChanged, true);
            app.yEditFieldLabel = uilabel(app.EngrenagemHelicoidal);
            app.yEditFieldLabel.Text = 'y';
            app.yeng = uieditfield(app.EngrenagemHelicoidal, 'numeric');
            app.yeng.ValueChangedFcn = createCallbackFcn(app, @yengValueChanged, true);
            app.zEditFieldLabel = uilabel(app.EngrenagemHelicoidal);
            app.zEditFieldLabel.Text = 'z';
            app.zeng = uieditfield(app.EngrenagemHelicoidal, 'numeric');
            app.zeng.ValueChangedFcn = createCallbackFcn(app, @zengValueChanged, true);
            app.rd = uicheckbox(app.EngrenagemHelicoidal);
            app.rd.ValueChangedFcn = createCallbackFcn(app, @rdValueChanged, true);
            app.rd.Text = 'Rosca direita';
            app.rd.Value = true;
            app.re = uicheckbox(app.EngrenagemHelicoidal);
            app.re.ValueChangedFcn = createCallbackFcn(app, @reValueChanged, true);
            app.re.Text = 'Rosca esquerda';
            app.hor = uicheckbox(app.EngrenagemHelicoidal);
            app.hor.ValueChangedFcn = createCallbackFcn(app, @horValueChanged, true);
            app.hor.Text = 'Horário';
            app.hor.Value = true;
            app.ahor = uicheckbox(app.EngrenagemHelicoidal);
            app.ahor.ValueChangedFcn = createCallbackFcn(app, @ahorValueChanged, true);
            app.ahor.Text = 'Anti-Horário';
            app.RotaorpmLabel_2 = uilabel(app.EngrenagemHelicoidal);
            app.RotaorpmLabel_2.Text = 'Rotação (rpm):';
            app.rot_1 = uieditfield(app.EngrenagemHelicoidal, 'numeric');
            app.AdicionarEngrenagensButton = uibutton(app.PanelEngrenagem, 'push');
            app.AdicionarEngrenagensButton.ButtonPushedFcn = createCallbackFcn(app, @AdicionarEngrenagensButtonPushed, true);
            app.AdicionarEngrenagensButton.Enable = 'off';
            app.AdicionarEngrenagensButton.Text = 'Adicionar Engrenagens';
            app.TipodeengrenagemDropDownLabel = uilabel(app.PanelEngrenagem);
            app.TipodeengrenagemDropDownLabel.Text = 'Tipo de engrenagem';
            app.TipodeengrenagemDropDown = uidropdown(app.PanelEngrenagem);
            app.TipodeengrenagemDropDown.Items = {'Dentes Retos', 'Helicoidal', 'Cônica D.R.', 'Sem Fim'};
            app.TipodeengrenagemDropDown.ValueChangedFcn = createCallbackFcn(app, @TipoEngDropDownValueChanged, true);
            app.TipodeengrenagemDropDown.Value = 'Dentes Retos';
            app.EngrenagemDentesRetos = uipanel(app.PanelEngrenagem);
            app.EngrenagemDentesRetos.Title = 'Engrenagem Motora';
            app.NmerodedentesLabel_2 = uilabel(app.EngrenagemDentesRetos);
            app.NmerodedentesLabel_2.Text = 'Número de dentes:';
            app.nd1_2 = uieditfield(app.EngrenagemDentesRetos, 'numeric');
            app.nd1_2.Limits = [1 Inf];
            app.nd1_2.RoundFractionalValues = 'on';
            app.nd1_2.ValueDisplayFormat = '%.0f';
            app.nd1_2.ValueChangedFcn = createCallbackFcn(app, @nd1_2ValueChanged, true);
            app.nd1_2.Value = 10;
            app.Passodiametral1inLabel_2 = uilabel(app.EngrenagemDentesRetos);
            app.Passodiametral1inLabel_2.Text = 'Passo diametral (1/in):';
            app.pd_2 = uieditfield(app.EngrenagemDentesRetos, 'numeric');
            app.pd_2.LowerLimitInclusive = 'off';
            app.pd_2.Limits = [0 Inf];
            app.pd_2.ValueChangedFcn = createCallbackFcn(app, @pd_2ValueChanged, true);
            app.pd_2.Value = 5;
            app.ngulodepressoLabel = uilabel(app.EngrenagemDentesRetos);
            app.ngulodepressoLabel.Text = 'Ângulo de pressão:';
            app.ap_2 = uieditfield(app.EngrenagemDentesRetos, 'numeric');
            app.PotnciacvLabel = uilabel(app.EngrenagemDentesRetos);
            app.PotnciacvLabel.Text = 'Potência (cv):';
            app.pot_2 = uieditfield(app.EngrenagemDentesRetos, 'numeric');
            app.pot_2.ValueChangedFcn = createCallbackFcn(app, @pot_2ValueChanged, true);
            app.PosioLabel_2 = uilabel(app.EngrenagemDentesRetos);
            app.PosioLabel_2.Text = 'Posição';
            app.xeng_2Label_2 = uilabel(app.EngrenagemDentesRetos);
            app.xeng_2Label_2.Text = 'x';
            app.xeng_2 = uieditfield(app.EngrenagemDentesRetos, 'numeric');
            app.xeng_2.ValueChangedFcn = createCallbackFcn(app, @xeng_2ValueChanged, true);
            app.yeng_2Label_2 = uilabel(app.EngrenagemDentesRetos);
            app.yeng_2Label_2.Text = 'y';
            app.yeng_2 = uieditfield(app.EngrenagemDentesRetos, 'numeric');
            app.yeng_2.ValueChangedFcn = createCallbackFcn(app, @yeng_2ValueChanged, true);
            app.zeng_2Label_2 = uilabel(app.EngrenagemDentesRetos);
            app.zeng_2Label_2.Text = 'z';
            app.zeng_2 = uieditfield(app.EngrenagemDentesRetos, 'numeric');
            app.zeng_2.ValueChangedFcn = createCallbackFcn(app, @zeng_2ValueChanged, true);
            app.RotaorpmLabel = uilabel(app.EngrenagemDentesRetos);
            app.RotaorpmLabel.Text = 'Rotação (rpm):';
            app.rot_2 = uieditfield(app.EngrenagemDentesRetos, 'numeric');
            app.hor_2 = uicheckbox(app.EngrenagemDentesRetos);
            app.hor_2.ValueChangedFcn = createCallbackFcn(app, @hor_2ValueChanged, true);
            app.hor_2.Text = 'Horário';
            app.hor_2.Value = true;
            app.ahor_2 = uicheckbox(app.EngrenagemDentesRetos);
            app.ahor_2.ValueChangedFcn = createCallbackFcn(app, @ahor_2ValueChanged, true);
            app.ahor_2.Text = 'Anti-Horário';
            app.AtualizarEngrenagensButton = uibutton(app.PanelEngrenagem, 'push');
            app.AtualizarEngrenagensButton.ButtonPushedFcn = createCallbackFcn(app, @AtualizarEngrenagensButtonPushed, true);
            app.AtualizarEngrenagensButton.Visible = 'off';
            app.AtualizarEngrenagensButton.Text = 'Atualizar Engrenagens';
            app.DeletarEngrenagensButton = uibutton(app.PanelEngrenagem, 'push');
            app.DeletarEngrenagensButton.ButtonPushedFcn = createCallbackFcn(app, @DeletarEngrenagensButtonPushed, true);
            app.DeletarEngrenagensButton.Visible = 'off';
            app.DeletarEngrenagensButton.Text = 'Deletar Engrenagens';
            app.NomedasengrenagensLabel = uilabel(app.PanelEngrenagem);
            app.NomedasengrenagensLabel.Text = 'Nome das engrenagens:';
            app.Nomee = uieditfield(app.PanelEngrenagem, 'text');
            app.EixoDropDownLabel = uilabel(app.PanelEngrenagem);
            app.EixoDropDownLabel.Text = 'Eixo';
            app.EixoDropDown = uidropdown(app.PanelEngrenagem);
            app.EixoDropDown.Items = {'Selecionar', 'Adicionar Eixo'};
            app.EixoDropDown.ValueChangedFcn = createCallbackFcn(app, @EixoDropDownValueChanged, true);
            app.EixoDropDown.Value = 'Selecionar';
            app.EixoDropDown_2Label = uilabel(app.PanelEngrenagem);
            app.EixoDropDown_2Label.Text = 'Eixo';
            app.EixoDropDown_2 = uidropdown(app.PanelEngrenagem);
            app.EixoDropDown_2.Items = {'Selecionar', 'Adicionar Eixo'};
            app.EixoDropDown_2.ValueChangedFcn = createCallbackFcn(app, @EixoDropDown_2ValueChanged, true);
            app.EixoDropDown_2.Enable = 'off';
            app.EixoDropDown_2.Value = 'Selecionar';
            app.EngrenagemConicaDR = uipanel(app.PanelEngrenagem);
            app.EngrenagemConicaDR.Title = 'Engrenagem Motora';
            app.EngrenagemConicaDR.Visible = 'off';
            app.NmerodedentesLabel_4 = uilabel(app.EngrenagemConicaDR);
            app.NmerodedentesLabel_4.Text = 'Número de dentes:';
            app.nd1_3 = uieditfield(app.EngrenagemConicaDR, 'numeric');
            app.nd1_3.Limits = [1 Inf];
            app.nd1_3.RoundFractionalValues = 'on';
            app.nd1_3.ValueDisplayFormat = '%.0f';
            app.nd1_3.ValueChangedFcn = createCallbackFcn(app, @nd1_3ValueChanged, true);
            app.nd1_3.Value = 10;
            app.Passodiametral1inLabel_3 = uilabel(app.EngrenagemConicaDR);
            app.Passodiametral1inLabel_3.Text = 'Passo diametral (1/in):';
            app.pd_3 = uieditfield(app.EngrenagemConicaDR, 'numeric');
            app.pd_3.LowerLimitInclusive = 'off';
            app.pd_3.Limits = [0 Inf];
            app.pd_3.ValueChangedFcn = createCallbackFcn(app, @pd_3ValueChanged, true);
            app.pd_3.Value = 5;
            app.ngulodepressoLabel_3 = uilabel(app.EngrenagemConicaDR);
            app.ngulodepressoLabel_3.Text = 'Ângulo de pressão:';
            app.ap_3 = uieditfield(app.EngrenagemConicaDR, 'numeric');
            app.PotnciacvLabel_3 = uilabel(app.EngrenagemConicaDR);
            app.PotnciacvLabel_3.Text = 'Potência (cv):';
            app.pot_3 = uieditfield(app.EngrenagemConicaDR, 'numeric');
            app.pot_3.ValueChangedFcn = createCallbackFcn(app, @pot_3ValueChanged, true);
            app.PosioLabel_3 = uilabel(app.EngrenagemConicaDR);
            app.PosioLabel_3.Text = 'Posição';
            app.xeng_2Label_5 = uilabel(app.EngrenagemConicaDR);
            app.xeng_2Label_5.Text = 'x';
            app.xeng_6 = uieditfield(app.EngrenagemConicaDR, 'numeric');
            app.xeng_6.ValueChangedFcn = createCallbackFcn(app, @xeng_6ValueChanged, true);
            app.yeng_2Label_5 = uilabel(app.EngrenagemConicaDR);
            app.yeng_2Label_5.Text = 'y';
            app.yeng_6 = uieditfield(app.EngrenagemConicaDR, 'numeric');
            app.yeng_6.ValueChangedFcn = createCallbackFcn(app, @yeng_6ValueChanged, true);
            app.zeng_2Label_5 = uilabel(app.EngrenagemConicaDR);
            app.zeng_2Label_5.Text = 'z';
            app.zeng_6 = uieditfield(app.EngrenagemConicaDR, 'numeric');
            app.zeng_6.ValueChangedFcn = createCallbackFcn(app, @zeng_6ValueChanged, true);
            app.cone1 = uicheckbox(app.EngrenagemConicaDR);
            app.cone1.ValueChangedFcn = createCallbackFcn(app, @cone1ValueChanged, true);
            app.cone1.Text = '>';
            app.cone1.Value = true;
            app.cone2 = uicheckbox(app.EngrenagemConicaDR);
            app.cone2.ValueChangedFcn = createCallbackFcn(app, @cone2ValueChanged, true);
            app.cone2.Text = '<';
            app.LarguradefaceinLabel = uilabel(app.EngrenagemConicaDR);
            app.LarguradefaceinLabel.Text = 'Largura de face (in):';
            app.lff = uieditfield(app.EngrenagemConicaDR, 'numeric');
            app.lff.LowerLimitInclusive = 'off';
            app.lff.Limits = [0 Inf];
            app.lff.ValueChangedFcn = createCallbackFcn(app, @lffValueChanged, true);
            app.lff.Value = 1;
            app.hor_3 = uicheckbox(app.EngrenagemConicaDR);
            app.hor_3.ValueChangedFcn = createCallbackFcn(app, @hor_3ValueChanged, true);
            app.hor_3.Text = 'Horário';
            app.hor_3.Value = true;
            app.ahor_3 = uicheckbox(app.EngrenagemConicaDR);
            app.ahor_3.ValueChangedFcn = createCallbackFcn(app, @ahor_3ValueChanged, true);
            app.ahor_3.Text = 'Anti-Horário';
            app.RotaorpmLabel_3 = uilabel(app.EngrenagemConicaDR);
            app.RotaorpmLabel_3.Text = 'Rotação (rpm):';
            app.rot_3 = uieditfield(app.EngrenagemConicaDR, 'numeric');
            app.EngrenagemSemFim = uipanel(app.PanelEngrenagem);
            app.EngrenagemSemFim.Title = 'Engrenagem Sem Fim';
            app.EngrenagemSemFim.Visible = 'off';
            app.NmerodeentradasLabel = uilabel(app.EngrenagemSemFim);
            app.NmerodeentradasLabel.Text = 'Número de entradas:';
            app.nd1_4 = uieditfield(app.EngrenagemSemFim, 'numeric');
            app.nd1_4.UpperLimitInclusive = 'off';
            app.nd1_4.Limits = [1 Inf];
            app.nd1_4.RoundFractionalValues = 'on';
            app.nd1_4.ValueDisplayFormat = '%.0f';
            app.nd1_4.Value = 1;
            app.ngulodepressoLabel_4 = uilabel(app.EngrenagemSemFim);
            app.ngulodepressoLabel_4.Text = 'Ângulo de pressão:';
            app.ap_4 = uieditfield(app.EngrenagemSemFim, 'numeric');
            app.DimetroprimitivoinLabel = uilabel(app.EngrenagemSemFim);
            app.DimetroprimitivoinLabel.Text = 'Diâmetro primitivo (in):';
            app.dp_2 = uieditfield(app.EngrenagemSemFim, 'numeric');
            app.dp_2.LowerLimitInclusive = 'off';
            app.dp_2.ValueChangedFcn = createCallbackFcn(app, @dp_2ValueChanged, true);
            app.PotnciacvLabel_4 = uilabel(app.EngrenagemSemFim);
            app.PotnciacvLabel_4.Text = 'Potência (cv):';
            app.pot_4 = uieditfield(app.EngrenagemSemFim, 'numeric');
            app.pot_4.ValueChangedFcn = createCallbackFcn(app, @pot_4ValueChanged, true);
            app.PosioLabel_4 = uilabel(app.EngrenagemSemFim);
            app.PosioLabel_4.Text = 'Posição';
            app.xEditFieldLabel_2 = uilabel(app.EngrenagemSemFim);
            app.xEditFieldLabel_2.Text = 'x';
            app.xeng_7 = uieditfield(app.EngrenagemSemFim, 'numeric');
            app.xeng_7.ValueChangedFcn = createCallbackFcn(app, @xeng_7ValueChanged, true);
            app.yEditFieldLabel_2 = uilabel(app.EngrenagemSemFim);
            app.yEditFieldLabel_2.Text = 'y';
            app.yeng_7 = uieditfield(app.EngrenagemSemFim, 'numeric');
            app.yeng_7.ValueChangedFcn = createCallbackFcn(app, @yeng_7ValueChanged, true);
            app.zEditFieldLabel_2 = uilabel(app.EngrenagemSemFim);
            app.zEditFieldLabel_2.Text = 'z';
            app.zeng_7 = uieditfield(app.EngrenagemSemFim, 'numeric');
            app.zeng_7.ValueChangedFcn = createCallbackFcn(app, @zeng_7ValueChanged, true);
            app.rd_3 = uicheckbox(app.EngrenagemSemFim);
            app.rd_3.Text = 'Rosca direita';
            app.rd_3.Value = true;
            app.re_3 = uicheckbox(app.EngrenagemSemFim);
            app.re_3.Text = 'Rosca esquerda';
            app.hor_4 = uicheckbox(app.EngrenagemSemFim);
            app.hor_4.ValueChangedFcn = createCallbackFcn(app, @hor_4ValueChanged, true);
            app.hor_4.Text = 'Horário';
            app.hor_4.Value = true;
            app.ahor_4 = uicheckbox(app.EngrenagemSemFim);
            app.ahor_4.ValueChangedFcn = createCallbackFcn(app, @ahor_4ValueChanged, true);
            app.ahor_4.Text = 'Anti-Horário';
            app.RotaorpmLabel_4 = uilabel(app.EngrenagemSemFim);
            app.RotaorpmLabel_4.Text = 'Rotação (rpm):';
            app.rot_4 = uieditfield(app.EngrenagemSemFim, 'numeric');
            app.AtritoaltoCheckBox = uicheckbox(app.EngrenagemSemFim);
            app.AtritoaltoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AtritoaltoCheckBoxValueChanged, true);
            app.AtritoaltoCheckBox.Text = 'Atrito alto';
            app.AtritoaltoCheckBox.Value = true;
            app.AtritobaixoCheckBox = uicheckbox(app.EngrenagemSemFim);
            app.AtritobaixoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AtritobaixoCheckBoxValueChanged, true);
            app.AtritobaixoCheckBox.Text = 'Atrito baixo';
            app.EngrenagemAcopladaPanel = uipanel(app.PanelEngrenagem);
            app.EngrenagemAcopladaPanel.Title = 'Engrenagem Acoplada';
            app.NmerodedentesLabel_3 = uilabel(app.EngrenagemAcopladaPanel);
            app.NmerodedentesLabel_3.Text = 'Número de dentes:';
            app.nd2 = uieditfield(app.EngrenagemAcopladaPanel, 'numeric');
            app.nd2.Limits = [1 Inf];
            app.nd2.RoundFractionalValues = 'on';
            app.nd2.ValueDisplayFormat = '%.0f';
            app.nd2.ValueChangedFcn = createCallbackFcn(app, @nd2ValueChanged, true);
            app.nd2.Value = 10;
            app.ngulorelativoLabel = uilabel(app.EngrenagemAcopladaPanel);
            app.ngulorelativoLabel.Text = 'Ângulo relativo';
            app.ar = uieditfield(app.EngrenagemAcopladaPanel, 'numeric');
            app.ar.ValueChangedFcn = createCallbackFcn(app, @arValueChanged, true);
            app.ngulorelativoLabel_3 = uilabel(app.EngrenagemAcopladaPanel);
            app.ngulorelativoLabel_3.Text = 'Ângulo relativo:';
            app.ar2 = uidropdown(app.EngrenagemAcopladaPanel);
            app.ar2.Items = {'Selecionar', '0', '90', '180', '270'};
            app.ar2.ValueChangedFcn = createCallbackFcn(app, @ar2ValueChanged, true);
            app.ar2.Visible = 'off';
            app.ar2.Value = 'Selecionar';
            app.EngrenagemAcopladaPanel_2 = uipanel(app.PanelEngrenagem);
            app.EngrenagemAcopladaPanel_2.Title = 'Engrenagem Acoplada';
            app.EngrenagemAcopladaPanel_2.Visible = 'off';
            app.NmerodedentesLabel_5 = uilabel(app.EngrenagemAcopladaPanel_2);
            app.NmerodedentesLabel_5.Text = 'Número de dentes:';
            app.nd2_2 = uieditfield(app.EngrenagemAcopladaPanel_2, 'numeric');
            app.nd2_2.Limits = [1 Inf];
            app.nd2_2.RoundFractionalValues = 'on';
            app.nd2_2.ValueDisplayFormat = '%.0f';
            app.nd2_2.ValueChangedFcn = createCallbackFcn(app, @nd2_2ValueChanged, true);
            app.nd2_2.Value = 10;
            app.ngulorelativoLabel_2 = uilabel(app.EngrenagemAcopladaPanel_2);
            app.ngulorelativoLabel_2.Text = 'Ângulo relativo:';
            app.ar2_2 = uidropdown(app.EngrenagemAcopladaPanel_2);
            app.ar2_2.Items = {'Selecionar', '0', '90', '180', '270'};
            app.ar2_2.ValueChangedFcn = createCallbackFcn(app, @ar2_2ValueChanged, true);
            app.ar2_2.Value = 'Selecionar';
            app.Passodiametral1inLabel_4 = uilabel(app.EngrenagemAcopladaPanel_2);
            app.Passodiametral1inLabel_4.Text = 'Passo diametral (1/in):';
            app.pd_5 = uieditfield(app.EngrenagemAcopladaPanel_2, 'numeric');
            app.pd_5.LowerLimitInclusive = 'off';
            app.pd_5.Limits = [0 Inf];
            app.pd_5.RoundFractionalValues = 'on';
            app.pd_5.ValueDisplayFormat = '%.0f';
            app.pd_5.ValueChangedFcn = createCallbackFcn(app, @pd_5ValueChanged, true);
            app.pd_5.Value = 1;
            app.EixosButton = uibutton(app.ElementosUIFigure, 'push');
            app.EixosButton.ButtonPushedFcn = createCallbackFcn(app, @EixosButtonPushed, true);
            app.EixosButton.Visible = 'off';
            app.EixosButton.Text = 'Eixos';
            app.PanelEixo = uipanel(app.ElementosUIFigure);
            app.PanelEixo.Visible = 'off';
            app.EixoPanel = uipanel(app.PanelEixo);
            app.EixoPanel.Title = 'Eixo';
            app.SyLabel = uilabel(app.EixoPanel);
            app.SyLabel.Text = 'Sy:';
            app.sy = uieditfield(app.EixoPanel, 'numeric');
            app.sy.LowerLimitInclusive = 'off';
            app.sy.Limits = [0 Inf];
            app.sy.Value = 100000;
            app.FatordeSeguranaLabel = uilabel(app.EixoPanel);
            app.FatordeSeguranaLabel.Text = 'Fator de Segurança:';
            app.fs = uieditfield(app.EixoPanel, 'numeric');
            app.fs.Limits = [1 Inf];
            app.fs.Value = 1;
            app.AdicionarEixoButton = uibutton(app.EixoPanel, 'push');
            app.AdicionarEixoButton.ButtonPushedFcn = createCallbackFcn(app, @AdicionarEixoButtonPushed, true);
            app.AdicionarEixoButton.Enable = 'off';
            app.AdicionarEixoButton.Text = 'Adicionar Eixo';
            app.NomedoeixoLabel = uilabel(app.EixoPanel);
            app.NomedoeixoLabel.Text = 'Nome do eixo:';
            app.Nomeex = uieditfield(app.EixoPanel, 'text');
            app.DireodoeixoLabel = uilabel(app.EixoPanel);
            app.DireodoeixoLabel.Text = 'Direção do eixo';
            app.xCheckBox = uicheckbox(app.EixoPanel);
            app.xCheckBox.ValueChangedFcn = createCallbackFcn(app, @xCheckBoxValueChanged, true);
            app.xCheckBox.Text = 'x';
            app.yCheckBox = uicheckbox(app.EixoPanel);
            app.yCheckBox.ValueChangedFcn = createCallbackFcn(app, @yCheckBoxValueChanged, true);
            app.yCheckBox.Text = 'y';
            app.zCheckBox = uicheckbox(app.EixoPanel);
            app.zCheckBox.ValueChangedFcn = createCallbackFcn(app, @zCheckBoxValueChanged, true);
            app.zCheckBox.Text = 'z';
            app.zCheckBox.Value = true;
            app.Posiodomancal1Label = uilabel(app.EixoPanel);
            app.Posiodomancal1Label.Text = 'Posição do mancal 1';
            app.Posiodomancal2Label = uilabel(app.EixoPanel);
            app.Posiodomancal2Label.Visible = 'off';
            app.Posiodomancal2Label.Text = 'Posição do mancal 2';
            app.AtualizarEixoButton = uibutton(app.EixoPanel, 'push');
            app.AtualizarEixoButton.ButtonPushedFcn = createCallbackFcn(app, @AtualizarEixoButtonPushed, true);
            app.AtualizarEixoButton.Visible = 'off';
            app.AtualizarEixoButton.Text = 'Atualizar Eixo';
            app.xeng_3Label = uilabel(app.EixoPanel);
            app.xeng_3Label.Text = 'x';
            app.xm2 = uieditfield(app.EixoPanel, 'numeric');
            app.xm2.ValueChangedFcn = createCallbackFcn(app, @xm2ValueChanged, true);
            app.xm2.Enable = 'off';
            app.xeng_2Label = uilabel(app.EixoPanel);
            app.xeng_2Label.Text = 'x';
            app.xm1 = uieditfield(app.EixoPanel, 'numeric');
            app.xm1.ValueChangedFcn = createCallbackFcn(app, @xm1ValueChanged, true);
            app.yeng_2Label = uilabel(app.EixoPanel);
            app.yeng_2Label.Text = 'y';
            app.ym1 = uieditfield(app.EixoPanel, 'numeric');
            app.ym1.ValueChangedFcn = createCallbackFcn(app, @ym1ValueChanged, true);
            app.zeng_2Label = uilabel(app.EixoPanel);
            app.zeng_2Label.Text = 'z';
            app.zm1 = uieditfield(app.EixoPanel, 'numeric');
            app.zm1.ValueChangedFcn = createCallbackFcn(app, @zm1ValueChanged, true);
            app.zeng_3Label = uilabel(app.EixoPanel);
            app.zeng_3Label.Text = 'z';
            app.zm2 = uieditfield(app.EixoPanel, 'numeric');
            app.zm2.ValueChangedFcn = createCallbackFcn(app, @zm2ValueChanged, true);
            app.yeng_3Label = uilabel(app.EixoPanel);
            app.yeng_3Label.Text = 'y';
            app.ym2 = uieditfield(app.EixoPanel, 'numeric');
            app.ym2.ValueChangedFcn = createCallbackFcn(app, @ym2ValueChanged, true);
            app.ym2.Enable = 'off';
            app.DeletarEixoButton = uibutton(app.EixoPanel, 'push');
            app.DeletarEixoButton.ButtonPushedFcn = createCallbackFcn(app, @DeletarEixoButtonPushed, true);
            app.DeletarEixoButton.Visible = 'off';
            app.DeletarEixoButton.Text = 'Deletar Eixo';
            app.ResultadoButton = uibutton(app.ElementosUIFigure, 'push');
            app.ResultadoButton.ButtonPushedFcn = createCallbackFcn(app, @ResultadoButtonPushed, true);
            app.ResultadoButton.Visible = 'off';
            app.ResultadoButton.Text = 'Resultado';
            app.PanelResultado = uipanel(app.ElementosUIFigure);
            app.PanelResultado.Title = 'Resultado';
            app.PanelResultado.Visible = 'off';
            app.DimetrodoEixoEditFieldLabel = uilabel(app.PanelResultado);
            app.DimetrodoEixoEditFieldLabel.Text = 'Diâmetro do Eixo';
            app.DimetrodoEixoEditField = uieditfield(app.PanelResultado, 'text');
            app.DimetrodoEixoEditField.Editable = 'off';
            app.TorqueMximoEditFieldLabel = uilabel(app.PanelResultado);
            app.TorqueMximoEditFieldLabel.Text = 'Torque Máximo';
            app.TorqueMximoEditField = uieditfield(app.PanelResultado, 'text');
            app.TorqueMximoEditField.Editable = 'off';
            app.MomentoMximoEditFieldLabel = uilabel(app.PanelResultado);
            app.MomentoMximoEditFieldLabel.Text = 'Momento Máximo';
            app.MomentoMximoEditField = uieditfield(app.PanelResultado, 'text');
            app.MomentoMximoEditField.Editable = 'off';
            app.SelecionarEixoDropDownLabel = uilabel(app.PanelResultado);
            app.SelecionarEixoDropDownLabel.Text = 'Selecionar Eixo';
            app.SelecionarEixoDropDown = uidropdown(app.PanelResultado);
            app.SelecionarEixoDropDown.Items = {};
            app.SelecionarEixoDropDown.ValueChangedFcn = createCallbackFcn(app, @SelecionarEixoDropDownValueChanged, true);
            app.SelecionarEixoDropDown.Value = {};
            app.SelecionarElementoDropDownLabel = uilabel(app.PanelResultado);
            app.SelecionarElementoDropDownLabel.Visible = 'off';
            app.SelecionarElementoDropDownLabel.Text = 'Selecionar Elemento';
            app.SelecionarElementoDropDown = uidropdown(app.PanelResultado);
            app.SelecionarElementoDropDown.Items = {'Selecionar'};
            app.SelecionarElementoDropDown.ValueChangedFcn = createCallbackFcn(app, @SelecionarElementoDropDownValueChanged, true);
            app.SelecionarElementoDropDown.Visible = 'off';
            app.SelecionarElementoDropDown.Value = 'Selecionar';
            app.FxEditField_2Label = uilabel(app.PanelResultado);
            app.FxEditField_2Label.Visible = 'off';
            app.FxEditField_2Label.Text = 'Fx';
            app.FxEditField = uieditfield(app.PanelResultado, 'text');
            app.FxEditField.Editable = 'off';
            app.FxEditField.Visible = 'off';
            app.FyEditFieldLabel = uilabel(app.PanelResultado);
            app.FyEditFieldLabel.Visible = 'off';
            app.FyEditFieldLabel.Text = 'Fy';
            app.FyEditField = uieditfield(app.PanelResultado, 'text');
            app.FyEditField.Editable = 'off';
            app.FyEditField.Visible = 'off';
            app.FzEditFieldLabel = uilabel(app.PanelResultado);
            app.FzEditFieldLabel.Visible = 'off';
            app.FzEditFieldLabel.Text = 'Fz';
            app.FzEditField = uieditfield(app.PanelResultado, 'text');
            app.FzEditField.Editable = 'off';
            app.FzEditField.Visible = 'off';
            app.AnliseDinmicaButton = uibutton(app.PanelResultado, 'push');
            app.AnliseDinmicaButton.ButtonPushedFcn = createCallbackFcn(app, @AnliseDinmicaButtonPushed, true);
            app.AnliseDinmicaButton.Text = 'Análise Dinâmica';
            app.UIAxes = uiaxes(app.PanelResultado);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.PanelResultado);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.AdicionarEixoPanel = uipanel(app.ElementosUIFigure);
            app.AdicionarEixoPanel.Title = 'Adicionar Eixo';
            app.AdicionarEixoPanel.Visible = 'off';
            app.xeng_2Label_3 = uilabel(app.AdicionarEixoPanel);
            app.xeng_2Label_3.Text = 'x';
            app.xeng_4 = uieditfield(app.AdicionarEixoPanel, 'numeric');
            app.xeng_4.ValueChangedFcn = createCallbackFcn(app, @xeng_4ValueChanged, true);
            app.yeng_2Label_3 = uilabel(app.AdicionarEixoPanel);
            app.yeng_2Label_3.Text = 'y';
            app.yeng_4 = uieditfield(app.AdicionarEixoPanel, 'numeric');
            app.yeng_4.ValueChangedFcn = createCallbackFcn(app, @yeng_4ValueChanged, true);
            app.zeng_2Label_3 = uilabel(app.AdicionarEixoPanel);
            app.zeng_2Label_3.Text = 'z';
            app.zeng_4 = uieditfield(app.AdicionarEixoPanel, 'numeric');
            app.zeng_4.ValueChangedFcn = createCallbackFcn(app, @zeng_4ValueChanged, true);
            app.xeng_2Label_4 = uilabel(app.AdicionarEixoPanel);
            app.xeng_2Label_4.Text = 'x';
            app.xeng_5 = uieditfield(app.AdicionarEixoPanel, 'numeric');
            app.xeng_5.ValueChangedFcn = createCallbackFcn(app, @xeng_5ValueChanged, true);
            app.xeng_5.Enable = 'off';
            app.yeng_2Label_4 = uilabel(app.AdicionarEixoPanel);
            app.yeng_2Label_4.Text = 'y';
            app.yeng_5 = uieditfield(app.AdicionarEixoPanel, 'numeric');
            app.yeng_5.ValueChangedFcn = createCallbackFcn(app, @yeng_5ValueChanged, true);
            app.yeng_5.Enable = 'off';
            app.zeng_2Label_4 = uilabel(app.AdicionarEixoPanel);
            app.zeng_2Label_4.Text = 'z';
            app.zeng_5 = uieditfield(app.AdicionarEixoPanel, 'numeric');
            app.zeng_5.ValueChangedFcn = createCallbackFcn(app, @zeng_5ValueChanged, true);
            app.NomedoeixoEditFieldLabel = uilabel(app.AdicionarEixoPanel);
            app.NomedoeixoEditFieldLabel.Text = 'Nome do eixo:';
            app.Nomeex2 = uieditfield(app.AdicionarEixoPanel, 'text');
            app.SyEditFieldLabel = uilabel(app.AdicionarEixoPanel);
            app.SyEditFieldLabel.Text = 'Sy:';
            app.sy2 = uieditfield(app.AdicionarEixoPanel, 'numeric');
            app.sy2.LowerLimitInclusive = 'off';
            app.sy2.Limits = [0 Inf];
            app.sy2.Value = 100000;
            app.FatordeseguranaLabel = uilabel(app.AdicionarEixoPanel);
            app.FatordeseguranaLabel.Text = 'Fator de segurança:';
            app.fs2 = uieditfield(app.AdicionarEixoPanel, 'numeric');
            app.fs2.Limits = [1 Inf];
            app.fs2.Value = 1;
            app.DireodoeixoLabel_2 = uilabel(app.AdicionarEixoPanel);
            app.DireodoeixoLabel_2.Text = 'Direção do eixo';
            app.xCheckBox_2 = uicheckbox(app.AdicionarEixoPanel);
            app.xCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @xCheckBox_2ValueChanged, true);
            app.xCheckBox_2.Text = 'x';
            app.yCheckBox_2 = uicheckbox(app.AdicionarEixoPanel);
            app.yCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @yCheckBox_2ValueChanged, true);
            app.yCheckBox_2.Text = 'y';
            app.zCheckBox_2 = uicheckbox(app.AdicionarEixoPanel);
            app.zCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @zCheckBox_2ValueChanged, true);
            app.zCheckBox_2.Text = 'z';
            app.zCheckBox_2.Value = true;
            app.Posiodomancal1Label_2 = uilabel(app.AdicionarEixoPanel);
            app.Posiodomancal1Label_2.Text = 'Posição do mancal 1';
            app.Posiodomancal2Label_2 = uilabel(app.AdicionarEixoPanel);
            app.Posiodomancal2Label_2.Text = 'Posição do mancal 2';
            app.AdicionarEixoButton_2 = uibutton(app.AdicionarEixoPanel, 'push');
            app.AdicionarEixoButton_2.ButtonPushedFcn = createCallbackFcn(app, @AdicionarEixoButton_2Pushed, true);
            app.AdicionarEixoButton_2.Enable = 'off';
            app.AdicionarEixoButton_2.Text = 'Adicionar Eixo';
            app.NovoProjetoLabel = uilabel(app.ElementosUIFigure);
            app.NovoProjetoLabel.Text = 'Novo Projeto';
            app.SistemaInternacionalButton = uibutton(app.ElementosUIFigure, 'push');
            app.SistemaInternacionalButton.ButtonPushedFcn = createCallbackFcn(app, @SistemaInternacionalButtonPushed, true);
            app.SistemaInternacionalButton.Text = 'Sistema Internacional';
            app.SistemaInglsButton = uibutton(app.ElementosUIFigure, 'push');
            app.SistemaInglsButton.ButtonPushedFcn = createCallbackFcn(app, @SistemaInglsButtonPushed, true);
            app.SistemaInglsButton.Text = 'Sistema Inglês';
            app.AnaliseDinamica = uipanel(app.ElementosUIFigure);
            app.AnaliseDinamica.Title = 'Análise Dinâmica';
            app.AnaliseDinamica.Visible = 'off';
            app.ElementosDropDownLabel = uilabel(app.AnaliseDinamica);
            app.ElementosDropDownLabel.Text = 'Elementos';
            app.ElementosDropDown = uidropdown(app.AnaliseDinamica);
            app.ElementosDropDown.Items = {'Selecionar'};
            app.ElementosDropDown.ValueChangedFcn = createCallbackFcn(app, @ElementosDropDownValueChanged, true);
            app.ElementosDropDown.Value = 'Selecionar';
            app.EngrenagensdeDentesRetosPanel = uipanel(app.AnaliseDinamica);
            app.EngrenagensdeDentesRetosPanel.Title = 'Engrenagens de Dentes Retos';
            app.EngrenagensdeDentesRetosPanel.Visible = 'off';
            app.TrattrmicopinhoDropDownLabel = uilabel(app.EngrenagensdeDentesRetosPanel);
            app.TrattrmicopinhoDropDownLabel.Text = 'Trat. térmico pinhão:';
            app.TrattrmicopinhoDropDown = uidropdown(app.EngrenagensdeDentesRetosPanel);
            app.TrattrmicopinhoDropDown.Items = {'Endurecido por completo', 'Endurecido por chama ou indução', 'Carbonetado e endurecido', 'Nitretado (aços endurecidos por completo)', 'Outro'};
            app.TrattrmicopinhoDropDown.ValueChangedFcn = createCallbackFcn(app, @TrattrmicopinhoDropDownValueChanged, true);
            app.TrattrmicopinhoDropDown.Value = 'Endurecido por completo';
            app.FontedepotnciaLabel = uilabel(app.EngrenagensdeDentesRetosPanel);
            app.FontedepotnciaLabel.Text = 'Fonte de potência:';
            app.FontedepotnciaDropDown = uidropdown(app.EngrenagensdeDentesRetosPanel);
            app.FontedepotnciaDropDown.Items = {'Uniforme', 'Choques moderados', 'Choques intensos'};
            app.FontedepotnciaDropDown.Value = 'Uniforme';
            app.MquinaacionadaDropDownLabel = uilabel(app.EngrenagensdeDentesRetosPanel);
            app.MquinaacionadaDropDownLabel.Text = 'Máquina acionada:';
            app.MquinaacionadaDropDown = uidropdown(app.EngrenagensdeDentesRetosPanel);
            app.MquinaacionadaDropDown.Items = {'Uniforme', 'Choques leves', 'Choques médios'};
            app.MquinaacionadaDropDown.Value = 'Uniforme';
            app.CoroamentoDropDownLabel = uilabel(app.EngrenagensdeDentesRetosPanel);
            app.CoroamentoDropDownLabel.Text = 'Coroamento:';
            app.CoroamentoDropDown = uidropdown(app.EngrenagensdeDentesRetosPanel);
            app.CoroamentoDropDown.Items = {'Sem coroamento', 'Com coroamento'};
            app.CoroamentoDropDown.Value = 'Sem coroamento';
            app.EngrenamentoDropDownLabel = uilabel(app.EngrenagensdeDentesRetosPanel);
            app.EngrenamentoDropDownLabel.Text = 'Engrenamento:';
            app.EngrenamentoDropDown = uidropdown(app.EngrenagensdeDentesRetosPanel);
            app.EngrenamentoDropDown.Items = {'Ajustado na montagem', 'Com lapidação', 'Outros'};
            app.EngrenamentoDropDown.Value = 'Ajustado na montagem';
            app.TipodeengDropDownLabel = uilabel(app.EngrenagensdeDentesRetosPanel);
            app.TipodeengDropDownLabel.Text = 'Tipo de eng.:';
            app.TipodeengDropDown = uidropdown(app.EngrenagensdeDentesRetosPanel);
            app.TipodeengDropDown.Items = {'Engrenamento aberto', 'Unid. fechadas, comerciais', 'Unid. fechadas, precisão', 'Unid. fechadas, extraprecisas'};
            app.TipodeengDropDown.Value = 'Engrenamento aberto';
            app.CaractdoaroDropDownLabel = uilabel(app.EngrenagensdeDentesRetosPanel);
            app.CaractdoaroDropDownLabel.Text = 'Caract. do aro:';
            app.CaractdoaroDropDown = uidropdown(app.EngrenagensdeDentesRetosPanel);
            app.CaractdoaroDropDown.Items = {'Mb>=1.2', 'Mb<1.2'};
            app.CaractdoaroDropDown.ValueChangedFcn = createCallbackFcn(app, @CaractdoaroDropDownValueChanged, true);
            app.CaractdoaroDropDown.Value = 'Mb>=1.2';
            app.MaterialdacoroaDropDownLabel = uilabel(app.EngrenagensdeDentesRetosPanel);
            app.MaterialdacoroaDropDownLabel.Text = 'Material da coroa:';
            app.MaterialdacoroaDropDown = uidropdown(app.EngrenagensdeDentesRetosPanel);
            app.MaterialdacoroaDropDown.Items = {'Aço', 'Aço 2.5% cromo (sem alumínio)', 'Nitralloy 135M', 'Nitralloy N 2.5% cromo (sem alumínio)', 'Ferro nodular grau 60-40-18', 'Ferro nodular grau 80-55-03', 'Ferro nodular grau 120-90-02', 'Ferro fundido cinza classe 20', 'Ferro fundido cinza classe 30', 'Ferro fundido cinza classe 40', 'Bronze', 'Bronze ASTM B-148 Liga 954', 'Outro'};
            app.MaterialdacoroaDropDown.ValueChangedFcn = createCallbackFcn(app, @MaterialdacoroaDropDownValueChanged, true);
            app.MaterialdacoroaDropDown.Value = 'Aço';
            app.MaterialdopinhoDropDownLabel = uilabel(app.EngrenagensdeDentesRetosPanel);
            app.MaterialdopinhoDropDownLabel.Text = 'Material do pinhão:';
            app.MaterialdopinhoDropDown = uidropdown(app.EngrenagensdeDentesRetosPanel);
            app.MaterialdopinhoDropDown.Items = {'Aço', 'Aço 2.5% cromo (sem alumínio)', 'Nitralloy 135M', 'Nitralloy N 2.5% cromo (sem alumínio)', 'Ferro nodular grau 60-40-18', 'Ferro nodular grau 80-55-03', 'Ferro nodular grau 120-90-02', 'Ferro fundido cinza classe 20', 'Ferro fundido cinza classe 30', 'Ferro fundido cinza classe 40', 'Bronze', 'Bronze ASTM B-148 Liga 954', 'Outro'};
            app.MaterialdopinhoDropDown.ValueChangedFcn = createCallbackFcn(app, @MaterialdopinhoDropDownValueChanged, true);
            app.MaterialdopinhoDropDown.Value = 'Aço';
            app.TrattrmicocoroaDropDownLabel = uilabel(app.EngrenagensdeDentesRetosPanel);
            app.TrattrmicocoroaDropDownLabel.Text = 'Trat. térmico coroa:';
            app.TrattrmicocoroaDropDown = uidropdown(app.EngrenagensdeDentesRetosPanel);
            app.TrattrmicocoroaDropDown.Items = {'Endurecido por completo', 'Endurecido por chama ou indução', 'Carbonetado e endurecido', 'Nitretado (aços endurecidos por completo)', 'Outro'};
            app.TrattrmicocoroaDropDown.ValueChangedFcn = createCallbackFcn(app, @TrattrmicocoroaDropDownValueChanged, true);
            app.TrattrmicocoroaDropDown.Value = 'Endurecido por completo';
            app.LarguradefaceinLabel_2 = uilabel(app.EngrenagensdeDentesRetosPanel);
            app.LarguradefaceinLabel_2.Text = 'Largura de face (in):';
            app.LarguradefaceEditField = uieditfield(app.EngrenagensdeDentesRetosPanel, 'numeric');
            app.LarguradefaceEditField.LowerLimitInclusive = 'off';
            app.LarguradefaceEditField.Limits = [0 Inf];
            app.LarguradefaceEditField.Value = 1.5;
            app.TempdefuncFLabel = uilabel(app.EngrenagensdeDentesRetosPanel);
            app.TempdefuncFLabel.Text = 'Temp. de func. (°F):';
            app.TempEditField = uieditfield(app.EngrenagensdeDentesRetosPanel, 'numeric');
            app.TempEditField.Value = 100;
            app.CoefdeconfiabilidadeLabel = uilabel(app.EngrenagensdeDentesRetosPanel);
            app.CoefdeconfiabilidadeLabel.Text = 'Coef. de confiabilidade:';
            app.ConfEditField = uieditfield(app.EngrenagensdeDentesRetosPanel, 'numeric');
            app.ConfEditField.Value = 0.9;
            app.VidadopinhociclosLabel = uilabel(app.EngrenagensdeDentesRetosPanel);
            app.VidadopinhociclosLabel.Text = 'Vida do pinhão (ciclos):';
            app.VidaEditField = uieditfield(app.EngrenagensdeDentesRetosPanel, 'numeric');
            app.VidaEditField.LowerLimitInclusive = 'off';
            app.VidaEditField.Limits = [0 Inf];
            app.VidaEditField.Value = 100000000;
            app.NmerodequalidadeLabel = uilabel(app.EngrenagensdeDentesRetosPanel);
            app.NmerodequalidadeLabel.Text = 'Número de qualidade:';
            app.NumqEditField = uieditfield(app.EngrenagensdeDentesRetosPanel, 'numeric');
            app.NumqEditField.LowerLimitInclusive = 'off';
            app.NumqEditField.Limits = [0 Inf];
            app.NumqEditField.Value = 6;
            app.DurezadacoroaHBEditFieldLabel = uilabel(app.EngrenagensdeDentesRetosPanel);
            app.DurezadacoroaHBEditFieldLabel.Text = 'Dureza da coroa (HB):';
            app.DurezacEditField = uieditfield(app.EngrenagensdeDentesRetosPanel, 'numeric');
            app.DurezacEditField.Limits = [0 Inf];
            app.DurezacEditField.ValueChangedFcn = createCallbackFcn(app, @DurezacEditFieldValueChanged, true);
            app.DurezacEditField.Value = 200;
            app.DurezadopinhoHBLabel = uilabel(app.EngrenagensdeDentesRetosPanel);
            app.DurezadopinhoHBLabel.Text = 'Dureza do pinhão (HB):';
            app.DurezapEditField = uieditfield(app.EngrenagensdeDentesRetosPanel, 'numeric');
            app.DurezapEditField.Limits = [0 Inf];
            app.DurezapEditField.ValueChangedFcn = createCallbackFcn(app, @DurezapEditFieldValueChanged, true);
            app.DurezapEditField.Value = 240;
            app.MbEditFieldLabel = uilabel(app.EngrenagensdeDentesRetosPanel);
            app.MbEditFieldLabel.Text = 'Mb:';
            app.MbEditField = uieditfield(app.EngrenagensdeDentesRetosPanel, 'numeric');
            app.MbEditField.LowerLimitInclusive = 'off';
            app.MbEditField.Limits = [0 Inf];
            app.MbEditField.Enable = 'off';
            app.MbEditField.Value = 1.2;
            app.AdicionarDadosButton = uibutton(app.EngrenagensdeDentesRetosPanel, 'push');
            app.AdicionarDadosButton.ButtonPushedFcn = createCallbackFcn(app, @AdicionarDadosButtonPushed, true);
            app.AdicionarDadosButton.Text = 'Adicionar Dados';
            app.AtualizarButton = uibutton(app.EngrenagensdeDentesRetosPanel, 'push');
            app.AtualizarButton.ButtonPushedFcn = createCallbackFcn(app, @AtualizarButtonPushed, true);
            app.AtualizarButton.Visible = 'off';
            app.AtualizarButton.Text = 'Atualizar';
            app.DeletarButton = uibutton(app.EngrenagensdeDentesRetosPanel, 'push');
            app.DeletarButton.Visible = 'off';
            app.DeletarButton.Text = 'Deletar';
            app.EdacoroapsiLabel = uilabel(app.EngrenagensdeDentesRetosPanel);
            app.EdacoroapsiLabel.Text = 'E da coroa (psi):';
            app.EdacoroapsiEditField = uieditfield(app.EngrenagensdeDentesRetosPanel, 'numeric');
            app.EdacoroapsiEditField.LowerLimitInclusive = 'off';
            app.EdacoroapsiEditField.Limits = [0 Inf];
            app.EdacoroapsiEditField.Enable = 'off';
            app.EdacoroapsiEditField.Value = 30000000;
            app.EdopinhopsiEditFieldLabel = uilabel(app.EngrenagensdeDentesRetosPanel);
            app.EdopinhopsiEditFieldLabel.Text = 'E do pinhão (psi):';
            app.EdopinhopsiEditField = uieditfield(app.EngrenagensdeDentesRetosPanel, 'numeric');
            app.EdopinhopsiEditField.LowerLimitInclusive = 'off';
            app.EdopinhopsiEditField.Limits = [0 Inf];
            app.EdopinhopsiEditField.Enable = 'off';
            app.EdopinhopsiEditField.Value = 30000000;
            app.ScadacoroapsiEditFieldLabel = uilabel(app.EngrenagensdeDentesRetosPanel);
            app.ScadacoroapsiEditFieldLabel.Text = 'Sca da coroa (psi):';
            app.ScadacoroapsiEditField = uieditfield(app.EngrenagensdeDentesRetosPanel, 'numeric');
            app.ScadacoroapsiEditField.LowerLimitInclusive = 'off';
            app.ScadacoroapsiEditField.Limits = [0 Inf];
            app.ScadacoroapsiEditField.Enable = 'off';
            app.ScadacoroapsiEditField.Value = 93500;
            app.ScadopinhopsiEditFieldLabel = uilabel(app.EngrenagensdeDentesRetosPanel);
            app.ScadopinhopsiEditFieldLabel.Text = 'Sca do pinhão (psi):';
            app.ScadopinhopsiEditField = uieditfield(app.EngrenagensdeDentesRetosPanel, 'numeric');
            app.ScadopinhopsiEditField.LowerLimitInclusive = 'off';
            app.ScadopinhopsiEditField.Limits = [0 Inf];
            app.ScadopinhopsiEditField.Enable = 'off';
            app.ScadopinhopsiEditField.Value = 106400;
            app.PosiodasengsDropDownLabel = uilabel(app.EngrenagensdeDentesRetosPanel);
            app.PosiodasengsDropDownLabel.Text = 'Posição das engs.';
            app.PosiodasengsDropDown = uidropdown(app.EngrenagensdeDentesRetosPanel);
            app.PosiodasengsDropDown.Items = {'Engrenagens internas', 'Engrenagens externas'};
            app.PosiodasengsDropDown.Value = 'Engrenagens internas';
            app.GraudacoroaDropDownLabel = uilabel(app.EngrenagensdeDentesRetosPanel);
            app.GraudacoroaDropDownLabel.Text = 'Grau da coroa';
            app.GraudacoroaDropDown = uidropdown(app.EngrenagensdeDentesRetosPanel);
            app.GraudacoroaDropDown.Items = {'1', '2'};
            app.GraudacoroaDropDown.ValueChangedFcn = createCallbackFcn(app, @GraudacoroaDropDownValueChanged, true);
            app.GraudacoroaDropDown.Value = '1';
            app.GraudopinhoDropDownLabel = uilabel(app.EngrenagensdeDentesRetosPanel);
            app.GraudopinhoDropDownLabel.Text = 'Grau do pinhão:';
            app.GraudopinhoDropDown = uidropdown(app.EngrenagensdeDentesRetosPanel);
            app.GraudopinhoDropDown.Items = {'1', '2'};
            app.GraudopinhoDropDown.ValueChangedFcn = createCallbackFcn(app, @GraudopinhoDropDownValueChanged, true);
            app.GraudopinhoDropDown.Value = '1';
            app.StadacoroapsiEditFieldLabel = uilabel(app.EngrenagensdeDentesRetosPanel);
            app.StadacoroapsiEditFieldLabel.Text = 'Sta da coroa (psi):';
            app.StadacoroapsiEditField = uieditfield(app.EngrenagensdeDentesRetosPanel, 'numeric');
            app.StadacoroapsiEditField.LowerLimitInclusive = 'off';
            app.StadacoroapsiEditField.Limits = [0 Inf];
            app.StadacoroapsiEditField.Enable = 'off';
            app.StadacoroapsiEditField.Value = 28260;
            app.StadopinhopsiEditFieldLabel = uilabel(app.EngrenagensdeDentesRetosPanel);
            app.StadopinhopsiEditFieldLabel.Text = 'Sta do pinhão (psi):';
            app.StadopinhopsiEditField = uieditfield(app.EngrenagensdeDentesRetosPanel, 'numeric');
            app.StadopinhopsiEditField.LowerLimitInclusive = 'off';
            app.StadopinhopsiEditField.Limits = [0 Inf];
            app.StadopinhopsiEditField.Enable = 'off';
            app.StadopinhopsiEditField.Value = 31350;
            app.EixosPanel = uipanel(app.AnaliseDinamica);
            app.EixosPanel.Title = 'Eixos';
            app.EixosPanel.Visible = 'off';
            app.TratamentosuperficialDropDownLabel = uilabel(app.EixosPanel);
            app.TratamentosuperficialDropDownLabel.Text = 'Tratamento superficial:';
            app.TratamentosuperficialDropDown = uidropdown(app.EixosPanel);
            app.TratamentosuperficialDropDown.Items = {'Retificado', 'Usinado ou laminado a frio', 'Laminado a quente', 'Forjado'};
            app.TratamentosuperficialDropDown.Value = 'Retificado';
            app.SutMPaLabel = uilabel(app.EixosPanel);
            app.SutMPaLabel.Text = 'Sut (MPa):';
            app.Sutf = uieditfield(app.EixosPanel, 'numeric');
            app.Sutf.LowerLimitInclusive = 'off';
            app.Sutf.Limits = [0 Inf];
            app.Sutf.Value = 500;
            app.TempdefuncFLabel_2 = uilabel(app.EixosPanel);
            app.TempdefuncFLabel_2.Text = 'Temp. de func. (°F):';
            app.TempEditField_2 = uieditfield(app.EixosPanel, 'numeric');
            app.TempEditField_2.Value = 100;
            app.CoefdeconfiabilidadeLabel_2 = uilabel(app.EixosPanel);
            app.CoefdeconfiabilidadeLabel_2.Text = 'Coef. de confiabilidade:';
            app.ConfEditField_2 = uieditfield(app.EixosPanel, 'numeric');
            app.ConfEditField_2.Value = 0.9;
            app.AdicionarDadosButton_2 = uibutton(app.EixosPanel, 'push');
            app.AdicionarDadosButton_2.ButtonPushedFcn = createCallbackFcn(app, @AdicionarDadosButton_2Pushed, true);
            app.AdicionarDadosButton_2.Text = 'Adicionar Dados';
            app.AtualizarButton_2 = uibutton(app.EixosPanel, 'push');
            app.AtualizarButton_2.Visible = 'off';
            app.AtualizarButton_2.Text = 'Atualizar';
            app.DeletarButton_2 = uibutton(app.EixosPanel, 'push');
            app.DeletarButton_2.Visible = 'off';
            app.DeletarButton_2.Text = 'Deletar';
            app.CritriodefalhaDropDownLabel = uilabel(app.EixosPanel);
            app.CritriodefalhaDropDownLabel.Text = 'Critério de falha:';
            app.CritriodefalhaDropDown = uidropdown(app.EixosPanel);
            app.CritriodefalhaDropDown.Items = {'Goodman modificado', 'Gerber', 'ASME-Elíptico'};
            app.CritriodefalhaDropDown.Value = 'Goodman modificado';
            app.RelatrioButton = uibutton(app.ElementosUIFigure, 'push');
            app.RelatrioButton.ButtonPushedFcn = createCallbackFcn(app, @RelatrioButtonPushed, true);
            app.RelatrioButton.Visible = 'off';
            app.RelatrioButton.Text = 'Relatório';
            app.Label = uilabel(app.ElementosUIFigure);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.ElementosUIFigure);
            app.TextArea.Visible = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = TCCv138F

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ElementosUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ElementosUIFigure)
        end
    end
end